classdef NCA_GUI_OCT2025 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NarrowChannel                   matlab.ui.Figure
        RampInformationPanel            matlab.ui.container.Panel
        RampActiveLamp                  matlab.ui.control.Lamp
        RampActiveLampLabel             matlab.ui.control.Label
        SLMEditField_18                 matlab.ui.control.NumericEditField
        SLMEditField_18Label            matlab.ui.control.Label
        O2PercentPlateauVolumeSpinner   matlab.ui.control.Spinner
        O2percentplateauVolumeLabel     matlab.ui.control.Label
        SLMsEditField_15                matlab.ui.control.NumericEditField
        SLMsEditField_15Label           matlab.ui.control.Label
        O2PercentRampDownsecSpinner     matlab.ui.control.Spinner
        O2PercentRampDownsecSpinnerLabel  matlab.ui.control.Label
        OxygenRampsecEditField          matlab.ui.control.NumericEditField
        OxygenRampTimesecLabel          matlab.ui.control.Label
        RampUp1secEditField             matlab.ui.control.NumericEditField
        RampUp1secEditFieldLabel        matlab.ui.control.Label
        RampDown2secEditField           matlab.ui.control.NumericEditField
        RampDown2secEditFieldLabel      matlab.ui.control.Label
        RampDown1secEditField           matlab.ui.control.NumericEditField
        RampDown1secEditFieldLabel      matlab.ui.control.Label
        PhaseDurationsLabel             matlab.ui.control.Label
        TotalFlowReadingsPanel          matlab.ui.container.Panel
        TotalVelSetpointEditField       matlab.ui.control.NumericEditField
        TotalVelSetpointEditFieldLabel  matlab.ui.control.Label
        TotalFlowSetpointEditField      matlab.ui.control.NumericEditField
        TotalFlowSetpointEditFieldLabel  matlab.ui.control.Label
        ActualTotalVelocityEditField    matlab.ui.control.NumericEditField
        ActualTotalVelocityEditFieldLabel  matlab.ui.control.Label
        ActualTotalFlowEditField        matlab.ui.control.NumericEditField
        ActualTotalFlowEditFieldLabel   matlab.ui.control.Label
        UnitPanel                       matlab.ui.container.Panel
        AirLabel                        matlab.ui.control.Label
        E0055Label                      matlab.ui.control.Label
        O2Label_2                       matlab.ui.control.Label
        N2Label_2                       matlab.ui.control.Label
        D0011Label                      matlab.ui.control.Label
        C0011Label                      matlab.ui.control.Label
        O2Label                         matlab.ui.control.Label
        B150Label                       matlab.ui.control.Label
        N2Label                         matlab.ui.control.Label
        A150Label                       matlab.ui.control.Label
        ActualsPanel                    matlab.ui.container.Panel
        EditField_20                    matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        VelocityLabel                   matlab.ui.control.Label
        SLMLabel_2                      matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        FullScaleLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        SLMLabel                        matlab.ui.control.Label
        ReferencesPanel                 matlab.ui.container.Panel
        CrossSectionalAreacm2EditField  matlab.ui.control.NumericEditField
        CrossSectionalAreacm2EditFieldLabel  matlab.ui.control.Label
        KEditField_2                    matlab.ui.control.NumericEditField
        KEditField_2Label               matlab.ui.control.Label
        psiaEditField_2                 matlab.ui.control.NumericEditField
        psiaEditField_2Label            matlab.ui.control.Label
        FEditField_2                    matlab.ui.control.NumericEditField
        FEditField_2Label               matlab.ui.control.Label
        atmEditField_2                  matlab.ui.control.NumericEditField
        atmEditField_2Label             matlab.ui.control.Label
        DensityCorrectionFactorEditField  matlab.ui.control.NumericEditField
        DensityCorrectionFactorEditFieldLabel  matlab.ui.control.Label
        ChannelWidthcmSpinner           matlab.ui.control.Spinner
        ChannelWidthcmSpinnerLabel      matlab.ui.control.Label
        ReferenceTemperatureCSpinner    matlab.ui.control.Spinner
        ReferenceTemperatureCSpinnerLabel  matlab.ui.control.Label
        ReferencePressurekPaSpinner     matlab.ui.control.Spinner
        ReferencePressurekPaSpinnerLabel  matlab.ui.control.Label
        SetupPanel                      matlab.ui.container.Panel
        ChannelPressurePanel            matlab.ui.container.Panel
        ConnectArduinoButton            matlab.ui.control.Button
        SensorPressurepsiaEditField     matlab.ui.control.NumericEditField
        SensorPressurepsiaEditFieldLabel  matlab.ui.control.Label
        psiatokpaconverterLabel         matlab.ui.control.Label
        MFCInletPressurepsiaPanel       matlab.ui.container.Panel
        CEditField                      matlab.ui.control.NumericEditField
        CEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        EEditField                      matlab.ui.control.NumericEditField
        EEditFieldLabel                 matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.NumericEditField
        AEditFieldLabel                 matlab.ui.control.Label
        kpaEditField                    matlab.ui.control.NumericEditField
        kpaEditFieldLabel               matlab.ui.control.Label
        psiaSpinner                     matlab.ui.control.Spinner
        psiaSpinnerLabel                matlab.ui.control.Label
        cmEditField                     matlab.ui.control.NumericEditField
        cmEditFieldLabel                matlab.ui.control.Label
        ChannelTemperatureCSpinner      matlab.ui.control.Spinner
        ChannelTemperatureCSpinnerLabel  matlab.ui.control.Label
        DesiredOxygenCompositionOxygeninMixtureSpinner  matlab.ui.control.Spinner
        DesiredOxygenCompositionOxygeninMixtureSpinnerLabel  matlab.ui.control.Label
        psiaEditField                   matlab.ui.control.NumericEditField
        psiaEditFieldLabel              matlab.ui.control.Label
        Panel_15                        matlab.ui.container.Panel
        FinalVelocity1TotalcmsSpinner   matlab.ui.control.Spinner
        FinalVelocity1TotalcmsSpinnerLabel  matlab.ui.control.Label
        RampUpRate1TotalcmssSpinner     matlab.ui.control.Spinner
        RampUpRate1TotalcmssSpinnerLabel  matlab.ui.control.Label
        SLMEditField_17                 matlab.ui.control.NumericEditField
        SLMEditField_17Label            matlab.ui.control.Label
        SLMsEditField_14                matlab.ui.control.NumericEditField
        SLMsEditField_14Label           matlab.ui.control.Label
        Panel_14                        matlab.ui.container.Panel
        PlateauVelocity2TotalcmsSpinner  matlab.ui.control.Spinner
        PlateauVelocity2TotalcmsSpinnerLabel  matlab.ui.control.Label
        RampDownRate2TotalcmssSpinner   matlab.ui.control.Spinner
        RampDownRate2TotalcmssSpinnerLabel  matlab.ui.control.Label
        SLMEditField_16                 matlab.ui.control.NumericEditField
        SLMEditField_16Label            matlab.ui.control.Label
        SLMsEditField_13                matlab.ui.control.NumericEditField
        SLMsEditField_13Label           matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        SLMEditField_13                 matlab.ui.control.NumericEditField
        SLMEditField_13Label            matlab.ui.control.Label
        SLMsEditField_10                matlab.ui.control.NumericEditField
        SLMsEditField_10Label           matlab.ui.control.Label
        RampDownRate1BcmssEditField     matlab.ui.control.NumericEditField
        RampDownRate1BcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocity1BcmsEditField   matlab.ui.control.NumericEditField
        PlateauVelocity1BcmsEditFieldLabel  matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        SLMEditField_12                 matlab.ui.control.NumericEditField
        SLMEditField_12Label            matlab.ui.control.Label
        InitialVelocityBcmsEditField    matlab.ui.control.NumericEditField
        InitialVelocityBcmsEditFieldLabel  matlab.ui.control.Label
        SourceDGasDropDown              matlab.ui.control.DropDown
        SourceDGasDropDownLabel         matlab.ui.control.Label
        SourceBGasDropDown              matlab.ui.control.DropDown
        SourceBGasDropDownLabel         matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        PlateauVelocity1TotalcmsSpinner  matlab.ui.control.Spinner
        PlateauVelocity1TotalcmsSpinnerLabel  matlab.ui.control.Label
        RampDownRate1TotalcmssSpinner   matlab.ui.control.Spinner
        RampDownRate1TotalcmssSpinnerLabel  matlab.ui.control.Label
        SLMEditField_9                  matlab.ui.control.NumericEditField
        SLMEditField_9Label             matlab.ui.control.Label
        SLMsEditField_7                 matlab.ui.control.NumericEditField
        SLMsEditField_7Label            matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        InitialVelocityTotalcmsSpinner  matlab.ui.control.Spinner
        InitialVelocityTotalcmsSpinnerLabel  matlab.ui.control.Label
        TotalFlowSettingsLabel          matlab.ui.control.Label
        SLMEditField_8                  matlab.ui.control.NumericEditField
        SLMEditField_8Label             matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        SLMEditField_7                  matlab.ui.control.NumericEditField
        SLMEditField_7Label             matlab.ui.control.Label
        SLMsEditField_6                 matlab.ui.control.NumericEditField
        SLMsEditField_6Label            matlab.ui.control.Label
        RampDownRateAcmssEditField      matlab.ui.control.NumericEditField
        RampDownRateAcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocityAcmsEditField    matlab.ui.control.NumericEditField
        PlateauVelocityAcmsEditFieldLabel  matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        SLMEditField_6                  matlab.ui.control.NumericEditField
        SLMEditField_6Label             matlab.ui.control.Label
        SLMsEditField_5                 matlab.ui.control.NumericEditField
        SLMsEditField_5Label            matlab.ui.control.Label
        RampDownRate2AcmssEditField     matlab.ui.control.NumericEditField
        RampDownRate2AcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocity2AcmsEditField   matlab.ui.control.NumericEditField
        PlateauVelocity2AcmsEditFieldLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        SLMEditField_5                  matlab.ui.control.NumericEditField
        SLMEditField_5Label             matlab.ui.control.Label
        SLMsEditField_4                 matlab.ui.control.NumericEditField
        SLMsEditField_4Label            matlab.ui.control.Label
        RampDownRate1AcmssEditField     matlab.ui.control.NumericEditField
        RampDownRate1AcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocity1AcmsEditField   matlab.ui.control.NumericEditField
        PlateauVelocity1AcmsEditFieldLabel  matlab.ui.control.Label
        GapHeightmmSpinner              matlab.ui.control.Spinner
        GapHeightmmSpinnerLabel         matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        FEditField                      matlab.ui.control.NumericEditField
        FEditFieldLabel                 matlab.ui.control.Label
        atmEditField                    matlab.ui.control.NumericEditField
        atmEditFieldLabel               matlab.ui.control.Label
        ChannelPressurekPaSpinner       matlab.ui.control.Spinner
        ChannelPressurekPaSpinnerLabel  matlab.ui.control.Label
        Panel_13                        matlab.ui.container.Panel
        SLMEditField_15                 matlab.ui.control.NumericEditField
        SLMEditField_15Label            matlab.ui.control.Label
        SLMsEditField_12                matlab.ui.control.NumericEditField
        SLMsEditField_12Label           matlab.ui.control.Label
        RampUpRateBcmssEditField        matlab.ui.control.NumericEditField
        RampUpRateBcmssEditFieldLabel   matlab.ui.control.Label
        FinalVelocityBcmsEditField      matlab.ui.control.NumericEditField
        FinalVelocityBcmsEditFieldLabel  matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        SLMEditField_14                 matlab.ui.control.NumericEditField
        SLMEditField_14Label            matlab.ui.control.Label
        SLMsEditField_11                matlab.ui.control.NumericEditField
        SLMsEditField_11Label           matlab.ui.control.Label
        RampDownRate2BcmssEditField     matlab.ui.control.NumericEditField
        RampDownRate2BcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocity2BcmsEditField   matlab.ui.control.NumericEditField
        PlateauVelocity2BcmsEditFieldLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SLMEditField                    matlab.ui.control.NumericEditField
        SLMEditFieldLabel               matlab.ui.control.Label
        InitialVelocityAcmsEditField    matlab.ui.control.NumericEditField
        InitialVelocityAcmsEditFieldLabel  matlab.ui.control.Label
        SourceCGasDropDown              matlab.ui.control.DropDown
        SourceCGasDropDownLabel         matlab.ui.control.Label
        SourceAGasDropDown              matlab.ui.control.DropDown
        SourceAGasDropDownLabel         matlab.ui.control.Label
        ControlsPanel                   matlab.ui.container.Panel
        ResetDataButton                 matlab.ui.control.Button
        cameraButton                    matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        ExportDataButton                matlab.ui.control.Button
        RecordDataButton                matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        ReadMFCsButton                  matlab.ui.control.Button
        RampUpButton                    matlab.ui.control.Button
        RampDown1Button                 matlab.ui.control.Button
        RampDownO2Button                matlab.ui.control.Button
        RampDown2Button                 matlab.ui.control.Button
        AirVelocitycmsSpinner           matlab.ui.control.Spinner
        AirVelocitycmsSpinnerLabel      matlab.ui.control.Label
        StartAirButton                  matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
    end

    properties (Constant, Access = private)
        flowTableVarNames = { 'Timestamp (s)','Q_A [SLPM]','Q_B','Q_C','Q_D' };
        recordedDataTableVarNames = {'Timestamp (s)', 'PSIA', 'Q_A [SLPM]','Q_B','Q_C','Q_D', 'Q_E'};
        mfcIOTimerFreq = 30; %Hz
        RampFreq = 5;
        mfcIDs = 'ABCDE';
        
    end

    properties (Access = private)
        cameraApp %camera app instance
        % flowTable % table object to store the flow signals sent to the MFCs for Rampdowns and rampup
        flowTableSrc; %Array to be converted into a table upwon operation end
        recordedDataVec = zeros(1,7); %Array to store pressure, MFC flow rates, and time stamps
    
        
    end

    properties (Access = private, SetObservable)
        % Initializing variables within the gui
        gChPress; gRefPress; ChTempK; gRefTemp;
        gOxyComp; gIVT; gChannelWidth; gGapHeight;
        gPVT1; gRDRT1;
        gPVT2; gRDRT2; gRURT; gFVT;
        gO2_percent_plateau; gO2_percent_RDR;
        DC; A; Dryvel; QDry;
        gChPressATM;
        gChPressPSIA;
        gChTemp; ChTempF;
        gConverterPsia;
        gRefPressATM; gRefPressPSIA;
        RefTempF; RefTempK
        QT1; QB1; QA1; IVA; IVB; ISA50; ISA1; ISB50; ISB1;
        QRT1; QPT1; PVB1; QPB1; PVA1; QPA1; RDRA1; QRA1; RDRB1; QRB1;
        RD1T;
        O2_vel_plateau; O2_flow_plateau; N2_vel_plateau; N2_flow_plateau; O2_Q_ramp_rate; N2_Q_ramp_rate; duration
        QRT2; QPT2; PVB2; QPB2; PVA2; QPA2; RDRA2; RDRB2; QRA2; QRB2;
        RD2T;
        QRT3; QFT; PFB; QFB; PFA; QFA; RURA; RURB; QRA3; QRB3;
        RUT;
        
        ISA2MFC; ISB2MFC; N2Unit; NonN2Unit; O2Unit; NonO2Unit;

        N2UnitSmall; N2UnitLarge; O2UnitSmall, O2UnitLarge;
        
        N2UnitSmallMax;N2UnitLargeMax;O2UnitSmallMax; O2UnitLargeMax; N2UnitLargeMin;O2UnitLargeMin
        AFlowAct; BFlowAct; CFlowAct; DFlowAct; EFlowAct;
        AFlow = 0, BFlow = 0; CFlow = 0; DFlow = 0; EFlow = 0; % MFC flow signals 0-64000
        AFlowSLPM = 0; BFlowSLPM = 0; CFlowSLPM = 0; DFlowSLPM = 0; EFlowSLPM = 0; %MFC setpoint flow rates slpm
        
        LastSentSignal; % most recent signal/setpoint sent to mfcs

        RSA1; RSB1;
        
        sensorPressure; pressureDevice; sensorConversion; pressureVector; toggleData; 
        
        mfcIOTimer;
        
        inletPressureA; inletPressureB; inletPressureC; inletPressureD; inletPressureE; 
    
        displayFields;
        RampingActive = false; 
        MFCData;
        RampTimer;

        % Booleans to enable data recording, ssee startupFcn for
        % definitions
        recordDataListeners = struct();

        % Timer for recording data
        recordDataStopwatch;

        % IO tasks
        TaskMap;

        mfcPollIndex = 1;
    end

    methods (Access = public)
        
        function handleIncoming(app, src, ~)
            sensorOut = readline(src);

            if isempty(sensorOut)
                return
            end
            app.sensorPressure = str2double(sensorOut);

            app.recordData();
        end
        
        function sensorChange(app, ~, ~)
            % This function is not used anymore. Using a listener to write
            % the sensor data with a timestamp does not work. The order in
            % which listeners callback functions execute after the firing
            % of an event is undefined. This means the time that the signal
            % was recieved and the time the values were written were not
            % the same. This casued several values to be stacked/loaded in
            % the buffer than read all at once or within 1 ms of one
            % another.
            sensorPressurePSIA = app.sensorPressure*0.2897 + 0.5;
            app.SensorPressurepsiaEditField.Value = sensorPressurePSIA;

            if app.toggleData == true

                % use camera internal timer if camera is open 
                if ~(isempty(app.cameraApp) || ~isvalid(app.cameraApp))
                    % Get elapsed recording time from camera app. Returns
                    % zero if camera is not recording
                    % "measured";
                    time = app.cameraApp.readRecordingStopwatch(); %Save time in first column, might be slow
                else
                    time = toc(app.recordDataStopwatch);
                end

                app.recordedDataVec = [app.recordedDataVec; ...
                    time, sensorPressurePSIA, app.AFlowAct, app.BFlowAct,...
                    app.CFlowAct/1000, app.DFlowAct/1000, app.EFlowAct];

                app.pressureVector = [app.pressureVector app.SensorPressurepsiaEditField.Value];
            end
        end
        
        function recordData(app)
            sensorPressurePSIA = app.sensorPressure*0.2897 + 0.5;

            % The line below should be in its own function 
            %app.SensorPressurepsiaEditField.Value = sensorPressurePSIA;

            if app.toggleData == true

                % use camera internal timer if camera is open 
                if ~(isempty(app.cameraApp) || ~isvalid(app.cameraApp))
                    % Get elapsed recording time from camera app. Returns
                    % zero if camera is not recording
                    % "measured";
                    time = app.cameraApp.readRecordingStopwatch(); %Save time in first column, might be slow
                else
                    time = toc(app.recordDataStopwatch);
                end

                app.recordedDataVec = [app.recordedDataVec; ...
                    time, sensorPressurePSIA, app.AFlowAct, app.BFlowAct,...
                    app.CFlowAct/1000, app.DFlowAct/1000, app.EFlowAct];

                app.pressureVector = [app.pressureVector app.SensorPressurepsiaEditField.Value];
            end
        end

        function collectMFC(app, ~, ~)

            if app.RampingActive
                return
            else        
                try
                    flushAlicatBuffer;
                    
                    % tic % this takes 0.2 seconds omg so slow 
                    pollArrayA = readMFC('A');
                    pollArrayB = readMFC('B');
                    pollArrayC = readMFC('C');
                    pollArrayD = readMFC('D');
                    pollArrayE = readMFC('E');
                    % toc

                    % outPollArray = app.readAllMFCS();
                    % pollArrayA = outPollArray(1);
                    % pollArrayB = outPollArray(2);
                    % pollArrayC = outPollArray(3);
                    % pollArrayD = outPollArray(4);
                    % pollArrayE = outPollArray(5);
                    % toc

                    app.AFlowAct = pollArrayA.massFlow;
                    app.inletPressureA = pollArrayA.pressure;
                    app.EditField_11.Value = app.AFlowAct;
                    app.EditField_12.Value = app.EditField_11.Value / (app.A * app.DC * 0.06);
                    app.AEditField.Value = app.inletPressureA;
    
                    app.BFlowAct = pollArrayB.massFlow;
                    app.inletPressureB = pollArrayB.pressure;
                    app.EditField_13.Value = app.BFlowAct;
                    app.EditField_14.Value = app.EditField_13.Value / (app.A * app.DC * 0.06);
                    app.BEditField.Value = app.inletPressureB;
    
                    app.CFlowAct = pollArrayC.massFlow;
                    app.inletPressureC = pollArrayC.pressure;
                    app.EditField_16.Value = app.CFlowAct / 1000;
                    app.EditField_15.Value = app.EditField_16.Value / (app.A * app.DC * 0.06);
                    app.CEditField.Value = app.inletPressureC;
    
                    app.DFlowAct = pollArrayD.massFlow;
                    app.inletPressureD = pollArrayD.pressure;
                    app.EditField_17.Value = app.DFlowAct / 1000;
                    app.EditField_18.Value = app.EditField_17.Value / (app.A * app.DC * 0.06);
                    app.DEditField.Value = app.inletPressureD;
    
                    app.EFlowAct = pollArrayE.massFlow;
                    app.inletPressureE = pollArrayE.pressure;
                    app.EditField_19.Value = app.EFlowAct;
                    app.EditField_20.Value = app.EditField_19.Value / (app.A * app.DC * 0.06);
                    app.EEditField.Value = app.inletPressureE;
    
                    app.ActualTotalFlowEditField.Value = app.EditField_11.Value + app.EditField_13.Value + app.EditField_16.Value + app.EditField_17.Value + app.EditField_19.Value;
    
                    app.ActualTotalVelocityEditField.Value = app.ActualTotalFlowEditField.Value / (app.A * app.DC * 0.06);
    
                    bottleValue = app.EditField_11.Value + app.EditField_13.Value + app.EditField_16.Value + app.EditField_17.Value;
                    pressureCheckGood = app.inletPressureA > 30 && app.inletPressureB > 30 && app.inletPressureC > 30 && app.inletPressureD > 30;
                    pressureCheckBad = app.inletPressureA < 30 || app.inletPressureB < 30 || app.inletPressureC < 30 || app.inletPressureD < 30;
                    warningMessage = ["MFC flow stopped.", "Inlet pressure too low (<30 psi)."];
    
                    % -----David's check-----
                    % if strcmp(app.StartButton.Enable, "off") && pressureCheckGood
                    %     app.StartButton.Enable = "on";
                    % elseif strcmp(app.StartButton.Enable, "on") && pressureCheckBad
                    %     app.StartButton.Enable = "off";
                    % end
                    % - just going to rewrite enable property, dont feel
                    % like checking each button

                    if pressureCheckGood
                        app.StartButton.Enable = "on";
                        app.RampDown1Button.Enable = "on";
                        app.RampDown2Button.Enable = "on";
                        app.RampDownO2Button.Enable = "on";
                        app.RampUpButton.Enable = "on";
                    elseif pressureCheckBad
                        app.StartButton.Enable = "off";
                        app.RampDown1Button.Enable = "off";
                        app.RampDown2Button.Enable = "off";
                        app.RampDownO2Button.Enable = "off";
                        app.RampUpButton.Enable = "off";
                    end                  
                    
    
                    if pressureCheckBad && bottleValue > 1
                        StopButtonPushed(app, []);
                        uialert(app.NarrowChannel, warningMessage, "Warning", "Icon", "warning");
                    end
                catch e
                    disp(e)
                    disp(e.message)
                end
            end
        end
    end
    
    methods (Access = private)
        function RampDown(app,Ramp1)
            % Modified by Jack T. Volponi 10/2/2025
            % ------------------------------------------------------------------------
            % Rewriting the code for better precision at lower flowrates. This version
            % of RampDown should always set the initial flow rate first with small MFC,
            % and add onto the initial to achieve the total flow if the total flow is
            % greater than the capacity of the small mfc. It will then ramp down the
            % larger MFC first, then ramp the small mfc to minimize the error.
            % Modified by JTV 1/9/2026
            % Changed number of timesteps to a frequency at which a signal to the MFC
            % is sent. The MFCs operate at 19200 baud rate which corresponds to 1
            % second for 19200 bits, or ~52 ms/bit. To send a 16-bit signal to the MFC
            % it would talk ~52*16 =~ 0.83 ms which corresponds to a frequency of ~1200
            % Hz. This is the max frequency since this is the fastest the MFC can
            % operate. For a saftey margin I am implementing a max of 1000 Hz. I am
            % going to make it so that the display only updates at 4 Hz though for
            % performance.
            % Modified by JTV 1/13/2026, changing function to just pass app instead of
            % all these arguents
            
            % Requires the following app properties (units in brackets):
            %   app.gIVT          Total Initial Velocity [cm/s]
            %   app.gPVT1         Total Plateau Velocity [cm/s]
            %   app.gRDRT1        Total Velocity Rampdown Rate [cm/s^2]
            %   app.gOxyComp      Oxygen Concentration [%]
            %   app.A             Cross-sectional area [cm^2]
            %   app.DC            Density correction factor [-]
            %   app.MFCData       Struct defining MFC tags/ranges
            %   app.displayFields Struct of UI fields to update
            % Optional:
            %   app.RampFreq    Ramp command frequency [Hz] (default 10)
            
            arguments 
                app (1,1) {mustBeA(app,"matlab.apps.AppBase")} %App handle
                Ramp1(1,1) logical %boolean to indicate if Rampdown 1 or Rampdown 2 was pressed
            end
            
            % ---- Pull required values from app (keeps rest of code readable) ----
            if Ramp1
                IVT           = app.gIVT;
                PVT           = app.gPVT1;
                RDRT          = app.gRDRT1;
            else 
                IVT           = app.gPVT1;
                PVT           = app.gPVT2;
                RDRT          = app.gRDRT2;
            end
            
            OxyComp       = app.gOxyComp;
            A             = app.A;
            DC            = app.DC;
            MFCStruct     = app.MFCData;
            displayFields = app.displayFields;
            
            % ---- Optional args ----
            if isprop(app,"RampFreq") && ~isempty(app.RampFreq)
                f = app.RampFreq;
            else
                f = 10;
            end
            validateattributes(f, {'numeric'},{'scalar','real','finite','>',0});
            
            % ---- Validate critical inputs (keeps failure modes crisp) ----
            validateattributes([IVT PVT RDRT OxyComp A DC], {'numeric'}, {'real','finite'});
                    
            
            RDT = (IVT - PVT)/RDRT; %Ramp Down Time (Total)
            % N = 50; %Adjust this value to find a good spot to match the ramp time. Too low = longer pauses, Too high = more iterations (too long).
            % dt = RDT / N; %Fraction of the total time RDT (Ramp Down Time) dictated by N
            % D_dt = RDRT*dt; %Ramp down in a fraction of time. D_dt = Decrease per dt
            
            % f = 20; %Frequency 
            N = ceil(f*RDT); %Number of executions (also number of lines on file)
            dt = 1/f; %Period of timer
            % Q_step = (IVT - PVT)/N; %Amount to step down each execution
            
            
            %------Timer setup--------
            t = timer;
            t.period = dt;
            t.TasksToExecute = N;
            %Add the timer callback function to the matlab execution que every dt
            %seconds. This does not wait for the queue lag time or the callback function
            %execution time before adding another subsequent execution of the callback
            %function.
            t.ExecutionMode = 'fixedRate';
            
            %Set the busy mode to drop so that the signals are continuously set at 20
            %Hz. If a queue forms, the execution will be dropped and that flow will be
            %set at the next execution. (Not sure if this is good since it will lead to
            %the flow being set at the next execution but it will probably change the
            %rate
            
            t.BusyMode = "drop";
            % t.BusyMode = "error";
            
            %Set the callback function to the helper below and pass the neccesary
            %arguments as a cell array
            t.TimerFcn = @(obj,event) app.RampVeltimerCallback(obj,event,IVT,PVT,RDRT,OxyComp,A,DC,MFCStruct,displayFields,f);
            t.StartFcn = @(obj,event) app.rampTimerStart(obj,event);
            t.StopFcn  = @(obj,event) app.rampTimerEnd(obj,event);
            t.ErrorFcn = @(obj,event) app.rampDownTimerErr(obj,event);
            
            t.Name = "rampdown_timer";
    
            %Store the timer in the app
            app.RampTimer = t;
    
            % Define the number of lines in the flow table if the check box is
            % checked
            % if app.saveFileCheckBox.Value
            %     % test_tab = [test_tab; array2table(zeros(100,5),'VariableNames',test_tab.Properties.VariableNames)]
            %     % Add N-1 rows to the flow table. This is the maximum # of
            %     % lines in the file since the RampDown function will execute at
            %     % max N times. This allocates the table array.
            %     app.flowTableSrc = zeros(N,5);
            %     % app.flowTable = [app.flowTable; array2table(zeros(N-1,5),'VariableNames',app.flowTable.Properties.VariableNames)];
            % end        
    
            %Start timer process
            t.UserData.file_idx = 1; %Index for the flow conditions file
            t.UserData.elapsedTime = tic;
            start(t)


            %------ OLD RAMPDOWN CODE------- Removed 10/7/2025
            % tic
            % for i = 1:N
            %     RVT = IVT + D_dt*i; %(Ramp Velocity Total decreasing) --> opposite in RampUp.m
            %     Q_T1 = RVT*A*DC*0.06; %Flow Total
            %     Q_B1 = OxyComp*Q_T1/100; %Flow A
            %     Q_A1 = Q_T1 - Q_B1; %Flow B
            % 
            %     %Set Flows Here
            %     setFlow(Q_A1, N2Unit);
            %     setFlow(Q_B1, O2Unit);
            % 
            %     pause(dt);
            % end
            % toc
        end
        
        function RampVeltimerCallback(app,obj,event,IVT, PVT, RDRT, OxyComp, A, DC,MFCStruct,displayFields,f)
            % Update the current total flow, and calculate the neccesary Gas A and
            % B flow rates.
            % RVT = IVT - obj.period*RDRT*obj.TasksExecuted; %(Ramp Velocity Total decreasing) --> opposite in RampUp.m [cm/s]
            % try    
            obj.UserData.callbackExecuting = true;
            
            currTime = toc(obj.UserData.elapsedTime);
            RVT = IVT - RDRT*currTime; 

            %Prevent the callback from undershooting
            RVT = max(PVT,RVT); 

            RVT; %Print for debugging purposes 
            Q_T1 = RVT*A*DC*0.06; %Flow Total [SLM]
            Q_B1 = OxyComp*Q_T1/100; %Flow B, O2 [SLM]
            Q_A1 = Q_T1 - Q_B1; %Flow A, N2 [SLM]
        
            %Calculate setpoints for each MFC and set their flows. Output the
            %setpoints for updating display fields in future\
            % f = 20;
            
            %Calculate flow rates and set the flows for all the MFCs    
            [QASmallSetpoint,QALargeSetpoint,QBSmallSetpoint,QBLargeSetpoint] = ...
                app.distributeFlow(Q_A1,Q_B1);

            taskName = sprintf('rampingFlow mfc:',currTime);

            % Large MFC Flow Tasks
            app.addTask([taskName 'A'], @() app.setNCAFlow(QALargeSetpoint,'A'),1);
            app.addTask([taskName 'B'], @() app.setNCAFlow(QBLargeSetpoint,'B'),1);

            % Small MFC Flow Tasks
            app.addTask([taskName 'C'], @() app.setNCAFlow(QASmallSetpoint,'C'),1);
            app.addTask([taskName 'D'], @() app.setNCAFlow(QBSmallSetpoint,'D'),1);

            % if mod(obj.TasksExecuted,f) == 0
            %     % "updated display"
            %     % update display at 1 hz
            %     taskName = sprintf('rampingFlow %4f.2 and update ',currTime);
            % 
            %     % Large MFC Flow Tasks
            %     app.addTask([taskName 'A'], @() app.setNCAFlow(QALargeSetpoint,'A'),1);
            %     app.addTask([taskName 'B'], @() app.setNCAFlow(QBLargeSetpoint,'B'),1);
            % 
            %     % Small MFC Flow Tasks
            %     app.addTask([taskName 'C'], @() app.setNCAFlow(QASmallSetpoint,'C'),1);
            %     app.addTask([taskName 'D'], @() app.setNCAFlow(QBSmallSetpoint,'D'),1);
            % 
            % else
            %     taskName = sprintf('rampingFlow %4f.2 mfc:',currTime);
            % 
            %     % Large MFC Flow Tasks
            %     app.addTask([taskName 'A'], @() app.setNCAFlow(QALargeSetpoint,'A'),1);
            %     app.addTask([taskName 'B'], @() app.setNCAFlow(QBLargeSetpoint,'B'),1);
            % 
            %     % Small MFC Flow Tasks
            %     app.addTask([taskName 'C'], @() app.setNCAFlow(QASmallSetpoint,'C'),1);
            %     app.addTask([taskName 'D'], @() app.setNCAFlow(QBSmallSetpoint,'D'),1);
            % 
            %     % [QASmallSetpoint,QALargeSetpoint,QBSmallSetpoint,QBLargeSetpoint] = NCASetpoints(Q_A1,Q_B1,MFCStruct,true,updateFields=false);                 
            % end

            %Write save file table if checked
            % if app.saveFileCheckBox.Value 
            %     % if camera app is open and recording, save the timestamp
            %     % from the camera elapsed time in the file (probably needs
            %     % to be in NCASetpoints() though
            %     if ~(isempty(app.cameraApp) || ~isvalid(app.cameraApp))
            %         % Get elapsed recording time from camera app. Returns
            %         % zero if camera is not recording
            %         % "measured";
            %         app.flowTableSrc(obj.UserData.file_idx, 1) = app.cameraApp.readRecordingStopwatch(); %Save time in first column
            %         % app.flowTable.("Timestamp (s)")(obj.UserData.file_idx) = app.cameraApp.readRecordingStopwatch(); %this might be slow
            % 
            %     else
            %         %If no camera app, use original app timer
            %         app.flowTableSrc(obj.UserData.file_idx, 1) = toc(obj.UserData.elapsedTime);                    
            %     end
            %     % app.flowTableSrc(obj.UserData.file_idx, 2) = QALargeSetpoint;
            %     % app.flowTableSrc(obj.UserData.file_idx, 3) = QBLargeSetpoint;
            % 
            %     %Save all setpoints in source array
            %     app.flowTableSrc(obj.UserData.file_idx, 2:5) = [QALargeSetpoint, QBLargeSetpoint, QASmallSetpoint, QBSmallSetpoint];
            % 
            %     % app.flowTable.("Q_A [SLPM]")(obj.UserData.file_idx) = QALargeSetpoint;
            %     % app.flowTable.("Q_B")(obj.UserData.file_idx) = QBLargeSetpoint;
            %     % app.flowTable.("Q_C")(obj.UserData.file_idx) = QASmallSetpoint;
            %     % app.flowTable.("Q_D")(obj.UserData.file_idx) = QBSmallSetpoint;
            % 
            % 
            %     % Increment line in table
            %     obj.UserData.file_idx = obj.UserData.file_idx+1;             
            % 
            % end
        end
        
        function rampTimerStart(app,obj,event)
            app.RampingActive = true;
            app.RampActiveLamp.Enable = "on";
        end
        
        function rampTimerEnd(app,obj,event)
            % obj.UserData.callbackExecuting
            app.RampingActive = false;             
            

            % Write the flowTable to a file if checked
            % if app.saveFileCheckBox.Value
            %     filename = string(app.filenameField.Value) + string(app.fileExtensionDropDown.Value);
            % 
            %     %Create table out of table source array
            %     flowTable = array2table(app.flowTableSrc,"VariableNames",app.flowTableVarNames);
            % 
            %     %Writetable to file 
            %     writetable(flowTable,filename);
            % 
            %     %Free table from memory/ empty flowTable
            %     app.flowTableSrc = [];
            %     % app.flowTable = table(0,0,0,0,0,'VariableNames',["Timestamp (s)","Q_A [SLPM]","Q_B","Q_C","Q_D"]);
            % end

            %Remove the timer from memory 
            delete(obj);
            app.RampTimer = [];

            %Turn off Lamp
            app.RampActiveLamp.Enable = "off";

        end   

        function rampDownTimerErr(app,obj,event)
            fprintf("rampdown timer function error \n")
        end
        
        function startMovieOnButton(app,callerButton)            
            %if camera app does not exist, do nothing 
            if (isempty(app.cameraApp) || ~isvalid(app.cameraApp))
                return;
            end
            
            % Check if caller argument is one of the following
            callerInDropDown = ismember(callerButton, ["Start (Main app)", ...
                    "Rampdown 1 (Main app)", "Rampdown 2 (Main app)", ...
                    "Rampdown O2 (Main app)"]);
            
            if ~callerInDropDown
                error("caller button not a member of selectable buttons")
            end
            
            % Get the value of the dropdown in the camera app
            startMovieButton =...
                app.cameraApp.StartMovieOnButtonPressDropDown.Value;

            % if the caller button matches the selection in the camera app,
            % start the movie
            if callerButton == startMovieButton
                app.cameraApp.pressStartMovie();
            else
                return
            end
        end

        %Function/routine to rampdown the oxygen concentation
        function RampDownO2(app)
            % Ramp down oxygen routine
            % Jack Volponi 2/12/2026

            %RampDownOxygen(app.QT1, app.gO2_percent_plateau, app.gO2_percent_RDR, app.gOxyComp, app.N2Unit, app.O2Unit )
            
            %Get required values
            QTotal             = app.QT1;
            O2_percent_plateau = app.gO2_percent_plateau;
            O2_percent_RDR     = app.gO2_percent_RDR;
            N2Unit             = app.N2Unit;
            O2Unit             = app.O2Unit;
            OxyComp            = app.gOxyComp;

            % Collect total rampdown time
            RDT = app.duration; %O2 Rampdown Time (Total)

            % Calculate number of timer callback executions (# of flow
            % rates to send to MFCs)
            f = app.RampFreq; %Frequencty to execute timerFcn at
            N = ceil(abs(f*RDT)); %Maximum # of executions
            dt = 1/f; %timer period [s]

            %---- Setup timer------
            t = timer;
            t.period = dt;
            t.TasksToExecute = N;
            t.ExecutionMode = 'fixedRate';
            t.BusyMode = 'drop';
            t.Name = "O2_rampdown_timer";
            t.TimerFcn = @(obj,event) app.o2TimerCallback(obj,event);
            t.StartFcn = @(obj,event) app.rampTimerStart(obj,event);
            t.StopFcn  = @(obj,event) app.rampTimerEnd(obj,event);


            % Store timer in global state
            app.RampTimer = t;

            % Define the number of lines in the flow table if the check box is
            % checked
            % if app.saveFileCheckBox.Value
            %     % test_tab = [test_tab; array2table(zeros(100,5),'VariableNames',test_tab.Properties.VariableNames)]
            %     % Add N-1 rows to the flow table. This is the maximum # of
            %     % lines in the file since the RampDown function will execute at
            %     % max N times. This allocates the table array.
            %     app.flowTableSrc = zeros(N,5);
            %     % app.flowTable = [app.flowTable; array2table(zeros(N-1,5),'VariableNames',app.flowTable.Properties.VariableNames)];
            % end      
    
            %Start timer process
            t.UserData.file_idx = 1; %Index for the flow conditions file
            t.UserData.elapsedTime = tic;
            start(t)                   

        end
        
        % Define Timer callback for O2 ramping
        function o2TimerCallback(app,obj,event)
            %Calculate the current needed O2 percented based on how
            %long timer has been running
            currTime = toc(obj.UserData.elapsedTime);
            
            currentO2Percent = app.gOxyComp - app.gO2_percent_RDR*currTime;

            %Calculate the flow rates for nitrogen and oxygen
            Q_B = app.QT1*currentO2Percent/100; %Flow B, O2 [SLM]
            Q_A = app.QT1 - Q_B; %Flow A, N2 [SLM]

            %Calculate flow rates and set the flows for all the MFCs    
            [QASmallSetpoint,QALargeSetpoint,QBSmallSetpoint,QBLargeSetpoint] = ...
                app.distributeFlow(Q_A,Q_B);
            
            taskName = sprintf('rampingO2 mfc:');

            % Large MFC Flow Tasks
            app.addTask([taskName 'A'], @() app.setNCAFlow(QALargeSetpoint,'A'),1);
            app.addTask([taskName 'B'], @() app.setNCAFlow(QBLargeSetpoint,'B'),1);

            % Small MFC Flow Tasks
            app.addTask([taskName 'C'], @() app.setNCAFlow(QASmallSetpoint,'C'),1);
            app.addTask([taskName 'D'], @() app.setNCAFlow(QBSmallSetpoint,'D'),1);

            % if mod(obj.TasksExecuted,app.RampFreq) == 0
            %     % "updated display"
            %     % update display at 1 hz
            %     % Remove unique names so that stale flows setpoints do not
            %     % pile up
            %     taskName = sprintf('rampingO2 and update');
            % 
            %     % Large MFC Flow Tasks
            %     app.addTask([taskName 'A'], @() app.setNCAFlow(QALargeSetpoint,'A'),1);
            %     app.addTask([taskName 'B'], @() app.setNCAFlow(QBLargeSetpoint,'B'),1);
            % 
            %     % Small MFC Flow Tasks
            %     app.addTask([taskName 'C'], @() app.setNCAFlow(QASmallSetpoint,'C'),1);
            %     app.addTask([taskName 'D'], @() app.setNCAFlow(QBSmallSetpoint,'D'),1);
            % 
            % else
            %     taskName = sprintf('rampingO2 mfc:');
            % 
            %     % Large MFC Flow Tasks
            %     app.addTask([taskName 'A'], @() app.setNCAFlow(QALargeSetpoint,'A'),1);
            %     app.addTask([taskName 'B'], @() app.setNCAFlow(QBLargeSetpoint,'B'),1);
            % 
            %     % Small MFC Flow Tasks
            %     app.addTask([taskName 'C'], @() app.setNCAFlow(QASmallSetpoint,'C'),1);
            %     app.addTask([taskName 'D'], @() app.setNCAFlow(QBSmallSetpoint,'D'),1);
            % end
            
            % %Write save file table if checked
            % if app.saveFileCheckBox.Value 
            %     % if camera app is open and recording, save the timestamp
            %     % from the camera elapsed time in the file (probably needs
            %     % to be in NCASetpoints() though
            %     if ~(isempty(app.cameraApp) || ~isvalid(app.cameraApp))
            %         % Get elapsed recording time from camera app. Returns
            %         % zero if camera is not recording
            %         % "measured";
            %         app.flowTableSrc(obj.UserData.file_idx, 1) = app.cameraApp.readRecordingStopwatch(); %Save time in first column, might be slow
            % 
            %     else
            %         %If no camera app, use original app timer
            %         app.flowTableSrc(obj.UserData.file_idx, 1) = toc(obj.UserData.elapsedTime);                    
            %     end
            %     %Save all setpoints in source array
            %     app.flowTableSrc(obj.UserData.file_idx, 2:5) = [QALargeSetpoint, QBLargeSetpoint, QASmallSetpoint, QBSmallSetpoint];
            % 
            %     % Increment line in table
            %     obj.UserData.file_idx = obj.UserData.file_idx+1;             
            % 
            % end          
        end
        
        function OUT = readAllMFCS(app,quiet)
            % Read data from all 5 MFCs in the NCA experimental setup
            % Behaves similarly to readMFC() helper function, but is
            % optimized for reading all MFCs. Prevents ping, wait, read
            % loop behavior, and pings all MFCS, then reads all 5
            % responses.
            % Returns an array of Structs for each MFC instead of a single
            % output struct. OUT is in alphabetical order. 

            global aliComm;
            if isempty(aliComm), aliComm = connectAlicat; end

            if nargin<2, quiet = 1; end

            MFCs = ['A','B','C','D','E'];
            OUT(length(MFCs)).ID = [];
            scanTime = now;

            try                               
                % Ping all MFCs
                for i = 1:length(MFCs)
                    fprintf(aliComm,MFCs(i));
                    IN{i} = fgetl(aliComm); %fscanf(aliComm);
                    % pause(0.001)
                end

                % Read back the responses 
                for i = 1:length(MFCs)
                    % IN = fscanf(aliComm);

                    % [...
                    %     OUT(i).ID, ...
                    %     OUT(i).pressure,...
                    %     OUT(i).temp,...
                    %     OUT(i).volumetricFlow,...
                    %     OUT(i).massFlow,...
                    %     OUT(i).setPoint,...
                    %     OUT(i).gas...
                    %     ]=...
                    %     strread(IN{i},'%s%f%f%f%f%f%s', 'delimiter', ' '); %Format the data
                    C = textscan(IN{i}, '%s%f%f%f%f%f%s', 'Delimiter', ' ');

                    OUT(i).ID             = '';
                    OUT(i).pressure       = 0;
                    OUT(i).temp           = 0;
                    OUT(i).volumetricFlow = 0;
                    OUT(i).massFlow       = 0;
                    OUT(i).setPoint       = 0;
                    OUT(i).gas            = '';

                    if ~isempty(C{1}) && ~isempty(C{1}{1}), OUT(i).ID             = C{1}{1}; end
                    if ~isempty(C{2}),                      OUT(i).pressure       = C{2}(1); end
                    if ~isempty(C{3}),                      OUT(i).temp           = C{3}(1); end
                    if ~isempty(C{4}),                      OUT(i).volumetricFlow = C{4}(1); end
                    if ~isempty(C{5}),                      OUT(i).massFlow       = C{5}(1); end
                    if ~isempty(C{6}),                      OUT(i).setPoint       = C{6}(1); end
                    if ~isempty(C{7}) && ~isempty(C{7}{1}), OUT(i).gas            = C{7}{1}; end

                    % OUT(i).ID = cell2mat(OUT(i).ID);
                    % OUT(i).gas = cell2mat(OUT(i).gas);
                    OUT(i).time = scanTime;

                    %Set flow variables to zero if empty
                    specialArray = fieldnames(OUT(i));                  

                    % for j = 2:numel(specialArray) - 2
                    %     if isempty(OUT(i).(specialArray{j}))
                    %         OUT(i).(specialArray{j}) = 0;
                    %     end
                    % end

                    if isempty(OUT(i).ID)
                        OUT(i).ID = MFCs(i);
                    end
                end

            catch e
                if ~quiet
                    disp('Problem reading from serial port')
                    disp(e.message)
                end

            end

            flushAlicatBuffer;
        end
    
    % function OUT = readAllMFCS(app, quiet)
    % 
    %     global aliComm;
    %     if isempty(aliComm), aliComm = connectAlicat; end
    %     if nargin < 2, quiet = 1; end
    % 
    %     MFCs = 'ABCDE';
    %     nMFC = numel(MFCs);
    % 
    %     % Preallocate defaults
    %     OUT = repmat(app.defaultMFCStruct, 1, nMFC);
    %     scanTime = now;
    % 
    %     try
    %         % Clear stale input BEFORE starting this burst
    %         flushinput(aliComm);   % or your equivalent input-only flush
    % 
    %         % Send all polls with a tiny gap
    %         for i = 1:nMFC
    %             fprintf(aliComm, MFCs(i));
    %             pause(0.002);   % try 1-5 ms experimentally
    %         end
    % 
    %         got = false(1, nMFC);
    %         tStart = tic;
    % 
    %         while ~all(got) && toc(tStart) < 0.2
    %             IN = fgetl(aliComm);   % one line at a time
    % 
    %             if isempty(IN)
    %                 continue
    %             end
    % 
    %             S = app.parseMFCLine(IN, scanTime);
    % 
    %             if isempty(S.ID)
    %                 continue
    %             end
    % 
    %             idx = find(MFCs == S.ID, 1);
    %             if ~isempty(idx)
    %                 OUT(idx) = S;
    %                 got(idx) = true;
    %             end
    %         end
    % 
    %         % Fill missing IDs
    %         for i = 1:nMFC
    %             if isempty(OUT(i).ID)
    %                 OUT(i).ID = MFCs(i);
    %             end
    %         end
    % 
    %     catch e
    %         if ~quiet
    %             disp('Problem reading from serial port')
    %             disp(e.message)
    %         end
    %         OUT = [];
    %     end
    % end
    % 
    % 
    % function S = parseMFCLine(app,IN, scanTime)
    %     C = textscan(IN, '%s%f%f%f%f%f%s', 'Delimiter', ' ');
    % 
    %     S = app.defaultMFCStruct;
    %     S.time = scanTime;
    % 
    %     if ~isempty(C{1}) && ~isempty(C{1}{1}), S.ID = C{1}{1}; end
    %     if ~isempty(C{2}) && ~isempty(C{2}(1)), S.pressure = C{2}(1); end
    %     if ~isempty(C{3}) && ~isempty(C{3}(1)), S.temp = C{3}(1); end
    %     if ~isempty(C{4}) && ~isempty(C{4}(1)), S.volumetricFlow = C{4}(1); end
    %     if ~isempty(C{5}) && ~isempty(C{5}(1)), S.massFlow = C{5}(1); end
    %     if ~isempty(C{6}) && ~isempty(C{6}(1)), S.setPoint = C{6}(1); end
    %     if ~isempty(C{7}) && ~isempty(C{7}{1}), S.gas = C{7}{1}; end
    % end
    % 
    % 
    % function S = defaultMFCStruct(app)
    %     S = struct( ...
    %         'ID', '', ...
    %         'pressure', 0, ...
    %         'temp', 0, ...
    %         'volumetricFlow', 0, ...
    %         'massFlow', 0, ...
    %         'setPoint', 0, ...
    %         'gas', '', ...
    %         'time', NaN);
    % end

        % Function to handle all other actions related to MFC Input, output
        % and control. Current Rate is 10 Hz
        function mfcIOcallback(app,obj,event)
            tasksExecuted = obj.TasksExecuted;

            try
                taskRan = app.runOneTask();

                % Only do extra MFC polling when not ramping.
                % GUI fields still update below.
                if ~taskRan && ~app.RampingActive && mod(tasksExecuted,app.mfcIOTimerFreq/10) == 0
                    app.collectSingleMFC(app.mfcIDs(app.mfcPollIndex));

                    app.mfcPollIndex = app.mfcPollIndex + 1;
                    if app.mfcPollIndex > numel(app.mfcIDs)
                        app.mfcPollIndex = 1;
                    end
                end

                % Always update GUI at 1 Hz, even during ramps.
                if mod(tasksExecuted, app.mfcIOTimerFreq) == 0
                    app.updateMFCFields();
                    app.updatePressureSensorField();
                end

            catch exception
                disp(exception.message)
                disp(exception.stack(1))
            end
        end
        
        function taskRan = runOneTask(app)
            taskRan = false; 
            keys = app.TaskMap.keys;

            for i = 1:numel(keys)
                key = keys{i};

                if ~isKey(app.TaskMap, key)
                    continue
                end

                task = app.TaskMap(key);

                try
                    task.fcn();

                    if isfinite(task.remainingRuns)
                        task.remainingRuns = task.remainingRuns - 1;
                    end

                    if task.remainingRuns <= 0
                        remove(app.TaskMap, key);
                    else
                        app.TaskMap(key) = task;
                    end

                catch ME
                    warning('Task %s failed: %s', key, ME.message);
                end
                
                taskRan = true;
                % fprintf("task ran\n");
                return   % <- critical: only one task per tick
            end
        end

        function addTask(app, name, fcn, remainingRuns)
            if nargin < 4 || isempty(remainingRuns)
                remainingRuns = Inf;  % default: persistent
            end

            task.fcn = fcn;
            task.remainingRuns = remainingRuns;

            app.TaskMap(name) = task;
        end

        function removeTask(app, name)
            if isKey(app.TaskMap,name)
                remove(app.TaskMap, name)
            end
        end
       
        function clearTasks(app)
            app.TaskMap = containers.Map('KeyType','char','ValueType','any');
        end

        function collectSingleMFC(app,MFC)
            %Function that updates the global value of an MFC
            try
                pollArray = readMFC(MFC);
            catch exception
                disp(exception.message)
                disp(exception.stack(1))
            end
            
            % if(isnan(pollArray.massFlow))
            %     disp("aga")
            % end
            
            app.updateGlobMfcActuals(MFC,pollArray);
            % try
            %     switch MFC
            %         case 'A'
            %             app.AFlowAct = pollArray.massFlow;
            %             app.inletPressureA = pollArray.pressure;
            % 
            %         case 'B'
            %             app.BFlowAct = pollArray.massFlow;
            %             app.inletPressureB = pollArray.pressure;
            % 
            %         case 'C'
            %             app.CFlowAct = pollArray.massFlow;
            %             app.inletPressureC = pollArray.pressure;
            % 
            %         case 'D'
            %             app.DFlowAct = pollArray.massFlow;
            %             app.inletPressureD = pollArray.pressure;
            % 
            %         case 'E'
            %             app.EFlowAct = pollArray.massFlow;
            %             app.inletPressureE = pollArray.pressure;
            %     end      
            % catch exception
            %     disp(exception.message)
            %     disp(exception.stack(1))
            % end
        end

        function updatePressureSensorField(app)

            %Calculate the pressure from the signal from arduino and
            %display it in the gui
            if (app.recordDataListeners.pressureSensorConnected == true)
                sensorPressurePSIA = app.sensorPressure*0.2897 + 0.5;
                app.SensorPressurepsiaEditField.Value = sensorPressurePSIA;
            end

        end

        function updateMFCFields(app)  
            % disp("updateMFCFields executed")
            % Setpoint Fields
            % A
            app.displayFields.signalA.Value = app.AFlow;
            app.displayFields.flowA.Value = app.AFlowSLPM;

            % B
            app.displayFields.signalB.Value = app.BFlow;
            app.displayFields.flowB.Value = app.BFlowSLPM;

            % C
            app.displayFields.signalC.Value = app.CFlow;
            app.displayFields.flowC.Value = app.CFlowSLPM;

            % D
            app.displayFields.signalD.Value = app.DFlow; 
            app.displayFields.flowD.Value = app.DFlowSLPM;

            % E
            app.EditField_9.Value = app.EFlow; %Air MFC signal field
            app.EditField_10.Value = app.EFlowSLPM; %Air MFC SLPM field

            % Total setpoint update
            app.TotalFlowSetpointEditField.Value = app.AFlowSLPM + app.BFlowSLPM + app.CFlowSLPM + app.DFlowSLPM + app.EFlowSLPM; %Total flowrate
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06); %Velocity Setpoint update 

            try                
                % Actual Fields
                %MFC C,D,E get /1000 b/c they are in sccm
                % Update A flow and Inlet pressure Fields
                
                app.EditField_11.Value = app.AFlowAct;
                app.EditField_12.Value = app.EditField_11.Value / (app.A * app.DC * 0.06);
                app.AEditField.Value = app.inletPressureA;
                % B
                app.EditField_13.Value = app.BFlowAct;
                app.EditField_14.Value = app.EditField_13.Value / (app.A * app.DC * 0.06);
                app.BEditField.Value = app.inletPressureB;
                %C
                app.EditField_16.Value = app.CFlowAct / 1000;
                app.EditField_15.Value = app.EditField_16.Value / (app.A * app.DC * 0.06);
                app.CEditField.Value = app.inletPressureC;
                % D
                app.EditField_17.Value = app.DFlowAct / 1000;
                app.EditField_18.Value = app.EditField_17.Value / (app.A * app.DC * 0.06);
                app.DEditField.Value = app.inletPressureD;
    
                % E
                app.EditField_19.Value = app.EFlowAct;
                app.EditField_20.Value = app.EditField_19.Value / (app.A * app.DC * 0.06);
                app.EEditField.Value = app.inletPressureE;
                
                % Total Flow
                app.ActualTotalFlowEditField.Value = app.EditField_11.Value + app.EditField_13.Value + app.EditField_16.Value + app.EditField_17.Value + app.EditField_19.Value;
    
                app.ActualTotalVelocityEditField.Value = app.ActualTotalFlowEditField.Value / (app.A * app.DC * 0.06);
            catch exception
                fprintf("Flow A:%f, B:%f, C:%f, D:%f, E:%f \n",app.AFlowAct, app.BFlowAct, app.CFlowAct, app.DFlowAct, app.EFlowAct);
                fprintf("press A:%f, B:%f, C:%f, D:%f, E:%f \n",app.inletPressureA ,app.inletPressureB, app.inletPressureC, app.inletPressureD, app.inletPressureE);
                disp(exception.message)
                disp(exception.stack(1))
            end
            
            % Check for proper MFC inlet pressure
            bottleValue = app.EditField_11.Value + app.EditField_13.Value + app.EditField_16.Value + app.EditField_17.Value;
            pressureCheckGood = app.inletPressureA > 30 && app.inletPressureB > 30 && app.inletPressureC > 30 && app.inletPressureD > 30;
            pressureCheckBad = app.inletPressureA < 30 || app.inletPressureB < 30 || app.inletPressureC < 30 || app.inletPressureD < 30;
            warningMessage = ["MFC flow stopped.", "Inlet pressure too low (<30 psi)."];
        
            if pressureCheckGood
                app.StartButton.Enable = "on";
                app.RampDown1Button.Enable = "on";
                app.RampDown2Button.Enable = "on";
                app.RampDownO2Button.Enable = "on";
                app.RampUpButton.Enable = "on";
            elseif pressureCheckBad
                app.StartButton.Enable = "off";
                app.RampDown1Button.Enable = "off";
                app.RampDown2Button.Enable = "off";
                app.RampDownO2Button.Enable = "off";
                app.RampUpButton.Enable = "off";
            end


            if pressureCheckBad && bottleValue > 1
                StopButtonPushed(app, []);
                uialert(app.NarrowChannel, warningMessage, "Warning", "Icon", "warning");
            end
        end

        
        function enableDataRecording(app,src,event)
            if app.recordDataListeners.readMFCsPressed && app.recordDataListeners.pressureSensorConnected
                app.RecordDataButton.Enable = "on";
                app.ExportDataButton.Enable = "on";
                app.ResetDataButton.Enable  = "on";
            end
        end
        
        % function setNCAFlow(app,flowRate,unitID)
        %     % tTotal = tic;
        %     global aliComm;
        %     if isempty(aliComm), aliComm = connectAlicat; end
        % 
        %     flowRate = round(flowRate*1000)/1000;
        %     flowRate = str2double(sprintf('%0.3g',flowRate));
        % 
        %     signal = calcFlow(flowRate, unitID);
        %     signal = round(signal);
        % 
        %     % Update globals
        %     app.updateSetpointGlobals(unitID,flowRate,signal)
        % 
        %     cmd = sprintf('%s%d',unitID,signal);
        %     tWrite = tic;
        %     fprintf(aliComm,cmd);
        %     % writeTime = toc(tWrite);
        % 
        %     % Send flow to MFC
        %     fprintf(aliComm, sprintf('%s%d',unitID,signal));
        % 
        %     % Parse Reponse
        %     % tRead = tic;
        %     try
        %         IN = fgetl(aliComm);
        %         OUT = app.processMFCLine(IN);
        % 
        %         % Update globals
        %         app.updateGlobMfcActuals(unitID,OUT);
        %     catch exception
        %         disp(exception.message)
        %         disp(exception.stack(1))
        %         flushinput(aliComm); %Clean up on exception 
        %     end
        % 
        %     % toc
        % end
        
        %---------time instrumented setNCAFlow -----------------------------
        function setNCAFlow(app,flowRate,unitID)
            tTotal = tic;

            global aliComm;
            if isempty(aliComm), aliComm = connectAlicat; end

            flowRate = round(flowRate*1000)/1000;
            flowRate = str2double(sprintf('%0.3g',flowRate));

            signal = round(calcFlow(flowRate, unitID));

            % Always update desired/display state.
            app.updateSetpointGlobals(unitID, flowRate, signal)

            % Only skip if this exact signal has already been physically sent.
            if ~app.checkSetpointChange(unitID, signal)
                % fprintf("MFC %s")
                return
            end

            cmd = sprintf('%s%d',unitID,signal);

            tWrite = tic;
            try
                fprintf(aliComm, cmd);
                writeTime = toc(tWrite);

                % Mark as sent only after fprintf succeeds.
                app.LastSentSignal.(unitID) = signal;
            catch ME
                fprintf('MFC %s fprintf FAILED: %s\n', unitID, ME.message);
                return
            end

            tRead = tic;
            try
                IN = fgetl(aliComm);
                readTime = toc(tRead);
            catch ME
                readTime = toc(tRead);
                fprintf('MFC %s fgetl FAILED after %.3f s: %s\n', ...
                    unitID, readTime, ME.message);
                flushinput(aliComm);
                return
            end

            totalTime = toc(tTotal);

            if totalTime > 0.04
                fprintf('MFC %s slow: total %.3f s, write %.3f s, read %.3f s, cmd=%s\n', ...
                    unitID, totalTime, writeTime, readTime, cmd);
            end

            try
                OUT = app.processMFCLine(IN);
                app.updateGlobMfcActuals(unitID,OUT);
            catch ME
                fprintf('MFC %s parse failed: %s | line="%s"\n', ...
                    unitID, ME.message, IN);
                flushinput(aliComm);
            end
        end
        %-----------------------------------------
        function OUT = processMFCLine(app,IN)
            IN(IN==0) = ' ';
            % Turn returnOnError true for release
            data = textscan(IN, '%s%f%f%f%f%f%s',1, 'Delimiter', ' ',ReturnOnError=true, MultipleDelimsAsOne=true);

            [...
                OUT.ID, ...
                OUT.pressure, ...
                OUT.temp, ...
                OUT.volumetricFlow, ...
                OUT.massFlow, ...
                OUT.setPoint, ...
                OUT.gas ...
                ] = data{:};

            OUT.ID = cell2mat(OUT.ID);
            OUT.gas = cell2mat(OUT.gas);
            OUT.time = now;
        end
        
        function updateGlobMfcActuals(app,unitID,pollArray)
            try
                switch unitID
                    case 'A'
                        app.AFlowAct = pollArray.massFlow;
                        app.inletPressureA = pollArray.pressure;
    
                    case 'B'
                        app.BFlowAct = pollArray.massFlow;
                        app.inletPressureB = pollArray.pressure;
    
                    case 'C'
                        app.CFlowAct = pollArray.massFlow;
                        app.inletPressureC = pollArray.pressure;
    
                    case 'D'
                        app.DFlowAct = pollArray.massFlow;
                        app.inletPressureD = pollArray.pressure;
    
                    case 'E'
                        app.EFlowAct = pollArray.massFlow;
                        app.inletPressureE = pollArray.pressure;
                end      
            catch exception
                disp(exception.message)
                disp(exception.stack(1))
            end
        end

        function updateSetpointGlobals(app,unitID,flow,signal)
            switch unitID
                case 'A'
                    app.AFlow = signal;
                    app.AFlowSLPM = flow;

                case 'B'
                    app.BFlow = signal;
                    app.BFlowSLPM = flow;

                case 'C'
                    app.CFlow = signal;
                    app.CFlowSLPM = flow;

                case 'D'
                    app.DFlow = signal;
                    app.DFlowSLPM = flow;

                case 'E'
                    app.EFlow = signal;
                    app.EFlowSLPM = flow;
            end
        end

        function tf = checkSetpointChange(app, unitID, signal)
            tf = ~isequal(app.LastSentSignal.(unitID), signal);
        end

        function [QASmallSetpoint,QALargeSetpoint,QBSmallSetpoint,QBLargeSetpoint] = distributeFlow(app,QA1,QB1)
            arguments (Output)
                QASmallSetpoint {mustBeGreaterThanOrEqual(QASmallSetpoint,0)} %Gas A Small MFC setpoint [SLM]
                QALargeSetpoint {mustBeGreaterThanOrEqual(QALargeSetpoint,0)} %Gas A Large MFC setpoint [SLM]
                QBSmallSetpoint {mustBeGreaterThanOrEqual(QBSmallSetpoint,0)} %Gas B Small MFC setpoint [SLM]
                QBLargeSetpoint {mustBeGreaterThanOrEqual(QBLargeSetpoint,0)} %Gas B Large MFC setpoint [SLM]
            end

            MFCStruct = app.MFCData;
            %Calculate flow rates
            %Calculating the 'leftover' flow for each gas that the small
            % MFC does not have capacity for.
            QALeftover = QA1 - MFCStruct.N2UnitSmallMax;
            QBLeftover = QB1 - MFCStruct.O2UnitSmallMax;

            %Calculate flow for gas A (N2) MFCs
            %If the leftover is less than or equal to zero, dont use large
            %MFC. If the leftover is between 0 and 1 then set the large MFC
            %to 1 and use the small MFC for the leftover
            % otherwise, supply leftover with large MFC
            % Same logic for oxygen
            if QALeftover <= 0
                QASmallSetpoint = QA1;
                QALargeSetpoint = 0;
            elseif (QALeftover > 0) && (QALeftover <= MFCStruct.N2UnitLargeMin)
                QASmallSetpoint = QALeftover;
                QALargeSetpoint = MFCStruct.N2UnitLargeMin;
            elseif QALeftover > MFCStruct.N2UnitSmallMax
                QASmallSetpoint = MFCStruct.N2UnitSmallMax;
                QALargeSetpoint = QALeftover;
            else
                warning('Error computing gas A Flows. Leftover computed incorrectly QALeftover = %f.2, QA1 = %f.2\n',QALeftover, MFCStruct.QA1);
            end

            %Calculate flow for gas B (O2) MFCs
            if QBLeftover <= 0
                QBSmallSetpoint = QB1;
                QBLargeSetpoint = 0;
            elseif (QBLeftover > 0) && (QBLeftover <= MFCStruct.O2UnitLargeMin)
                QBSmallSetpoint = QBLeftover;
                QBLargeSetpoint = MFCStruct.O2UnitLargeMin;
            elseif QBLeftover > 0
                QBSmallSetpoint = MFCStruct.O2UnitSmallMax;
                QBLargeSetpoint = QBLeftover;
            else
                warning('Error computing gas B Flows. Leftover computed incorrectly QBLeftover = %f.2, QB1 = %f.2\n',QBLeftover, MFCStruct.QB1);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %For Debugging make sure you add path to helper functions 
           
            %These functions set up the default values when the gui loads up

            app.Lamp.Color = '#DA1884';
            app.pressureVector = [];
            app.toggleData = false;
            app.mfcIOTimer = timer('Period', round(1/app.mfcIOTimerFreq,3), 'ExecutionMode', 'fixedRate');
            app.mfcIOTimer.TimerFcn = @(obj,event) app.mfcIOcallback(obj,event);

            app.mfcIOTimer.BusyMode = 'drop';
            app.mfcIOTimer.Name = "MFC I/O Timer";
            
            app.StartButton.Enable = "off";
            app.RampDown1Button.Enable = "off";
            app.RampDown2Button.Enable = "off";
            app.RampDownO2Button.Enable = "off";
            app.RampUpButton.Enable = "off";

            %function ChannelPressurekPaSpinnerValueChanged(app, event)
            app.gChPress = app.ChannelPressurekPaSpinner.Value;
            app.gChPressATM = (app.gChPress)/101.32;
            app.atmEditField.Value = app.gChPressATM;
            
            %Pounds per square inch pressure
            app.gChPressPSIA = (app.gChPress)*14.696/101.32;
            app.psiaEditField.Value = app.gChPressPSIA;
            %end
            
            %function ChannelTemperatureCSpinnerValueChanged(app, event)
            app.gChTemp = app.ChannelTemperatureCSpinner.Value;
            app.ChTempK = (app.gChTemp) + 273.15;
            app.KEditField.Value = app.ChTempK;
            app.ChTempF = ((app.ChTempK)-273.15)*(9/5)+32;
            app.FEditField.Value = app.ChTempF;
            %end
            
            %function GapHeightmmSpinnerValueChanged(app, event)
            app.gGapHeight = app.GapHeightmmSpinner.Value;
            app.cmEditField.Value = app.gGapHeight/10;
            %end

            %function psiaSpinnerValueChanged(app, event)
            app.gConverterPsia = app.psiaSpinner.Value;
            app.kpaEditField.Value = app.gConverterPsia*6.89476;
            %end
            
            %function ChannelWidthcmSpinnerValueChanged(app, event)
            app.gChannelWidth = app.ChannelWidthcmSpinner.Value;
            %end
            
            %function CrossSectionalAreacm2EditFieldValueChanged(app, event)
            app.A = app.CrossSectionalAreacm2EditField.Value;
            %end
            
            %function DensityCorrectionFactorEditFieldValueChanged(app, event)
            app.DC = app.DensityCorrectionFactorEditField.Value;
            %end
            
            %function ReferencePressurekPaSpinnerValueChanged(app, event)
            app.gRefPress = app.ReferencePressurekPaSpinner.Value;
            app.gRefPressATM = (app.gRefPress)/101.32;
            app.atmEditField_2.Value = app.gRefPressATM;
            app.gRefPressPSIA = (app.gRefPress)*14.696/101.32;
            app.psiaEditField_2.Value = app.gRefPressPSIA;
            %end
            
            %function ReferenceTemperatureCSpinnerValueChanged(app, event)
            app.gRefTemp = app.ReferenceTemperatureCSpinner.Value;
            app.RefTempK = (app.gRefTemp) + 273.15;
            app.KEditField_2.Value = app.RefTempK;
            app.RefTempF = ((app.RefTempK)-273.15)*(9/5)+32;
            app.FEditField_2.Value = app.RefTempF;
            %end
            
            %function DesiredOxygenCompositionOxygeninMixtureSpinnerValueChanged(app, event)
            app.gOxyComp = app.DesiredOxygenCompositionOxygeninMixtureSpinner.Value;
            %end
            
            %function AirVelocitycmsSpinnerValueChanged(app, event)
            app.Dryvel = app.AirVelocitycmsSpinner.Value;
            %end
            
            %function InitialVelocityTotalcmsSpinnerValueChanged(app, event)
            app.gIVT = app.InitialVelocityTotalcmsSpinner.Value;
            %end
            
            %function RampDownRate1TotalcmssSpinnerValueChanged(app, event)
            app.gRDRT1 = app.RampDownRate1TotalcmssSpinner.Value;
            %end
            
            %function PlateauVelocity1TotalcmsSpinnerValueChanged(app, event)
            app.gPVT1 = app.PlateauVelocity1TotalcmsSpinner.Value;
            %end

            app.PVA1 = app.PlateauVelocity1AcmsEditField.Value;
            app.PVB1 = app.PlateauVelocity1BcmsEditField.Value;
            
            %function RampDownRate2TotalcmssSpinnerValueChanged(app, event)
            app.gRDRT2 = app.RampDownRate2TotalcmssSpinner.Value;
            %end
            
            %function PlateauVelocity2TotalcmsSpinnerValueChanged(app, event)
            app.gPVT2 = app.PlateauVelocity2TotalcmsSpinner.Value;
            %end

            app.PVA2 = app.PlateauVelocity2AcmsEditField.Value;
            app.PVB2 = app.PlateauVelocity2BcmsEditField.Value;
            
            %function RampUpRate1TotalcmssSpinnerValueChanged(app, event)
            app.gRURT = app.RampUpRate1TotalcmssSpinner.Value;
            %end
            
            %function FinalVelocity1TotalcmsSpinnerValueChanged(app, event)
            app.gFVT = app.FinalVelocity1TotalcmsSpinner.Value;
            %end

            app.PFA = app.PlateauVelocityAcmsEditField.Value;
            app.PFB = app.FinalVelocityBcmsEditField.Value;
            
            %function O2PercentPlateauVolumeSpinnerValueChanged(app, event)
            app.gO2_percent_plateau = app.O2PercentPlateauVolumeSpinner.Value;
            %end
            
            %function O2PercentRampDownsecSpinnerValueChanged(app, event)
            app.gO2_percent_RDR = app.O2PercentRampDownsecSpinner.Value;
            %end
            
            %-------------------------------------------------------------
            % Create global struct that stores all of the display edit
            % fields so that it doesn't have to be rebuilt in each callback
            % function
            % ------------------------------------------------------------

            %Setup display fields struct
            % MFC A = Gas A (N2) Large MFC
            % MFC B = Gas B (O2) Large MFC
            % MFC C = Gas A (N2) Small MFC
            % MFC D = Gas B (O2) Small MFC

            % 16 bit signal EditField displays
            app.displayFields.signalA = app.EditField;
            app.displayFields.signalB = app.EditField_2;
            app.displayFields.signalC = app.EditField_4;
            app.displayFields.signalD = app.EditField_7;

            % SLM EditField displays
            app.displayFields.flowA = app.EditField_3;
            app.displayFields.flowB = app.EditField_5;
            app.displayFields.flowC = app.EditField_6;
            app.displayFields.flowD = app.EditField_8;

            % Set Ramping flag to false
            app.RampingActive = false;

            % MFC data struct setup
            %Specify MFC labels (Replace w/ values from dropdown if anyone
            %ever decided to make that work. David said making those work
            %is useless)
            app.N2UnitSmall = 'C';
            app.N2UnitLarge = 'A';
            app.O2UnitSmall = 'D';
            app.O2UnitLarge = 'B';
            
            %Specify maximum flow rates for each MFC [SLM]
            app.N2UnitSmallMax = 1;
            app.N2UnitLargeMax = 50;
            app.N2UnitLargeMin = 1;

            app.O2UnitSmallMax = 1;
            app.O2UnitLargeMax = 50;
            app.O2UnitLargeMin = 1;
            
            %Setup MFCData input struct
            app.MFCData.N2UnitSmall = app.N2UnitSmall;
            app.MFCData.N2UnitLarge = app.N2UnitLarge;
            app.MFCData.O2UnitSmall = app.O2UnitSmall;
            app.MFCData.O2UnitLarge = app.O2UnitLarge;

            app.MFCData.N2UnitSmallMax = app.N2UnitSmallMax;
            app.MFCData.N2UnitLargeMax = app.N2UnitLargeMax;
            app.MFCData.N2UnitLargeMin = app.N2UnitLargeMin;
            app.MFCData.O2UnitSmallMax = app.O2UnitSmallMax;
            app.MFCData.O2UnitLargeMax = app.O2UnitLargeMax;
            app.MFCData.O2UnitLargeMin = app.O2UnitLargeMin;

            % Initialize the table used to store flow signals. This table
            % is only written to the check box is checked
            app.flowTableSrc; %Source array 
            % app.flowTableVarNames = { 'Timestamp (s)','Q_A [SLPM]','Q_B','Q_C','Q_D' };
            % app.flowTable = table(0,0,0,0,0,'VariableNames',["Timestamp (s)","Q_A [SLPM]","Q_B","Q_C","Q_D"]);
            
            % --- Setup Rampdown Timer -----
            
            % -- Set Data recording struct listeners----
            app.recordDataListeners.readMFCsPressed = false;
            app.recordDataListeners.pressureSensorConnected = false;

            addlistener(app,'recordDataListeners','PostSet',@app.enableDataRecording);
            
            % IO tasks
            app.TaskMap = containers.Map('KeyType','char','ValueType','any');

            % Last sent mfc signals
            app.LastSentSignal = struct( ...
                'A', NaN, ...
                'B', NaN, ...
                'C', NaN, ...
                'D', NaN, ...
                'E', NaN);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %Calculates Density Correction Factor
            app.DC =(app.gChPress/app.gRefPress)*(app.ChTempK/app.RefTempK);
            % disp(app.DC)
            % app.RefTempK
            % app.ChTempK
            % fprintf("app.gChPress = %f, app.gRefPress = %f, app.ChTempK = %f, app.RefTempK = %f \n",app.gChPress,app.gRefPress,app.ChTempK, app.RefTempK)
            app.DensityCorrectionFactorEditField.Value = app.DC;
            
            %Calculates Cross Sectional Area
            app.A = (app.gChannelWidth)*(app.gGapHeight / 10);
            app.CrossSectionalAreacm2EditField.Value = app.A;
              
            %QT1
            app.QT1 = app.gIVT * app.A* app.DC * .06;
            app.SLMEditField_8.Value = app.QT1;
            
            %QB1
            app.QB1 = (app.gOxyComp * app.QT1)/100;
            app.SLMEditField_12.Value = app.QB1;
            
            %QA1
            app.QA1 = (app.QT1 - app.QB1);
            app.SLMEditField.Value = app.QA1;
            
            %IVA
            app.IVA = app.QA1/(app.A*.06);
            app.InitialVelocityAcmsEditField.Value = app.IVA;
            
            %IVB
            app.IVB = app.QB1/(app.A*.06);
            app.InitialVelocityBcmsEditField.Value = app.IVB;
            
            %ISA50 
            app.ISA50 = app.QA1*64000/50;
            
            %ISA1
            app.ISA1 = app.QA1*64000/1;
            
            %ISB50
            app.ISB50 = app.QB1*64000/50;
            
            %ISB1
            app.ISB1 = app.QB1*64000/1;
            
            %The rest will be reacting on the ramp buttons------------------------------------------
            %Ramp Down 1 calculations were transferred to the Ramp Down 1 Button---
            
            %%Ramp Down 1 Button------------------------------------------------------------------
            
            if app.gPVT1 >= app.gIVT
                msgbox('Plateau Velocity 1 Total must be lower than Initial Velocity Total');
            else
                %QRT1
                app.QRT1 = app.gRDRT1*app.A*app.DC*6/100;
                app.SLMsEditField_7.Value = app.QRT1;
            
                %PVT1
                app.QPT1 = app.gPVT1*app.A*app.DC*6/100;
                app.SLMEditField_9.Value = app.QPT1;
            
                %PVB1
                app.PVB1 = app.gPVT1*app.gOxyComp/100;
                app.PlateauVelocity1BcmsEditField.Value = app.PVB1;
            
                %QPB1
                app.QPB1 = app.PVB1*app.A*app.DC*6/100;
                app.SLMEditField_13.Value = app.QPB1;
            
                %PVA1
                app.PVA1 = app.gPVT1-app.PVB1;
                app.PlateauVelocity1AcmsEditField.Value = app.PVA1;
            
                %QPA1
                app.QPA1 = app.PVA1*app.A*app.DC*6/100;
                app.SLMEditField_5.Value = app.QPA1;
            
                %RDRA1
                app.RDRA1 = app.gRDRT1*((app.PVA1-app.IVA)/(app.gPVT1-app.gIVT));
                if isnan(app.RDRA1)
                    msgbox('Ramp Down Rate 1-A resulted to NaN');
                else
                    app.RampDownRate1AcmssEditField.Value = app.RDRA1;
                end
            
                %QRA1
                app.QRA1 = app.RDRA1*app.A*app.DC*6/100;
                app.SLMsEditField_4.Value = app.QRA1;
            
                %RDRB1
                app.RDRB1 = app.gRDRT1*((app.PVB1-app.IVB)/(app.gPVT1-app.gIVT));
                if isnan(app.RDRB1)
                    msgbox('Ramp Down Rate 1-B resulted to NaN');
                else
                    app.RampDownRate1BcmssEditField.Value = app.RDRB1;
                end
            
                %QRB1
                app.QRB1 = app.RDRB1*app.A*app.DC*6/100;
                app.SLMsEditField_10.Value = app.QRB1;
            
                %RD1T (Ramp Down 1 Time)
                app.RD1T = (app.gPVT1-app.gIVT)/(app.gRDRT1);
                if isnan(app.RD1T)
                    msgbox('Ramp Down 1 resulted to NaN');
                else
                    app.RampDown1secEditField.Value = app.RD1T;
                end
            end
            
            %Thao's code -----------------------------------------------------------------------
            app.O2_vel_plateau = app.gIVT*app.gO2_percent_plateau/100;
            app.O2_flow_plateau = app.QT1*app.gO2_percent_plateau/100;
            app.SLMEditField_18.Value = app.O2_flow_plateau; % SLM for O2
            
            app.N2_vel_plateau = app.gIVT-app.O2_vel_plateau;
            app.N2_flow_plateau = app.QT1-app.O2_flow_plateau;
            
            app.O2_Q_ramp_rate = app.QT1*app.gO2_percent_RDR/100;
            app.SLMsEditField_15.Value = app.O2_Q_ramp_rate; % SLM/s for O2
            app.N2_Q_ramp_rate = -1*app.O2_Q_ramp_rate;
            
            app.duration = (app.gO2_percent_plateau-app.gOxyComp)/app.gO2_percent_RDR;
            if isnan(app.duration)
                msgbox('Oxygen Ramp Time resulted to NaN');
            else
                app.OxygenRampsecEditField.Value = app.duration;
            end
            
            %Ramp Down 2 calculations were transferred to the Ramp Down 2 Button
            %Ramp Down 2 Button---------------------------------------------------------------------
            
            if app.gPVT2 > app.gPVT1
                msgbox('Plateau Velocity 2 Total must be lower than Plateau Velocity 1 Total');
            elseif app.gPVT2 == app.gPVT1
                app.RDRA2 = 0;
                app.RDRB2 = 0;
            else
                %QRT2
                app.QRT2 = app.gRDRT2*app.A*app.DC*6/100;
                app.SLMsEditField_13.Value = app.QRT2;
            
                %QPT2
                app.QPT2 = app.gPVT2*app.A*app.DC*6/100;
                app.SLMEditField_16.Value = app.QPT2;
            
                %PVB2
                app.PVB2 = app.gPVT2*app.gOxyComp/100;
                app.PlateauVelocity2BcmsEditField.Value = app.PVB2;
            
                %QPB2
                app.QPB2 = app.PVB2*app.A*app.DC*6/100;
                app.SLMEditField_14.Value = app.QPB2;
            
                %PVA2
                app.PVA2 = app.gPVT2-app.PVB2;
                app.PlateauVelocity2AcmsEditField.Value = app.PVA2;
            
                %QPA2
                app.QPA2 = app.PVA2*app.A*app.DC*6/100;
                app.SLMEditField_6.Value = app.QPA2;
            
                %RDRA2
                app.RDRA2 = app.gRDRT2*((app.PVA2-app.PVA1)/(app.gPVT2-app.gPVT1));
                
                if isnan(app.RDRA2)
                    msgbox('Ramp Down Rate 2-A resulted to NaN');
                else
                    app.RampDownRate2AcmssEditField.Value = app.RDRA2;
                end
            
                %if ~isnan(app.RDRA2)
                    %app.RampDownRate2AcmssEditField.Value = app.RDRA2;
                %end
            
                %QRA2
                app.QRA2 = app.RDRA2*app.A*app.DC*6/100;
                if isnan(app.QRA2)
                    msgbox('Ramp Down Flow Rate 2-A (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_5.Value = app.QRA2;
                end
            
                %RDRB2
                app.RDRB2 = app.gRDRT2*((app.PVB2-app.PVB1)/(app.gPVT2-app.gPVT1));
                if isnan(app.RDRB2)
                    msgbox('Ramp Down Rate 2-B resulted to NaN');
                else
                    app.RampDownRate2BcmssEditField.Value = app.RDRB2;
                end
            
                %QRB2
                app.QRB2 = app.RDRB2*app.A*app.DC*6/100;
                if isnan(app.QRB2)
                    msgbox('Ramp Down Flow Rate 2-B resulted to NaN');
                else
                    app.SLMsEditField_11.Value = app.QRB2;
                end
            
                %RD2T (Rampdown2 time)
                app.RD2T =(app.gPVT2-app.gPVT1)/(app.gRDRT2);
                if isnan(app.RD2T)
                    msgbox('Ramp Down 2 resulted to NaN');
                else
                app.RampDown2secEditField.Value = app.RD2T;
                end

            end
          
            %Ramp Up Button-------------------------------------------------------------------
            if app.gFVT < app.gPVT2
                msgbox('Final Plateau Velocity must be higher than Plateau Velocity 2 Total');
            elseif app.gFVT == app.gPVT2
                app.RURA = 0;
                app.RURB = 0;
            else
                %QRT3
                app.QRT3 = app.gRURT*app.A*app.DC*6/100;
                app.SLMsEditField_14.Value = app.QRT3;
            
                %QFT
                app.QFT = app.gFVT*app.A*app.DC*6/100;
                app.SLMEditField_17.Value = app.QFT;
                
                %PFB
                app.PFB = app.gFVT*app.gOxyComp/100;
                app.FinalVelocityBcmsEditField.Value = app.PFB;
                
                %QFB
                app.QFB = app.PFB*app.A*app.DC*6/100;
                app.SLMEditField_15.Value = app.QFB;
                
                %PFA
                app.PFA = app.gFVT-app.PFB;
                app.PlateauVelocityAcmsEditField.Value = app.PFA;
                
                %QFA
                app.QFA = app.PFA*app.A*app.DC*6/100;
                app.SLMEditField_7.Value = app.QFA;
                
                %RURA
                app.RURA = app.gRURT*((app.PFA-app.PVA2)/(app.gFVT-app.gPVT2));
                if isnan(app.RURA)
                    msgbox('Ramp Up Rate A resulted to NaN');
                else
                    app.RampDownRateAcmssEditField.Value = app.RURA;
                end
                
                %QRA3
                app.QRA3 = app.RURA*app.A*app.DC*6/100;
                if isnan(app.QRA3)
                    msgbox('Ramp Up Flow Rate A (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_6.Value = app.QRA3;
                end
                
                %RURB
                app.RURB = app.gRURT*((app.PFB-app.PVA2)/(app.gFVT-app.gPVT2));
                if isnan(app.RURB)
                    msgbox('Ramp Up Rate B resulted to NaN');
                else
                    app.RampUpRateBcmssEditField.Value = app.RURB;
                end
                
                %QRB3
                app.QRB3 = app.RURB*app.A*app.DC*6/100;
                if isnan(app.QRB3)
                    msgbox('Ramp Up Flow Rate B (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_12.Value = app.QRB3;
                end
                
                %RUT
                app.RUT = (app.gFVT-app.gPVT2)/(app.gRURT);
                if isnan(app.RUT)
                    msgbox('Ramp Up 1 resulted to NaN');
                else
                    app.RampUp1secEditField.Value = app.RUT;
                end
            end
        end

        % Value changed function: ChannelPressurekPaSpinner
        function ChannelPressurekPaSpinnerValueChanged(app, event)
            app.gChPress = app.ChannelPressurekPaSpinner.Value;
            
            %Atmospheric Pressure
            app.gChPressATM = (app.gChPress)/101.32;
            app.atmEditField.Value = app.gChPressATM;
            
            %Pounds per square inch pressure
            app.gChPressPSIA = (app.gChPress)*14.696/101.32;
            app.psiaEditField.Value = app.gChPressPSIA;
        end

        % Value changed function: ChannelTemperatureCSpinner
        function ChannelTemperatureCSpinnerValueChanged(app, event)
            app.gChTemp = app.ChannelTemperatureCSpinner.Value;
            
            %Kelvins
            app.ChTempK = (app.gChTemp) + 273.15;
            app.KEditField.Value = app.ChTempK;
            
            %Fahrenheit
            app.ChTempF = ((app.ChTempK)-273.15)*(9/5)+32;
            app.FEditField.Value = app.ChTempF;
        end

        % Value changed function: 
        % DesiredOxygenCompositionOxygeninMixtureSpinner
        function DesiredOxygenCompositionOxygeninMixtureSpinnerValueChanged(app, event)
            app.gOxyComp = app.DesiredOxygenCompositionOxygeninMixtureSpinner.Value;
            
        end

        % Button pushed function: StartAirButton
        function StartAirButtonPushed(app, event)
            % Modified 6/8/2026 to work with new serial communication
            % system. Prevented function for accesing serial port
            % asynchronously. Removed code to update UI for individual MFCs.
            % UI updates for the individual MFCs are done inside setNCAFlow. 
            % Updating of total values is still done here. 
            format shortG
            app.QDry = app.Dryvel * app.A * app.DC * 0.06;
            app.Dryvel;
            app.QDry;
            %testing;
            
            if app.QDry < 5

                app.addTask('set MFC E Flow', @() app.setNCAFlow(app.QDry,'E'),1);
                %setFlow(app.QDry,'E');
                %testing;
                
                %For the display for SLPM scale
                %app.EFlow = calcFlow(app.QDry,'E');
                
                %pause(app.Dryvel) %pause might not be suitable for ramp downs because multiple they would happen simultaneously
                %setFlow(0, 'E');
            elseif app.QDry > 5
                app.QDry = 5;
                app.addTask('set MFC E Flow', @() app.setNCAFlow(app.QDry,'E'),1);
                %setFlow(5, 'E');
                
                %For the display for SLPM scale
                
                %app.EFlow = 64000;
                
                % disp('Max Flow Rate has been reached at 5SLM')
 
                uialert(app.NarrowChannel, "Max Flow Rate has been reached at 5SLM","Warning", "Icon","warning")
            end
            
            % %Display for SLPM scale
            % app.EditField_9.Value = app.EFlow;
            % %Display for SLM
            % app.EditField_10.Value = app.QDry;

            %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
            app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
            %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
        end

        % Value changed function: AirVelocitycmsSpinner
        function AirVelocitycmsSpinnerValueChanged(app, event)
            app.Dryvel = app.AirVelocitycmsSpinner.Value;
            
        end

        % Value changed function: FinalVelocity1TotalcmsSpinner
        function FinalVelocity1TotalcmsSpinnerValueChanged(app, event)
            app.gFVT = app.FinalVelocity1TotalcmsSpinner.Value;
            
        end

        % Value changed function: GapHeightmmSpinner
        function GapHeightmmSpinnerValueChanged(app, event)
            app.gGapHeight = app.GapHeightmmSpinner.Value;

            % mm to cm
            app.cmEditField.Value = app.gGapHeight/10;
            
        end

        % Value changed function: ChannelWidthcmSpinner
        function ChannelWidthcmSpinnerValueChanged(app, event)
            app.gChannelWidth = app.ChannelWidthcmSpinner.Value;
            
        end

        % Value changed function: InitialVelocityTotalcmsSpinner
        function InitialVelocityTotalcmsSpinnerValueChanged(app, event)
            app.gIVT = app.InitialVelocityTotalcmsSpinner.Value;
          
        end

        % Value changed function: O2PercentPlateauVolumeSpinner
        function O2PercentPlateauVolumeSpinnerValueChanged(app, event)
            app.gO2_percent_plateau = app.O2PercentPlateauVolumeSpinner.Value;
            
        end

        % Value changed function: O2PercentRampDownsecSpinner
        function O2PercentRampDownsecSpinnerValueChanged(app, event)
            app.gO2_percent_RDR = app.O2PercentRampDownsecSpinner.Value;
            
        end

        % Value changed function: PlateauVelocity1TotalcmsSpinner
        function PlateauVelocity1TotalcmsSpinnerValueChanged(app, event)
            app.gPVT1 = app.PlateauVelocity1TotalcmsSpinner.Value;
            
        end

        % Value changed function: PlateauVelocity2TotalcmsSpinner
        function PlateauVelocity2TotalcmsSpinnerValueChanged(app, event)
            app.gPVT2 = app.PlateauVelocity2TotalcmsSpinner.Value;
            
        end

        % Button pushed function: RampDown1Button
        function RampDown1ButtonPushed(app, event)
            %Ramp Down 1 Button------------------------------------------------------------------
            
            %Check if the Plateau Velocity is greater than the initial
            %velocity
            if app.gPVT1 >= app.gIVT
                msgbox('Plateau Velocity 1 Total must be lower than Initial Velocity Total');
            else
                %QRT1, calculates Volume flow rampdown rate in standard
                %L/s/min (name is confusing)
                app.QRT1 = app.gRDRT1*app.A*app.DC*6/100;
                app.SLMsEditField_7.Value = app.QRT1;
            
                %PVT1, calculates plateau volume flow in standard L/min
                app.QPT1 = app.gPVT1*app.A*app.DC*6/100;
                app.SLMEditField_9.Value = app.QPT1;
            
                %PVB1, calculates oxygen plateau velocity in cm/s
                app.PVB1 = app.gPVT1*app.gOxyComp/100;
                app.PlateauVelocity1BcmsEditField.Value = app.PVB1;
            
                %QPB1, calculates oxygen plateau volume flow in standard L/min
                app.QPB1 = app.PVB1*app.A*app.DC*6/100;
                app.SLMEditField_13.Value = app.QPB1;
            
                %PVA1, calculate the nitrogen plateau velocity in cm/s
                app.PVA1 = app.gPVT1-app.PVB1;
                app.PlateauVelocity1AcmsEditField.Value = app.PVA1;
            
                %QPA1, calculate the nitrogen plateau volume flow in standard L/min
                app.QPA1 = app.PVA1*app.A*app.DC*6/100;
                app.SLMEditField_5.Value = app.QPA1;
            
                %RDRA1, scalculate the fraction of total rampdown rate
                %needed to ramp just gas A in cm/s/s of ga A
                app.RDRA1 = app.gRDRT1*((app.PVA1-app.IVA)/(app.gPVT1-app.gIVT));
                if isnan(app.RDRA1)
                    msgbox('Ramp Down Rate 1-A resulted to NaN');
                else
                    app.RampDownRate1AcmssEditField.Value = app.RDRA1;
                end
            
                %QRA1, calculate volume flow rampdown rate in standard L/s/min of Gas
                %A
                app.QRA1 = app.RDRA1*app.A*app.DC*6/100;
                app.SLMsEditField_4.Value = app.QRA1;
            
                %RDRB1, calculate the fraction of total rampdown rate
                %needed to ramp just gas B in cm/s/s of gas B
                app.RDRB1 = app.gRDRT1*((app.PVB1-app.IVB)/(app.gPVT1-app.gIVT));
                if isnan(app.RDRB1)
                    msgbox('Ramp Down Rate 1-B resulted to NaN');
                else
                    app.RampDownRate1BcmssEditField.Value = app.RDRB1;
                end
            
                %QRB1, calculate volume flow rampdown rate in standard L/s/min of Gas
                %B
                app.QRB1 = app.RDRB1*app.A*app.DC*6/100;
                app.SLMsEditField_10.Value = app.QRB1;
            
                %RD1T, calculate the total duration of the rampdown in
                %seconds
                app.RD1T = (app.gPVT1-app.gIVT)/(app.gRDRT1);
                if isnan(app.RD1T)
                    msgbox('Ramp Down 1 resulted to NaN');
                else
                    app.RampDown1secEditField.Value = app.RD1T;
                end
            
            
                %-----Setting the Flows for gas A and gas B------
                %For now assuming that the unit numbers dont change
                % app.N2_large = 'A';
                % app.N2_small = 'C';
                % app.O2_large = 
                
                %----- PREVIOUIS CODE set flow 10/6/2025 -------
                %For Display Purposes
                % Set N2 Flow
                % if app.N2Unit == 'A'
                %     %Display for SLM Capacity
                %     app.AFlow = calcFlow(app.QPA1, app.N2Unit); %1-64000
                %     app.EditField.Value = app.AFlow; %1-64000
                % 
                %     %Display for MFC flow
                %     app.EditField_3.Value = app.QPA1; %SLM
                % elseif app.N2Unit == 'C'
                %     %Display for SLM Capacity
                %     app.CFlow = calcFlow(app.QPA1, app.N2Unit); %1-64000
                %     app.EditField_4.Value = app.CFlow; %1-64000
                % 
                %     %Display for MFC flow
                %     app.EditField_6.Value = app.QPA1; %SLM
                % end
                % 
                % % Setting Nitrogen flow rate for both MFC's
                % 
                % %Set O2 Flow
                % if app.O2Unit == 'B'
                %     %Display for SLM Capacity
                %     app.BFlow = calcFlow(app.QPB1, app.O2Unit); %1-64000
                %     app.EditField_2.Value = app.BFlow; %1-64000
                % 
                %     %Display for MFC flow
                %     app.EditField_5.Value = app.QPB1; %SLM
                % elseif app.O2Unit == 'D'
                %     %Display for SLM Capacity
                %     app.DFlow = calcFlow(app.QPB1, app.O2Unit); %1-64000
                %     app.EditField_7.Value = app.DFlow; %1-64000
                % 
                %     %Display for MFC flow
                %     app.EditField_8.Value = app.QPB1; %SLM
                % end
                %-----PREVIOUS CODE--------
                
                %New set flow 10/6/2025
                %Setup MFCData input struct
                % MFCData.N2UnitSmall = app.N2UnitSmall;
                % MFCData.N2UnitLarge = app.N2UnitLarge;
                % MFCData.O2UnitSmall = app.O2UnitSmall;
                % MFCData.O2UnitLarge = app.O2UnitLarge;
                % 
                % MFCData.N2UnitSmallMax = app.N2UnitSmallMax;
                % MFCData.N2UnitLargeMax = app.N2UnitLargeMax;
                % MFCData.N2UnitLargeMin = app.N2UnitLargeMin;
                % MFCData.O2UnitSmallMax = app.O2UnitSmallMax;
                % MFCData.O2UnitLargeMax = app.O2UnitLargeMax;
                % MFCData.O2UnitLargeMin = app.O2UnitLargeMin;
    
                %Calculate flow rates           
                [QASmallSetpoint,QALargeSetpoint,QBSmallSetpoint,QBLargeSetpoint] = NCASetpoints(app.QA1,app.QB1,app.MFCData);
    
                %Update Display with 16 bit signal (0-64000)
                %Calculate 16 bit signal
                app.CFlow = calcFlow(QASmallSetpoint,app.N2UnitSmall);
                app.AFlow = calcFlow(QALargeSetpoint,app.N2UnitLarge);
                app.DFlow = calcFlow(QBSmallSetpoint,app.O2UnitSmall);
                app.BFlow = calcFlow(QBLargeSetpoint,app.O2UnitLarge);
    
                %16 bit Signal Display Fields
                app.EditField_4.Value = app.CFlow;
                app.EditField.Value = app.AFlow;
                app.EditField_7.Value = app.DFlow;
                app.EditField_2.Value = app.BFlow;
                
                %Flow Rate display fields
                app.EditField_6.Value = QASmallSetpoint;
                app.EditField_3.Value = QALargeSetpoint;
                app.EditField_8.Value = QBSmallSetpoint;
                app.EditField_5.Value = QBLargeSetpoint;          
    
                %Set the flow for all MFCs (A through D)
                app.addTask('set_init_A', @() app.setNCAFlow(QALargeSetpoint,'A'),1);
                app.addTask('set_init_B', @() app.setNCAFlow(QBLargeSetpoint,'B'),1);
                app.addTask('set_init_C', @() app.setNCAFlow(QASmallSetpoint,'C'),1);
                app.addTask('set_init_D', @() app.setNCAFlow(QBSmallSetpoint,'D'),1);
                %END New set flow

                %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
                app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
                %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
                app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
                
                %---- Previous Ramp down -----%
           
                %%This snippet of code was an attempt to replicate the Igor Pro Code for Ramp Down -- didn't quite work
                %             app.RSA1 = app.QA1;
                %             app.RSB1 = app.QB1;
                %             N = 100;
                %             dt = app.RD1T/N;
                %             
                %             tic
                %             for i = 1:N
                %                 timerVal = tic;
                %                 elapsedTime = toc(timerVal);
                %                 while elapsedTime <= dt
                %                     app.RSA1 = app.RSA1 - ((app.RSA1-app.QPA1)/N); %curved slope decrease; we want a linear slope based on rate
                %                     app.RSB1 = app.RSB1 - ((app.RSB1-app.QPB1)/N); %curved slope decrease; we want a linear slope based on rate
                %                     %app.RSA1 = app.RSA1 - ((app.QA1-app.QPA1)/N);
                %                     %app.RSB1 = app.RSB1 - ((app.QB1-app.QPB1)/N);
                %                     elapsedTime = toc(timerVal);
                %                     setFlow(app.RSA1, app.N2Unit)
                %                     setFlow(app.RSB1, app.O2Unit)
                %                     %if app.RSA1 == app.QPA1 && app.RSB1 == app.QPB1
                %                         %break
                %                     %end
                %                 end
                %             toc
                %             end

                % --- New Rampdown ----

                % Set Ramping flag to active to prevent attempts to read
                % and write MFCs at the same time                
                % app.RampingActive = true; 

                % Start recording the movie 
                callerButton = "Rampdown 1 (Main app)";
                startMovieOnButton(app,callerButton)

                %Starts the ramp down procedure by calling RampDown func
                % RampDown(app.gIVT, app.gPVT1, app.gRDRT1, app.gOxyComp, app.A, app.DC, MFCData,app.displayFields)
                app.RampDown(true)

                % Set Ramping flag to false, allow for collectMFC's again
                % app.RampingActive = false;

            end
        end

        % Button pushed function: RampDown2Button
        function RampDown2ButtonPushed(app, event)
            %-------Ramp Down 2 Button---------------------------------------------------------------------
            % The rampdown2 button starts at the enpoint of the rampdown 1
            % button and is NOT a separate rampdown setting it seems to be
            % intended to be executed in a sequence 
            % ---------------------------------------------------------------

            if app.gPVT2 >= app.gPVT1
                msgbox('Plateau Velocity 2 Total must be lower than Plateau Velocity 1 Total');
            else
                %QRT2, calculates volume flow rampdown rate in standard
                %L/min/s (confusing name again)
                app.QRT2 = app.gRDRT2*app.A*app.DC*6/100;
                app.SLMsEditField_13.Value = app.QRT2;
            
                %QPT2, calculate the volume flow in Standard L/m that
                %corresponds to the plateau velocity for 2nd rampdown
                app.QPT2 = app.gPVT2*app.A*app.DC*6/100;
                app.SLMEditField_16.Value = app.QPT2;
            
                %PVB2, calculate the plateau velocity for oxygen in cm/s
                app.PVB2 = app.gPVT2*app.gOxyComp/100;
                app.PlateauVelocity2BcmsEditField.Value = app.PVB2;
            
                %QPB2, calculate the oxygen plateau volume flow in standard
                %L/min
                app.QPB2 = app.PVB2*app.A*app.DC*6/100;
                app.SLMEditField_14.Value = app.QPB2;
            
                %PVA2, calculate the plateau velocity for nitrogen in cm/s
                app.PVA2 = app.gPVT2-app.PVB2;
                app.PlateauVelocity2AcmsEditField.Value = app.PVA2;
            
                %QPA2, calculate the nitrogen plateau volume flow in standard
                %L/min
                app.QPA2 = app.PVA2*app.A*app.DC*6/100;
                app.SLMEditField_6.Value = app.QPA2;
            
                %RDRA2, calculate the fraction of total rampdown rate
                %needed to ramp just gas A in cm/s/s of gas A (Gas A is
                %typically nitrogen)
                app.RDRA2 = app.gRDRT2*((app.PVA2-app.PVA1)/(app.gPVT2-app.gPVT1));
                
                if isnan(app.RDRA2)
                    msgbox('Ramp Down Rate 2-A resulted to NaN');
                else
                    app.RampDownRate2AcmssEditField.Value = app.RDRA2;
                end
            
                %QRA2, calculate volume flow rampdown rate in standard L/s/min of Gas
                %A (nitrogen) for 2nd rampdown
                app.QRA2 = app.RDRA2*app.A*app.DC*6/100;
                if isnan(app.QRA2)
                    msgbox('Ramp Down Flow Rate 2-A (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_5.Value = app.QRA2;
                end
            
                %RDRB2, calculate the fraction of total rampdown rate
                %needed to ramp just gas B in cm/s/s of gas B (oxygen) for
                %rampdown 2
                app.RDRB2 = app.gRDRT2*((app.PVB2-app.PVB1)/(app.gPVT2-app.gPVT1));
                if isnan(app.RDRB2)
                    msgbox('Ramp Down Rate 2-B resulted to NaN');
                else
                    app.RampDownRate2BcmssEditField.Value = app.RDRB2;
                end
            
                %QRB2, calculate volume flow rampdown rate in standard L/s/min of Gas
                %B (oxygen)
                app.QRB2 = app.RDRB2*app.A*app.DC*6/100;
                if isnan(app.QRB2)
                    msgbox('Ramp Down Flow Rate 2-B resulted to NaN');
                else
                    app.SLMsEditField_11.Value = app.QRB2;
                end
            
                %RD2T, calculate the total duration of the rampdown in
                %seconds
                app.RD2T =(app.gPVT2-app.gPVT1)/(app.gRDRT2);
                if isnan(app.RD2T)
                    msgbox('Ramp Down 2 resulted to NaN');
                else
                app.RampDown2secEditField.Value = app.RD2T;
                end
                
                %-----Setting the Flows for gas A and gas B------
                
                %---- Old code, removed 1/7/2026 ----
                % %For Display Purposes
                % if app.N2Unit == 'A'
                %     %Display for SLM Capacity
                %     app.AFlow = calcFlow(app.QPA2, app.N2Unit);
                %     app.EditField.Value = app.AFlow;
                % 
                %     %Display for MFC flow
                %     app.EditField_3.Value = app.QPA2; %SLM
                % elseif app.N2Unit == 'C'
                %     %Display for SLM Capacity
                %     app.CFlow = calcFlow(app.QPA2, app.N2Unit);
                %     app.EditField_4.Value = app.CFlow;
                % 
                %     %Display for MFC flow
                %     app.EditField_6.Value = app.QPA2; %SLM
                % end
                % 
                % if app.O2Unit == 'B'
                %     %Display for SLM Capacity
                %     app.BFlow = calcFlow(app.QPB2, app.O2Unit);
                %     app.EditField_2.Value = app.BFlow;
                % 
                %     %Display for MFC flow
                %     app.EditField_5.Value = app.QPB2; %SLM
                % elseif app.O2Unit == 'D'
                %     %Display for SLM Capacity
                %     app.DFlow = calcFlow(app.QPB2, app.O2Unit);
                %     app.EditField_7.Value = app.DFlow;
                % 
                %     %Display for MFC flow
                %     app.EditField_8.Value = app.QPB2; %SLM
                % end
                % 
                % ---------------
                
                %Setup MFCData input struct
                % MFCData.N2UnitSmall = app.N2UnitSmall;
                % MFCData.N2UnitLarge = app.N2UnitLarge;
                % MFCData.O2UnitSmall = app.O2UnitSmall;
                % MFCData.O2UnitLarge = app.O2UnitLarge;
                % 
                % MFCData.N2UnitSmallMax = app.N2UnitSmallMax;
                % MFCData.N2UnitLargeMax = app.N2UnitLargeMax;
                % MFCData.N2UnitLargeMin = app.N2UnitLargeMin;
                % MFCData.O2UnitSmallMax = app.O2UnitSmallMax;
                % MFCData.O2UnitLargeMax = app.O2UnitLargeMax;
                % MFCData.O2UnitLargeMin = app.O2UnitLargeMin;
    
                %Calculate flow rates           
                [QASmallSetpoint,QALargeSetpoint,QBSmallSetpoint,QBLargeSetpoint] = NCASetpoints(app.QA1,app.QB1,app.MFCData);

                %Update Display with 16 bit signal (0-64000)
                %Calculate 16 bit signal
                app.CFlow = calcFlow(QASmallSetpoint,app.N2UnitSmall);
                app.AFlow = calcFlow(QALargeSetpoint,app.N2UnitLarge);
                app.DFlow = calcFlow(QBSmallSetpoint,app.O2UnitSmall);
                app.BFlow = calcFlow(QBLargeSetpoint,app.O2UnitLarge);
    
                %16 bit Signal Display Fields
                app.EditField_4.Value = app.CFlow;
                app.EditField.Value = app.AFlow;
                app.EditField_7.Value = app.DFlow;
                app.EditField_2.Value = app.BFlow;
                
                %Flow Rate display fields
                app.EditField_6.Value = QASmallSetpoint;
                app.EditField_3.Value = QALargeSetpoint;
                app.EditField_8.Value = QBSmallSetpoint;
                app.EditField_5.Value = QBLargeSetpoint;          
    
                %Set the flow for all MFCs (A through D)
                app.addTask('set_init_A', @() app.setNCAFlow(QALargeSetpoint,'A'),1);
                app.addTask('set_init_B', @() app.setNCAFlow(QBLargeSetpoint,'B'),1);
                app.addTask('set_init_C', @() app.setNCAFlow(QASmallSetpoint,'C'),1);
                app.addTask('set_init_D', @() app.setNCAFlow(QBSmallSetpoint,'D'),1);
                %END New set flow            

                %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
                app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
                %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
                app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
                
                % Start recording the movie 
                callerButton = "Rampdown 2 (Main app)";
                startMovieOnButton(app,callerButton)

                %Starts the ramp down procedure by calling RampDown func
                % RampDown(app.gPVT1, app.gPVT2, app.gRDRT2, app.gOxyComp, app.A, app.DC, app.N2Unit, app.O2Unit);
                % RampDown(app.gPVT1, app.gPVT2, app.gRDRT2, app.gOxyComp, app.A, app.DC, app.MFCData,app.displayFields)
                RampDown(app,false)
            end
            
        end

        % Button pushed function: RampDownO2Button
        function RampDownO2ButtonPushed(app, event)
            %Thao's code -----------------------------------------------------------------------
            app.O2_vel_plateau = app.gIVT*app.gO2_percent_plateau/100;
            app.O2_flow_plateau = app.QT1*app.gO2_percent_plateau/100;
            app.SLMEditField_18.Value = app.O2_flow_plateau; % SLM for O2
            
            app.N2_vel_plateau = app.gIVT-app.O2_vel_plateau;
            app.N2_flow_plateau = app.QT1-app.O2_flow_plateau;
            
            app.O2_Q_ramp_rate = app.QT1*app.gO2_percent_RDR/100;
            app.SLMsEditField_15.Value = app.O2_Q_ramp_rate; % SLM/s for O2
            app.N2_Q_ramp_rate = -1*app.O2_Q_ramp_rate;
            
            app.duration = (app.gO2_percent_plateau-app.gOxyComp)/app.gO2_percent_RDR;
            if isnan(app.duration)
                msgbox('Oxygen Ramp Time resulted to NaN');
            else
                app.OxygenRampsecEditField.Value = app.duration;
            end
            
            %For Display Purposes
            if app.N2Unit == 'A'
                %Display for SLM Capacity
                app.AFlow = calcFlow(app.N2_flow_plateau, app.N2Unit);
                app.EditField.Value = app.AFlow;
                
                %Display for MFC flow
                app.EditField_3.Value = app.N2_flow_plateau; %SLM
            elseif app.N2Unit == 'C'
                %Display for SLM Capacity
                app.CFlow = calcFlow(app.N2_flow_plateau, app.N2Unit);
                app.EditField_4.Value = app.CFlow;
                
                %Display for MFC flow
                app.EditField_6.Value = app.N2_flow_plateau; %SLM
            end
            
            if app.O2Unit == 'B'
                %Display for SLM Capacity
                app.BFlow = calcFlow(app.O2_flow_plateau, app.O2Unit);
                app.EditField_2.Value = app.BFlow;
                
                %Display for MFC flow
                app.EditField_5.Value = app.O2_flow_plateau; %SLM
            elseif app.O2Unit == 'D'
                %Display for SLM Capacity
                app.DFlow = calcFlow(app.O2_flow_plateau, app.O2Unit);
                app.EditField_7.Value = app.DFlow;
                
                %Display for MFC flow
                app.EditField_8.Value = app.O2_flow_plateau; %SLM
            end
            %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E) -- Although the total shouldn't change since keeping the same total flow rate
            app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
            %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
            
            % Start recording the movie 
            callerButton = "Rampdown O2 (Main app)";
            startMovieOnButton(app,callerButton)
            
            %Begin Rampdown procedure
            app.RampDownO2();
            % RampDownOxygen(app.QT1, app.gO2_percent_plateau, app.gO2_percent_RDR, app.gOxyComp, app.N2Unit, app.O2Unit )
        end

        % Value changed function: RampDownRate1TotalcmssSpinner
        function RampDownRate1TotalcmssSpinnerValueChanged(app, event)
            app.gRDRT1 = app.RampDownRate1TotalcmssSpinner.Value;
            
        end

        % Value changed function: RampDownRate2TotalcmssSpinner
        function RampDownRate2TotalcmssSpinnerValueChanged(app, event)
            app.gRDRT2 = app.RampDownRate2TotalcmssSpinner.Value;
            
        end

        % Button pushed function: RampUpButton
        function RampUpButtonPushed(app, event)
            if app.gFVT <= app.gPVT2
                msgbox('Final Plateau Velocity must be higher than Plateau Velocity 2 Total');
            else
                %Ramp Up Button-------------------------------------------------------------------
                %QRT3
                app.QRT3 = app.gRURT*app.A*app.DC*6/100;
                app.SLMsEditField_14.Value = app.QRT3;
                
                %QFT
                app.QFT = app.gFVT*app.A*app.DC*6/100;
                app.SLMEditField_17.Value = app.QFT;
                
                %PFB
                app.PFB = app.gFVT*app.gOxyComp/100;
                app.FinalVelocityBcmsEditField.Value = app.PFB;
                
                %QFB
                app.QFB = app.PFB*app.A*app.DC*6/100;
                app.SLMEditField_15.Value = app.QFB;
                
                %PFA
                app.PFA = app.gFVT-app.PFB;
                app.PlateauVelocityAcmsEditField.Value = app.PFA;
                
                %QFA
                app.QFA = app.PFA*app.A*app.DC*6/100;
                app.SLMEditField_7.Value = app.QFA;
                
                %RURA
                app.RURA = app.gRURT*((app.PFA-app.PVA2)/(app.gFVT-app.gPVT2));
                if isnan(app.RURA)
                    msgbox('Ramp Up Rate A resulted to NaN');
                else
                    app.RampDownRateAcmssEditField.Value = app.RURA;
                end
                
                %QRA3
                app.QRA3 = app.RURA*app.A*app.DC*6/100;
                if isnan(app.QRA3)
                    msgbox('Ramp Up Flow Rate A (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_6.Value = app.QRA3;
                end
                
                %RURB
                app.RURB = app.gRURT*((app.PFB-app.PVA2)/(app.gFVT-app.gPVT2));
                if isnan(app.RURB)
                    msgbox('Ramp Up Rate B resulted to NaN');
                else
                    app.RampUpRateBcmssEditField.Value = app.RURB;
                end
                
                %QRB3
                app.QRB3 = app.RURB*app.A*app.DC*6/100;
                if isnan(app.QRB3)
                    msgbox('Ramp Up Flow Rate B (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_12.Value = app.QRB3;
                end
                
                %RUT
                app.RUT = (app.gFVT-app.gPVT2)/(app.gRURT);
                if isnan(app.RUT)
                    msgbox('Ramp Up 1 resulted to NaN');
                else
                    app.RampUp1secEditField.Value = app.RUT;
                end
                
                %-----Setting the Flows for gas A and gas B------
                
                %For Display Purposes
                if app.N2Unit == 'A'
                    %Display for SLM Capacity
                    app.AFlow = calcFlow(app.QFA, app.N2Unit);
                    app.EditField.Value = app.AFlow;
                    
                    %Display for MFC flow
                    app.EditField_3.Value = app.QFA; %SLM
                elseif app.N2Unit == 'C'
                    %Display for SLM Capacity
                    app.CFlow = calcFlow(app.QFA, app.N2Unit);
                    app.EditField_4.Value = app.CFlow;
                    
                    %Display for MFC flow
                    app.EditField_6.Value = app.QFA; %SLM
                end
                
                if app.O2Unit == 'B'
                    %Display for SLM Capacity
                    app.BFlow = calcFlow(app.QFB, app.O2Unit);
                    app.EditField_2.Value = app.BFlow;
                    
                    %Display for MFC flow
                    app.EditField_5.Value = app.QFB; %SLM
                elseif app.O2Unit == 'D'
                    %Display for SLM Capacity
                    app.DFlow = calcFlow(app.QFB, app.O2Unit);
                    app.EditField_7.Value = app.DFlow;
                    
                    %Display for MFC flow
                    app.EditField_8.Value = app.QFB; %SLM
                end
                %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
                app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
                %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
                app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
                
                %Starts the ramp down procedure by calling RampDown func
                RampUp(app.gPVT2, app.gFVT, app.gRURT, app.gOxyComp, app.A, app.DC, app.N2Unit, app.O2Unit)
            end
        end

        % Value changed function: RampUpRate1TotalcmssSpinner
        function RampUpRate1TotalcmssSpinnerValueChanged(app, event)
            app.gRURT = app.RampUpRate1TotalcmssSpinner.Value;
            
        end

        % Button pushed function: ReadMFCsButton
        function ReadMFCsButtonPushed(app, event)
            if strcmp(app.mfcIOTimer.Running, "off")
                app.mfcIOTimer.start;
                app.ReadMFCsButton.Enable = "off";
            end
            
            app.recordDataListeners.readMFCsPressed = true; 
        end

        % Value changed function: ReferencePressurekPaSpinner
        function ReferencePressurekPaSpinnerValueChanged(app, event)
            app.gRefPress = app.ReferencePressurekPaSpinner.Value;
            
            %Atmospheric Pressure
            app.gRefPressATM = (app.gRefPress)/101.32;
            app.atmEditField_2.Value = app.gRefPressATM;
            
            %Pounds per square inch pressure
            app.gRefPressPSIA = (app.gRefPress)*14.696/101.32;
            app.psiaEditField_2.Value = app.gRefPressPSIA;
        end

        % Value changed function: ReferenceTemperatureCSpinner
        function ReferenceTemperatureCSpinnerValueChanged(app, event)
            app.gRefTemp = app.ReferenceTemperatureCSpinner.Value;
            
            %Kelvin
            app.RefTempK = (app.gRefTemp) + 273.15;
            app.KEditField_2.Value = app.RefTempK;
            
            %Fahrenheit
            app.RefTempF = ((app.RefTempK)-273.15)*(9/5)+32;
            app.FEditField_2.Value = app.RefTempF;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %------------------Before pressing Start, make sure that all lines to the MFCs are pressurized!!!---------------
            
            %-----------Calculations-----------------------------------(Independent from the 'Calculate' Button)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %             app.EditField_3.Value = 0;
            %             app.EditField_5.Value = 0;
            %             app.EditField_6.Value = 0;
            %             app.EditField_8.Value = 0;
            %             app.EditField_10.Value = 0;
            %            
            %             setFlow(0,'A');
            %             setFlow(0,'B');
            %             setFlow(0,'C');
            %             setFlow(0,'D');
            %             setFlow(0,'E');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Calculates Density Correction Factor
            app.DC =(app.gChPress/app.gRefPress)*(app.ChTempK/app.RefTempK);
            app.DensityCorrectionFactorEditField.Value = app.DC;
            
            %Calculates Cross Sectional Area
            app.A = (app.gChannelWidth)*(app.gGapHeight / 10);
            app.CrossSectionalAreacm2EditField.Value = app.A;
            
            %QT1, calcualtes mass flow SLM
            app.QT1 = app.gIVT * app.A* app.DC * .06;
            app.SLMEditField_8.Value = app.QT1;
            
            %QB1, calcualtes B(oxygen) mass flow SLM
            app.QB1 = (app.gOxyComp * app.QT1)/100;
            app.SLMEditField_12.Value = app.QB1;
            
            %QA1, calculates A(Nitrogen) mass flow SLM
            app.QA1 = (app.QT1 - app.QB1);
            app.SLMEditField.Value = app.QA1;
            
            %IVA, calculates A velocity cm/s
            app.IVA = app.QA1/(app.A*.06);
            app.InitialVelocityAcmsEditField.Value = app.IVA;
            
            %IVB, calculates B velocity cm/s
            app.IVB = app.QB1/(app.A*.06);
            app.InitialVelocityBcmsEditField.Value = app.IVB;
            
            %ISA50, calculate the signal for the 50 SLM A MFC
            app.ISA50 = app.QA1*64000/50;
            
            %ISA1, calculate the signal for the 1 SLM A MFC
            app.ISA1 = app.QA1*64000/1;
            
            %ISB50, calculate the signal for the 50 SLM B MFC
            app.ISB50 = app.QB1*64000/50;
            
            %ISB1, calculate the signal for the 1 SLM B MFC
            app.ISB1 = app.QB1*64000/1;
            
            %Setting flow using both units for both gasses (Proper ramping
            %prep).
    
            %Specify MFC labels (Replace w/ values from dropdown if anyone
            %ever decided to make that work. David said making those work
            %is useless)
            % app.N2UnitSmall = 'C';
            % app.N2UnitLarge = 'A';
            % app.O2UnitSmall = 'D';
            % app.O2UnitLarge = 'B';
            % 
            % %Specify maximum flow rates for each MFC [SLM]
            % app.N2UnitSmallMax = 1;
            % app.N2UnitLargeMax = 50;
            % app.N2UnitLargeMin = 1;
            % 
            % app.O2UnitSmallMax = 1;
            % app.O2UnitLargeMax = 50;
            % app.O2UnitLargeMin = 1;
            % 
            % %Setup MFCData input struct
            % app.MFCData.N2UnitSmall = app.N2UnitSmall;
            % app.MFCData.N2UnitLarge = app.N2UnitLarge;
            % app.MFCData.O2UnitSmall = app.O2UnitSmall;
            % app.MFCData.O2UnitLarge = app.O2UnitLarge;
            % 
            % app.MFCData.N2UnitSmallMax = app.N2UnitSmallMax;
            % app.MFCData.N2UnitLargeMax = app.N2UnitLargeMax;
            % app.MFCData.N2UnitLargeMin = app.N2UnitLargeMin;
            % app.MFCData.O2UnitSmallMax = app.O2UnitSmallMax;
            % app.MFCData.O2UnitLargeMax = app.O2UnitLargeMax;
            % app.MFCData.O2UnitLargeMin = app.O2UnitLargeMin;

            %Setup display fields struct
            % MFC A = Gas A (N2) Large MFC
            % MFC B = Gas B (O2) Large MFC
            % MFC C = Gas A (N2) Small MFC
            % MFC D = Gas B (O2) Small MFC


            %Calculate flow rates and set the flows for all the MFCs    
            % tic
            [QASmallSetpoint,QALargeSetpoint,QBSmallSetpoint,QBLargeSetpoint] = ...
                app.distributeFlow(app.QA1,app.QB1);

            % Large MFC Flow Tasks
            app.addTask('set MFC A Flow', @() app.setNCAFlow(QALargeSetpoint,'A'),1);
            app.addTask('set MFC B Flow', @() app.setNCAFlow(QBLargeSetpoint,'B'),1);
            
            % Small MFC Flow Tasks
            app.addTask('set MFC C Flow', @() app.setNCAFlow(QASmallSetpoint,'C'),1);
            app.addTask('set MFC D Flow', @() app.setNCAFlow(QBSmallSetpoint,'D'),1);


            % app.addTask('startFlow', @() NCASetpoints(app.QA1,app.QB1,app.MFCData,true,updateFields=true,fields=app.displayFields),1);
            % [QASmallSetpoint,QALargeSetpoint,QBSmallSetpoint,QBLargeSetpoint] = NCASetpoints(app.QA1,app.QB1,app.MFCData,true,updateFields=true,fields=app.displayFields);
            % toc 

            % Start recording the movie 
            callerButton = "Start (Main app)";
            startMovieOnButton(app,callerButton)

            %Update Display with 16 bit signal (0-64000)
            %Calculate 16 bit signal
            % app.CFlow = calcFlow(QASmallSetpoint,app.N2UnitSmall);
            % app.AFlow = calcFlow(QALargeSetpoint,app.N2UnitLarge);
            % app.DFlow = calcFlow(QBSmallSetpoint,app.O2UnitSmall);
            % app.BFlow = calcFlow(QBLargeSetpoint,app.O2UnitLarge);
            % 
            % %16 bit Signal Display Fields
            % app.EditField_4.Value = app.CFlow;
            % app.EditField.Value = app.AFlow;
            % app.EditField_7.Value = app.DFlow;
            % app.EditField_2.Value = app.BFlow;
            % 
            % %Flow Rate display fields
            % app.EditField_6.Value = QASmallSetpoint;
            % app.EditField_3.Value = QALargeSetpoint;
            % app.EditField_8.Value = QBSmallSetpoint;
            % app.EditField_5.Value = QBLargeSetpoint;          
            

            % %N2 Units (Will pick one depending on flow rate capacity)
            % if app.QA1 < 1 %if QA1 is less than one (64000/50 = 1280)
            %     app.ISA2MFC = num2str(app.ISA1); %use MFC Unit C for N2
            %     app.N2Unit = 'C';
            %     app.NonN2Unit = 'A';
            %     setFlow(app.QA1, app.N2Unit);
            % 
            %     %Display for SLM Capacity
            %     app.CFlow = calcFlow(app.QA1, app.N2Unit);
            %     app.EditField_4.Value = app.CFlow;
            % 
            %     %Display for MFC flow
            %     app.EditField_6.Value = app.QA1; %SLM
            %     setFlow(app.QA1, app.N2Unit);
            % else
            %     app.ISA2MFC = num2str(app.ISA50); %use MFC Unit A for N2
            %     app.N2Unit = 'A';
            %     app.NonN2Unit = 'C';
            % 
            %     %Display for SLM Capacity
            %     app.AFlow = calcFlow(app.QA1, app.N2Unit); 
            %     app.EditField.Value = app.AFlow;
            % 
            %     %Display for MFC flow
            %     app.EditField_3.Value = app.QA1; %SLM
            %     setFlow(app.QA1, app.N2Unit);
            % end
            % 
            % %O2 Units (Will pick one depending on flow rate capacity)    
            % if app.QB1 < 1 %if QB1 is less than one (64000/50 = 1280)
            %     app.ISB2MFC = num2str(app.ISB1); %use MFC Unit D for O2
            %     app.O2Unit = 'D';
            %     app.NonO2Unit = 'B';
            %     setFlow(app.QB1, app.O2Unit);
            % 
            %     %Display for SLM Capacity
            %     app.DFlow = calcFlow(app.QB1, app.O2Unit);
            %     app.EditField_7.Value = app.DFlow;
            % 
            %     %Display for MFC flow
            %     app.EditField_8.Value = app.QB1; %SLM
            %     setFlow(app.QB1, app.O2Unit);
            % else
            %     app.ISB2MFC = num2str(app.ISB50); %use MFC Unit B for O2
            %     app.O2Unit = 'B';
            %     app.NonO2Unit = 'D';
            % 
            %     %Display for SLM Capacity
            %     app.BFlow = calcFlow(app.QB1, app.O2Unit);
            %     app.EditField_2.Value = app.BFlow;
            % 
            %     %Display for MFC flow
            %     app.EditField_5.Value = app.QB1; %SLM
            %     setFlow(app.QB1, app.O2Unit);
            % end
            
            %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
            app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
            %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            if ~isempty(app.RampTimer) && isvalid(app.RampTimer)
                stop(app.RampTimer);
                delete(app.RampTimer);
            end
            app.RampTimer = [];
            app.RampingActive = false;
            app.RampActiveLamp.Enable = "off";

            format shortG
            % app.addTask('set A Flow 0', @() setFlow(0,'A'),1);
            % app.addTask('set B Flow 0', @() setFlow(0,'B'),1);
            % app.addTask('set C Flow 0', @() setFlow(0,'C'),1);
            % app.addTask('set D Flow 0', @() setFlow(0,'D'),1);
            % app.addTask('set E Flow 0', @() setFlow(0,'E'),1);
            
            app.clearTasks(); % Remove all existing commands and overwrite with stoping flow
            app.addTask('set A Flow 0', @() app.setNCAFlow(0,'A'),1);
            app.addTask('set B Flow 0', @() app.setNCAFlow(0,'B'),1);
            app.addTask('set C Flow 0', @() app.setNCAFlow(0,'C'),1);
            app.addTask('set D Flow 0', @() app.setNCAFlow(0,'D'),1);
            app.addTask('set E Flow 0', @() app.setNCAFlow(0,'E'),1);
            % setFlow(0,'A');
            % setFlow(0,'B');
            % setFlow(0,'C');
            % setFlow(0,'D');
            % setFlow(0,'E');


            %Display for SLPM scale
            %MFC Unit A
            app.AFlow = 0;
            app.EditField.Value = 0; %Flow Unit
            app.EditField_3.Value = 0; %SLM
            app.AFlowAct = 0;
            app.EditField_11.Value = 0;
            app.EditField_12.Value = 0;

            %MFC Unit B
            app.BFlow = 0;
            app.EditField_2.Value = 0; %Flow Unit
            app.EditField_5.Value = 0; %SLM
            app.BFlowAct = 0;
            app.EditField_13.Value = 0;
            app.EditField_14.Value = 0;

            %MFC Unit C
            app.CFlow = 0;
            app.EditField_4.Value = 0; %Flow Unit
            app.EditField_6.Value = 0; %SLM
            app.CFlowAct = 0;
            app.EditField_16.Value = 0;
            app.EditField_15.Value = 0;

            %MFC Unit D
            app.DFlow = 0;
            app.EditField_7.Value = 0; %Flow Unit
            app.EditField_8.Value = 0; %SLM
            app.DFlowAct = 0;
            app.EditField_17.Value = 0;
            app.EditField_18.Value = 0;

            %MFC Unit E
            app.EFlow = 0;
            app.EditField_9.Value = 0; %Flow Unit
            app.EditField_10.Value = 0; %SLM
            app.EFlowAct = 0;
            app.EditField_19.Value = 0;
            app.EditField_20.Value = 0;


            %TotalFlow
            app.TotalFlowSetpointEditField.Value = 0;

            %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);

            %Actual Total Flow
            app.ActualTotalFlowEditField.Value = 0;

            %Actual Total Velocity
            app.ActualTotalVelocityEditField.Value = app.ActualTotalFlowEditField.Value / (app.A * app.DC * 0.06);
        end

        % Value changed function: psiaSpinner
        function psiaSpinnerValueChanged(app, event)
            app.gConverterPsia = app.psiaSpinner.Value;

            % mm to cm
            app.kpaEditField.Value = app.gConverterPsia*6.89476;
            
        end

        % Button pushed function: RecordDataButton
        function RecordDataButtonPushed(app, event)
            if app.toggleData == false
                app.toggleData = true;
                app.Lamp.Color = '#009000';
                app.recordDataStopwatch = tic;

            else
                app.toggleData = false;
                app.Lamp.Color = '#DA1884';
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            [fileNameData, fileLocData] = uiputfile('*.csv', 'Save Weight Data');
            saveLocData = [fileLocData fileNameData];
    
            try
                recordedDataTable = array2table(app.recordedDataVec,"VariableNames",app.recordedDataTableVarNames);
                writetable(recordedDataTable,saveLocData)
                % writematrix(app.pressureVector', saveLocData);
            catch ME
                uialert(app.NarrowChannel, ME.message, "Error", "Interpreter", "html");
            end

        end

        % Button pushed function: ConnectArduinoButton
        function ConnectArduinoButtonPushed(app, event)
            dlgTitle = 'COM Port';
            dlgInput = {'COM4'};
            dlgFieldSize = [1 45];
            dlgPrompt = {'Enter the COM port of the Arduino (Ex. COM4)'};
            tempPort = inputdlg(dlgPrompt, dlgTitle, dlgFieldSize, dlgInput);
            comPort = char(tempPort);

            try
                app.pressureDevice = serialport(comPort, 9600);
            catch ME
                uialert(app.NarrowChannel, ME.message, "Error", "Interpreter", "html");
                return
            end
            
            app.recordDataListeners.pressureSensorConnected = true;
            % configureCallback calls the function specified in the command when a terminator is available to be read from the serial port
            % addListener specifies a function to be called when a specific
            % value is changed
            
            readline(app.pressureDevice);
            configureTerminator(app.pressureDevice, 'CR/LF');
            configureCallback(app.pressureDevice, "terminator", @app.handleIncoming);
            % addlistener(app, 'sensorPressure', 'PostSet', @app.sensorChange);

        end

        % Close request function: NarrowChannel
        function NarrowChannelCloseRequest(app, event)
            %Executed when user closes the app
            
            %Stop and delete all running timers managed by the app
            stop(timerfindall)
            delete(timerfindall)

            %Delete app
            delete(app)
            
        end

        % Button pushed function: cameraButton
        function cameraButtonPushed(app, event)
            % Disable camera button while open to prevent multiple
            % instances
            app.cameraButton.Enable = "off";

            % Open camera app instance 
            app.cameraApp = camera_app(app);
        end

        % Callback function
        function saveFileCheckBoxValueChanged(app, event)
            value = app.saveFileCheckBox.Value;
            
            if value == 0
                app.filenameField.Enable = "off";
                app.fileExtensionDropDown.Enable = "off";
            else
                
                app.filenameField.Enable = "on";
                app.fileExtensionDropDown.Enable = "on";
            end
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            app.recordedDataVec = zeros(1,7);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NarrowChannel = uifigure('Visible', 'off');
            app.NarrowChannel.Name = 'UI Figure';
            app.NarrowChannel.CloseRequestFcn = createCallbackFcn(app, @NarrowChannelCloseRequest, true);
            app.ControlsPanel = uipanel(app.NarrowChannel);
            app.ControlsPanel.Title = 'Controls';
            app.StartButton = uibutton(app.ControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartAirButton = uibutton(app.ControlsPanel, 'push');
            app.StartAirButton.ButtonPushedFcn = createCallbackFcn(app, @StartAirButtonPushed, true);
            app.StartAirButton.Text = 'Start Air';
            app.AirVelocitycmsSpinnerLabel = uilabel(app.ControlsPanel);
            app.AirVelocitycmsSpinnerLabel.Text = 'Air Velocity (cm/s)';
            app.AirVelocitycmsSpinner = uispinner(app.ControlsPanel);
            app.AirVelocitycmsSpinner.Limits = [0 25];
            app.AirVelocitycmsSpinner.ValueChangedFcn = createCallbackFcn(app, @AirVelocitycmsSpinnerValueChanged, true);
            app.AirVelocitycmsSpinner.Value = 15;
            app.RampDown2Button = uibutton(app.ControlsPanel, 'push');
            app.RampDown2Button.ButtonPushedFcn = createCallbackFcn(app, @RampDown2ButtonPushed, true);
            app.RampDown2Button.Text = 'Ramp Down 2';
            app.RampDownO2Button = uibutton(app.ControlsPanel, 'push');
            app.RampDownO2Button.ButtonPushedFcn = createCallbackFcn(app, @RampDownO2ButtonPushed, true);
            app.RampDownO2Button.Text = 'Ramp Down O2';
            app.RampDown1Button = uibutton(app.ControlsPanel, 'push');
            app.RampDown1Button.ButtonPushedFcn = createCallbackFcn(app, @RampDown1ButtonPushed, true);
            app.RampDown1Button.Text = 'Ramp Down 1';
            app.RampUpButton = uibutton(app.ControlsPanel, 'push');
            app.RampUpButton.ButtonPushedFcn = createCallbackFcn(app, @RampUpButtonPushed, true);
            app.RampUpButton.Text = 'Ramp Up';
            app.ReadMFCsButton = uibutton(app.ControlsPanel, 'push');
            app.ReadMFCsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadMFCsButtonPushed, true);
            app.ReadMFCsButton.Text = 'Read MFCs';
            app.CalculateButton = uibutton(app.ControlsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RecordDataButton = uibutton(app.ControlsPanel, 'push');
            app.RecordDataButton.ButtonPushedFcn = createCallbackFcn(app, @RecordDataButtonPushed, true);
            app.RecordDataButton.Enable = 'off';
            app.RecordDataButton.Text = 'Record Data';
            app.ExportDataButton = uibutton(app.ControlsPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.Label_2 = uilabel(app.ControlsPanel);
            app.Label_2.Text = '';
            app.Lamp = uilamp(app.ControlsPanel);
            app.cameraButton = uibutton(app.ControlsPanel, 'push');
            app.cameraButton.ButtonPushedFcn = createCallbackFcn(app, @cameraButtonPushed, true);
            app.cameraButton.Tooltip = {'Opens camera control window. '};
            app.cameraButton.Text = 'Open Camera Control';
            app.ResetDataButton = uibutton(app.ControlsPanel, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Enable = 'off';
            app.ResetDataButton.Text = 'Reset Data';
            app.SetupPanel = uipanel(app.NarrowChannel);
            app.SetupPanel.Title = 'Setup';
            app.Panel_2 = uipanel(app.SetupPanel);
            app.SourceAGasDropDownLabel = uilabel(app.Panel_2);
            app.SourceAGasDropDownLabel.Text = 'Source A Gas';
            app.SourceAGasDropDown = uidropdown(app.Panel_2);
            app.SourceAGasDropDown.Items = {'O2', 'N2', 'Air', ''};
            app.SourceAGasDropDown.Value = 'N2';
            app.SourceCGasDropDownLabel = uilabel(app.Panel_2);
            app.SourceCGasDropDownLabel.Text = 'Source C Gas';
            app.SourceCGasDropDown = uidropdown(app.Panel_2);
            app.SourceCGasDropDown.Items = {'O2', 'N2', 'Air', ''};
            app.SourceCGasDropDown.Value = 'N2';
            app.InitialVelocityAcmsEditFieldLabel = uilabel(app.Panel_2);
            app.InitialVelocityAcmsEditFieldLabel.Text = 'Initial Velocity- A (cm/s)';
            app.InitialVelocityAcmsEditField = uieditfield(app.Panel_2, 'numeric');
            app.InitialVelocityAcmsEditField.Editable = 'off';
            app.SLMEditFieldLabel = uilabel(app.Panel_2);
            app.SLMEditFieldLabel.Text = 'SLM';
            app.SLMEditField = uieditfield(app.Panel_2, 'numeric');
            app.SLMEditField.Editable = 'off';
            app.Panel_12 = uipanel(app.SetupPanel);
            app.PlateauVelocity2BcmsEditFieldLabel = uilabel(app.Panel_12);
            app.PlateauVelocity2BcmsEditFieldLabel.Text = 'Plateau Velocity 2-B (cm/s)';
            app.PlateauVelocity2BcmsEditField = uieditfield(app.Panel_12, 'numeric');
            app.PlateauVelocity2BcmsEditField.Editable = 'off';
            app.RampDownRate2BcmssEditFieldLabel = uilabel(app.Panel_12);
            app.RampDownRate2BcmssEditFieldLabel.Text = 'Ramp Down Rate 2-B (cm/s/s)';
            app.RampDownRate2BcmssEditField = uieditfield(app.Panel_12, 'numeric');
            app.RampDownRate2BcmssEditField.Editable = 'off';
            app.SLMsEditField_11Label = uilabel(app.Panel_12);
            app.SLMsEditField_11Label.Text = 'SLM/s';
            app.SLMsEditField_11 = uieditfield(app.Panel_12, 'numeric');
            app.SLMsEditField_11.Editable = 'off';
            app.SLMEditField_14Label = uilabel(app.Panel_12);
            app.SLMEditField_14Label.Text = 'SLM';
            app.SLMEditField_14 = uieditfield(app.Panel_12, 'numeric');
            app.SLMEditField_14.Editable = 'off';
            app.Panel_13 = uipanel(app.SetupPanel);
            app.FinalVelocityBcmsEditFieldLabel = uilabel(app.Panel_13);
            app.FinalVelocityBcmsEditFieldLabel.Text = 'Final Velocity-B (cm/s)';
            app.FinalVelocityBcmsEditField = uieditfield(app.Panel_13, 'numeric');
            app.FinalVelocityBcmsEditField.Editable = 'off';
            app.RampUpRateBcmssEditFieldLabel = uilabel(app.Panel_13);
            app.RampUpRateBcmssEditFieldLabel.Text = 'Ramp Up Rate-B (cm/s/s)';
            app.RampUpRateBcmssEditField = uieditfield(app.Panel_13, 'numeric');
            app.RampUpRateBcmssEditField.Editable = 'off';
            app.SLMsEditField_12Label = uilabel(app.Panel_13);
            app.SLMsEditField_12Label.Text = 'SLM/s';
            app.SLMsEditField_12 = uieditfield(app.Panel_13, 'numeric');
            app.SLMsEditField_12.Editable = 'off';
            app.SLMEditField_15Label = uilabel(app.Panel_13);
            app.SLMEditField_15Label.Text = 'SLM';
            app.SLMEditField_15 = uieditfield(app.Panel_13, 'numeric');
            app.SLMEditField_15.Editable = 'off';
            app.ChannelPressurekPaSpinnerLabel = uilabel(app.SetupPanel);
            app.ChannelPressurekPaSpinnerLabel.Text = 'Channel Pressure (kPa)';
            app.ChannelPressurekPaSpinner = uispinner(app.SetupPanel);
            app.ChannelPressurekPaSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelPressurekPaSpinnerValueChanged, true);
            app.ChannelPressurekPaSpinner.Value = 101.32;
            app.atmEditFieldLabel = uilabel(app.SetupPanel);
            app.atmEditFieldLabel.Text = 'atm';
            app.atmEditField = uieditfield(app.SetupPanel, 'numeric');
            app.atmEditField.Editable = 'off';
            app.atmEditField.Value = 1;
            app.FEditFieldLabel = uilabel(app.SetupPanel);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.SetupPanel, 'numeric');
            app.FEditField.Editable = 'off';
            app.FEditField.Value = 77;
            app.KEditFieldLabel = uilabel(app.SetupPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.SetupPanel, 'numeric');
            app.KEditField.Editable = 'off';
            app.KEditField.Value = 298.1;
            app.GapHeightmmSpinnerLabel = uilabel(app.SetupPanel);
            app.GapHeightmmSpinnerLabel.Text = 'Gap Height (mm)';
            app.GapHeightmmSpinner = uispinner(app.SetupPanel);
            app.GapHeightmmSpinner.ValueChangedFcn = createCallbackFcn(app, @GapHeightmmSpinnerValueChanged, true);
            app.GapHeightmmSpinner.Value = 5;
            app.Panel_3 = uipanel(app.SetupPanel);
            app.PlateauVelocity1AcmsEditFieldLabel = uilabel(app.Panel_3);
            app.PlateauVelocity1AcmsEditFieldLabel.Text = 'Plateau Velocity 1-A (cm/s)';
            app.PlateauVelocity1AcmsEditField = uieditfield(app.Panel_3, 'numeric');
            app.PlateauVelocity1AcmsEditField.Editable = 'off';
            app.RampDownRate1AcmssEditFieldLabel = uilabel(app.Panel_3);
            app.RampDownRate1AcmssEditFieldLabel.Text = 'Ramp Down Rate 1-A (cm/s/s)';
            app.RampDownRate1AcmssEditField = uieditfield(app.Panel_3, 'numeric');
            app.RampDownRate1AcmssEditField.Editable = 'off';
            app.SLMsEditField_4Label = uilabel(app.Panel_3);
            app.SLMsEditField_4Label.Text = 'SLM/s';
            app.SLMsEditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.SLMsEditField_4.Editable = 'off';
            app.SLMEditField_5Label = uilabel(app.Panel_3);
            app.SLMEditField_5Label.Text = 'SLM';
            app.SLMEditField_5 = uieditfield(app.Panel_3, 'numeric');
            app.SLMEditField_5.Editable = 'off';
            app.Panel_4 = uipanel(app.SetupPanel);
            app.PlateauVelocity2AcmsEditFieldLabel = uilabel(app.Panel_4);
            app.PlateauVelocity2AcmsEditFieldLabel.Text = 'Plateau Velocity 2-A (cm/s)';
            app.PlateauVelocity2AcmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.PlateauVelocity2AcmsEditField.Editable = 'off';
            app.RampDownRate2AcmssEditFieldLabel = uilabel(app.Panel_4);
            app.RampDownRate2AcmssEditFieldLabel.Text = 'Ramp Down Rate 2-A (cm/s/s)';
            app.RampDownRate2AcmssEditField = uieditfield(app.Panel_4, 'numeric');
            app.RampDownRate2AcmssEditField.Editable = 'off';
            app.SLMsEditField_5Label = uilabel(app.Panel_4);
            app.SLMsEditField_5Label.Text = 'SLM/s';
            app.SLMsEditField_5 = uieditfield(app.Panel_4, 'numeric');
            app.SLMsEditField_5.Editable = 'off';
            app.SLMEditField_6Label = uilabel(app.Panel_4);
            app.SLMEditField_6Label.Text = 'SLM';
            app.SLMEditField_6 = uieditfield(app.Panel_4, 'numeric');
            app.SLMEditField_6.Editable = 'off';
            app.Panel_5 = uipanel(app.SetupPanel);
            app.PlateauVelocityAcmsEditFieldLabel = uilabel(app.Panel_5);
            app.PlateauVelocityAcmsEditFieldLabel.Text = 'Plateau Velocity-A (cm/s)';
            app.PlateauVelocityAcmsEditField = uieditfield(app.Panel_5, 'numeric');
            app.PlateauVelocityAcmsEditField.Editable = 'off';
            app.RampDownRateAcmssEditFieldLabel = uilabel(app.Panel_5);
            app.RampDownRateAcmssEditFieldLabel.Text = 'Ramp Down Rate-A (cm/s/s)';
            app.RampDownRateAcmssEditField = uieditfield(app.Panel_5, 'numeric');
            app.RampDownRateAcmssEditField.Editable = 'off';
            app.SLMsEditField_6Label = uilabel(app.Panel_5);
            app.SLMsEditField_6Label.Text = 'SLM/s';
            app.SLMsEditField_6 = uieditfield(app.Panel_5, 'numeric');
            app.SLMsEditField_6.Editable = 'off';
            app.SLMEditField_7Label = uilabel(app.Panel_5);
            app.SLMEditField_7Label.Text = 'SLM';
            app.SLMEditField_7 = uieditfield(app.Panel_5, 'numeric');
            app.SLMEditField_7.Editable = 'off';
            app.Panel_6 = uipanel(app.SetupPanel);
            app.SLMEditField_8Label = uilabel(app.Panel_6);
            app.SLMEditField_8Label.Text = 'SLM';
            app.SLMEditField_8 = uieditfield(app.Panel_6, 'numeric');
            app.SLMEditField_8.Editable = 'off';
            app.TotalFlowSettingsLabel = uilabel(app.Panel_6);
            app.TotalFlowSettingsLabel.Text = 'Total Flow Settings';
            app.InitialVelocityTotalcmsSpinnerLabel = uilabel(app.Panel_6);
            app.InitialVelocityTotalcmsSpinnerLabel.Text = 'Initial Velocity-Total (cm/s)';
            app.InitialVelocityTotalcmsSpinner = uispinner(app.Panel_6);
            app.InitialVelocityTotalcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @InitialVelocityTotalcmsSpinnerValueChanged, true);
            app.InitialVelocityTotalcmsSpinner.Value = 15;
            app.Panel_7 = uipanel(app.SetupPanel);
            app.SLMsEditField_7Label = uilabel(app.Panel_7);
            app.SLMsEditField_7Label.Text = 'SLM/s';
            app.SLMsEditField_7 = uieditfield(app.Panel_7, 'numeric');
            app.SLMsEditField_7.Editable = 'off';
            app.SLMEditField_9Label = uilabel(app.Panel_7);
            app.SLMEditField_9Label.Text = 'SLM';
            app.SLMEditField_9 = uieditfield(app.Panel_7, 'numeric');
            app.SLMEditField_9.Editable = 'off';
            app.RampDownRate1TotalcmssSpinnerLabel = uilabel(app.Panel_7);
            app.RampDownRate1TotalcmssSpinnerLabel.Text = 'Ramp Down Rate 1-Total (cm/s/s)';
            app.RampDownRate1TotalcmssSpinner = uispinner(app.Panel_7);
            app.RampDownRate1TotalcmssSpinner.ValueChangedFcn = createCallbackFcn(app, @RampDownRate1TotalcmssSpinnerValueChanged, true);
            app.PlateauVelocity1TotalcmsSpinnerLabel = uilabel(app.Panel_7);
            app.PlateauVelocity1TotalcmsSpinnerLabel.Text = 'Plateau Velocity 1-Total (cm/s)';
            app.PlateauVelocity1TotalcmsSpinner = uispinner(app.Panel_7);
            app.PlateauVelocity1TotalcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocity1TotalcmsSpinnerValueChanged, true);
            app.Panel_10 = uipanel(app.SetupPanel);
            app.SourceBGasDropDownLabel = uilabel(app.Panel_10);
            app.SourceBGasDropDownLabel.Text = 'Source B Gas';
            app.SourceBGasDropDown = uidropdown(app.Panel_10);
            app.SourceBGasDropDown.Items = {'O2', 'N2', 'Air', ''};
            app.SourceBGasDropDown.Value = 'O2';
            app.SourceDGasDropDownLabel = uilabel(app.Panel_10);
            app.SourceDGasDropDownLabel.Text = 'Source D Gas';
            app.SourceDGasDropDown = uidropdown(app.Panel_10);
            app.SourceDGasDropDown.Items = {'O2', 'N2', 'Air', ''};
            app.SourceDGasDropDown.Value = 'O2';
            app.InitialVelocityBcmsEditFieldLabel = uilabel(app.Panel_10);
            app.InitialVelocityBcmsEditFieldLabel.Text = 'Initial Velocity- B (cm/s)';
            app.InitialVelocityBcmsEditField = uieditfield(app.Panel_10, 'numeric');
            app.InitialVelocityBcmsEditField.Editable = 'off';
            app.SLMEditField_12Label = uilabel(app.Panel_10);
            app.SLMEditField_12Label.Text = 'SLM';
            app.SLMEditField_12 = uieditfield(app.Panel_10, 'numeric');
            app.SLMEditField_12.Editable = 'off';
            app.Panel_11 = uipanel(app.SetupPanel);
            app.PlateauVelocity1BcmsEditFieldLabel = uilabel(app.Panel_11);
            app.PlateauVelocity1BcmsEditFieldLabel.Text = 'Plateau Velocity 1-B (cm/s)';
            app.PlateauVelocity1BcmsEditField = uieditfield(app.Panel_11, 'numeric');
            app.PlateauVelocity1BcmsEditField.Editable = 'off';
            app.RampDownRate1BcmssEditFieldLabel = uilabel(app.Panel_11);
            app.RampDownRate1BcmssEditFieldLabel.Text = 'Ramp Down Rate 1-B (cm/s/s)';
            app.RampDownRate1BcmssEditField = uieditfield(app.Panel_11, 'numeric');
            app.RampDownRate1BcmssEditField.Editable = 'off';
            app.SLMsEditField_10Label = uilabel(app.Panel_11);
            app.SLMsEditField_10Label.Text = 'SLM/s';
            app.SLMsEditField_10 = uieditfield(app.Panel_11, 'numeric');
            app.SLMsEditField_10.Editable = 'off';
            app.SLMEditField_13Label = uilabel(app.Panel_11);
            app.SLMEditField_13Label.Text = 'SLM';
            app.SLMEditField_13 = uieditfield(app.Panel_11, 'numeric');
            app.SLMEditField_13.Editable = 'off';
            app.Panel_14 = uipanel(app.SetupPanel);
            app.SLMsEditField_13Label = uilabel(app.Panel_14);
            app.SLMsEditField_13Label.Text = 'SLM/s';
            app.SLMsEditField_13 = uieditfield(app.Panel_14, 'numeric');
            app.SLMsEditField_13.Editable = 'off';
            app.SLMEditField_16Label = uilabel(app.Panel_14);
            app.SLMEditField_16Label.Text = 'SLM';
            app.SLMEditField_16 = uieditfield(app.Panel_14, 'numeric');
            app.SLMEditField_16.Editable = 'off';
            app.RampDownRate2TotalcmssSpinnerLabel = uilabel(app.Panel_14);
            app.RampDownRate2TotalcmssSpinnerLabel.Text = 'Ramp Down Rate 2-Total (cm/s/s)';
            app.RampDownRate2TotalcmssSpinner = uispinner(app.Panel_14);
            app.RampDownRate2TotalcmssSpinner.ValueChangedFcn = createCallbackFcn(app, @RampDownRate2TotalcmssSpinnerValueChanged, true);
            app.PlateauVelocity2TotalcmsSpinnerLabel = uilabel(app.Panel_14);
            app.PlateauVelocity2TotalcmsSpinnerLabel.Text = 'Plateau Velocity 2-Total (cm/s)';
            app.PlateauVelocity2TotalcmsSpinner = uispinner(app.Panel_14);
            app.PlateauVelocity2TotalcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocity2TotalcmsSpinnerValueChanged, true);
            app.Panel_15 = uipanel(app.SetupPanel);
            app.SLMsEditField_14Label = uilabel(app.Panel_15);
            app.SLMsEditField_14Label.Text = 'SLM/s';
            app.SLMsEditField_14 = uieditfield(app.Panel_15, 'numeric');
            app.SLMsEditField_14.Editable = 'off';
            app.SLMEditField_17Label = uilabel(app.Panel_15);
            app.SLMEditField_17Label.Text = 'SLM';
            app.SLMEditField_17 = uieditfield(app.Panel_15, 'numeric');
            app.SLMEditField_17.Editable = 'off';
            app.RampUpRate1TotalcmssSpinnerLabel = uilabel(app.Panel_15);
            app.RampUpRate1TotalcmssSpinnerLabel.Text = 'Ramp Up Rate 1-Total (cm/s/s)';
            app.RampUpRate1TotalcmssSpinner = uispinner(app.Panel_15);
            app.RampUpRate1TotalcmssSpinner.ValueChangedFcn = createCallbackFcn(app, @RampUpRate1TotalcmssSpinnerValueChanged, true);
            app.FinalVelocity1TotalcmsSpinnerLabel = uilabel(app.Panel_15);
            app.FinalVelocity1TotalcmsSpinnerLabel.Text = 'Final Velocity 1-Total (cm/s)';
            app.FinalVelocity1TotalcmsSpinner = uispinner(app.Panel_15);
            app.FinalVelocity1TotalcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @FinalVelocity1TotalcmsSpinnerValueChanged, true);
            app.psiaEditFieldLabel = uilabel(app.SetupPanel);
            app.psiaEditFieldLabel.Text = 'psia';
            app.psiaEditField = uieditfield(app.SetupPanel, 'numeric');
            app.psiaEditField.Editable = 'off';
            app.psiaEditField.Value = 14.7;
            app.DesiredOxygenCompositionOxygeninMixtureSpinnerLabel = uilabel(app.SetupPanel);
            app.DesiredOxygenCompositionOxygeninMixtureSpinnerLabel.Text = 'Desired Oxygen Composition (% Oxygen in Mixture)';
            app.DesiredOxygenCompositionOxygeninMixtureSpinner = uispinner(app.SetupPanel);
            app.DesiredOxygenCompositionOxygeninMixtureSpinner.Limits = [0 100];
            app.DesiredOxygenCompositionOxygeninMixtureSpinner.ValueChangedFcn = createCallbackFcn(app, @DesiredOxygenCompositionOxygeninMixtureSpinnerValueChanged, true);
            app.DesiredOxygenCompositionOxygeninMixtureSpinner.Value = 21;
            app.ChannelTemperatureCSpinnerLabel = uilabel(app.SetupPanel);
            app.ChannelTemperatureCSpinnerLabel.Text = 'Channel Temperature (C)';
            app.ChannelTemperatureCSpinner = uispinner(app.SetupPanel);
            app.ChannelTemperatureCSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelTemperatureCSpinnerValueChanged, true);
            app.ChannelTemperatureCSpinner.Value = 22;
            app.cmEditFieldLabel = uilabel(app.SetupPanel);
            app.cmEditFieldLabel.Text = 'cm';
            app.cmEditField = uieditfield(app.SetupPanel, 'numeric');
            app.cmEditField.Editable = 'off';
            app.psiaSpinnerLabel = uilabel(app.SetupPanel);
            app.psiaSpinnerLabel.Text = 'psia';
            app.psiaSpinner = uispinner(app.SetupPanel);
            app.psiaSpinner.ValueChangedFcn = createCallbackFcn(app, @psiaSpinnerValueChanged, true);
            app.psiaSpinner.Value = 14.696;
            app.kpaEditFieldLabel = uilabel(app.SetupPanel);
            app.kpaEditFieldLabel.Text = 'kpa';
            app.kpaEditField = uieditfield(app.SetupPanel, 'numeric');
            app.kpaEditField.ValueDisplayFormat = '%.2f';
            app.kpaEditField.Editable = 'off';
            app.MFCInletPressurepsiaPanel = uipanel(app.SetupPanel);
            app.MFCInletPressurepsiaPanel.Title = 'MFC Inlet Pressure (psia)';
            app.AEditFieldLabel = uilabel(app.MFCInletPressurepsiaPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.MFCInletPressurepsiaPanel, 'numeric');
            app.AEditField.Editable = 'off';
            app.DEditFieldLabel = uilabel(app.MFCInletPressurepsiaPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.MFCInletPressurepsiaPanel, 'numeric');
            app.DEditField.Editable = 'off';
            app.EEditFieldLabel = uilabel(app.MFCInletPressurepsiaPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.MFCInletPressurepsiaPanel, 'numeric');
            app.EEditField.Editable = 'off';
            app.BEditFieldLabel = uilabel(app.MFCInletPressurepsiaPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.MFCInletPressurepsiaPanel, 'numeric');
            app.BEditField.Editable = 'off';
            app.CEditFieldLabel = uilabel(app.MFCInletPressurepsiaPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.MFCInletPressurepsiaPanel, 'numeric');
            app.CEditField.Editable = 'off';
            app.psiatokpaconverterLabel = uilabel(app.SetupPanel);
            app.psiatokpaconverterLabel.Text = 'psia to kpa converter';
            app.ChannelPressurePanel = uipanel(app.SetupPanel);
            app.ChannelPressurePanel.Title = 'Channel Pressure';
            app.SensorPressurepsiaEditFieldLabel = uilabel(app.ChannelPressurePanel);
            app.SensorPressurepsiaEditFieldLabel.Text = 'Sensor Pressure (psia)';
            app.SensorPressurepsiaEditField = uieditfield(app.ChannelPressurePanel, 'numeric');
            app.SensorPressurepsiaEditField.AllowEmpty = 'on';
            app.SensorPressurepsiaEditField.Editable = 'off';
            app.ConnectArduinoButton = uibutton(app.ChannelPressurePanel, 'push');
            app.ConnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectArduinoButtonPushed, true);
            app.ConnectArduinoButton.Text = 'Connect Arduino';
            app.ReferencesPanel = uipanel(app.NarrowChannel);
            app.ReferencesPanel.Title = 'References';
            app.ReferencePressurekPaSpinnerLabel = uilabel(app.ReferencesPanel);
            app.ReferencePressurekPaSpinnerLabel.Text = 'Reference Pressure (kPa)';
            app.ReferencePressurekPaSpinner = uispinner(app.ReferencesPanel);
            app.ReferencePressurekPaSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferencePressurekPaSpinnerValueChanged, true);
            app.ReferencePressurekPaSpinner.Value = 101.32;
            app.ReferenceTemperatureCSpinnerLabel = uilabel(app.ReferencesPanel);
            app.ReferenceTemperatureCSpinnerLabel.Text = 'Reference Temperature (C)';
            app.ReferenceTemperatureCSpinner = uispinner(app.ReferencesPanel);
            app.ReferenceTemperatureCSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceTemperatureCSpinnerValueChanged, true);
            app.ReferenceTemperatureCSpinner.Value = 25;
            app.ChannelWidthcmSpinnerLabel = uilabel(app.ReferencesPanel);
            app.ChannelWidthcmSpinnerLabel.Text = 'Channel Width (cm)';
            app.ChannelWidthcmSpinner = uispinner(app.ReferencesPanel);
            app.ChannelWidthcmSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelWidthcmSpinnerValueChanged, true);
            app.ChannelWidthcmSpinner.Value = 8.0645;
            app.DensityCorrectionFactorEditFieldLabel = uilabel(app.ReferencesPanel);
            app.DensityCorrectionFactorEditFieldLabel.Text = 'Density Correction Factor';
            app.DensityCorrectionFactorEditField = uieditfield(app.ReferencesPanel, 'numeric');
            app.DensityCorrectionFactorEditField.Editable = 'off';
            app.DensityCorrectionFactorEditField.Value = 1;
            app.atmEditField_2Label = uilabel(app.ReferencesPanel);
            app.atmEditField_2Label.Text = 'atm';
            app.atmEditField_2 = uieditfield(app.ReferencesPanel, 'numeric');
            app.atmEditField_2.Editable = 'off';
            app.atmEditField_2.Value = 1;
            app.FEditField_2Label = uilabel(app.ReferencesPanel);
            app.FEditField_2Label.Text = 'F';
            app.FEditField_2 = uieditfield(app.ReferencesPanel, 'numeric');
            app.FEditField_2.Editable = 'off';
            app.FEditField_2.Value = 77;
            app.psiaEditField_2Label = uilabel(app.ReferencesPanel);
            app.psiaEditField_2Label.Text = 'psia';
            app.psiaEditField_2 = uieditfield(app.ReferencesPanel, 'numeric');
            app.psiaEditField_2.Editable = 'off';
            app.psiaEditField_2.Value = 14.7;
            app.KEditField_2Label = uilabel(app.ReferencesPanel);
            app.KEditField_2Label.Text = 'K';
            app.KEditField_2 = uieditfield(app.ReferencesPanel, 'numeric');
            app.KEditField_2.Editable = 'off';
            app.KEditField_2.Value = 298.1;
            app.CrossSectionalAreacm2EditFieldLabel = uilabel(app.ReferencesPanel);
            app.CrossSectionalAreacm2EditFieldLabel.Text = 'Cross-Sectional Area (cm^2)';
            app.CrossSectionalAreacm2EditField = uieditfield(app.ReferencesPanel, 'numeric');
            app.CrossSectionalAreacm2EditField.Editable = 'off';
            app.CrossSectionalAreacm2EditField.Value = 4.0325;
            app.SettingsPanel = uipanel(app.NarrowChannel);
            app.SettingsPanel.Title = 'Settings';
            app.SLMLabel = uilabel(app.SettingsPanel);
            app.SLMLabel.Text = 'SLM';
            app.EditField = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_9.ValueDisplayFormat = '%.0f';
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_10.Editable = 'off';
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = '64000=100%';
            app.FullScaleLabel = uilabel(app.SettingsPanel);
            app.FullScaleLabel.Text = '%Full Scale';
            app.ActualsPanel = uipanel(app.NarrowChannel);
            app.ActualsPanel.Title = 'Actuals';
            app.SLMLabel_2 = uilabel(app.ActualsPanel);
            app.SLMLabel_2.Text = 'SLM';
            app.VelocityLabel = uilabel(app.ActualsPanel);
            app.VelocityLabel.Text = 'Velocity';
            app.EditField_11 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_11.Editable = 'off';
            app.EditField_12 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_12.Editable = 'off';
            app.EditField_13 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_13.Editable = 'off';
            app.EditField_14 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_14.Editable = 'off';
            app.EditField_15 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_15.Editable = 'off';
            app.EditField_16 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_16.Editable = 'off';
            app.EditField_17 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_17.Editable = 'off';
            app.EditField_18 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_18.Editable = 'off';
            app.EditField_19 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_19.Editable = 'off';
            app.EditField_20 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_20.Editable = 'off';
            app.UnitPanel = uipanel(app.NarrowChannel);
            app.UnitPanel.Title = 'Unit';
            app.A150Label = uilabel(app.UnitPanel);
            app.A150Label.Text = 'A:(1-50)';
            app.N2Label = uilabel(app.UnitPanel);
            app.N2Label.Text = 'N2';
            app.B150Label = uilabel(app.UnitPanel);
            app.B150Label.Text = 'B:(1-50)';
            app.O2Label = uilabel(app.UnitPanel);
            app.O2Label.Text = 'O2';
            app.C0011Label = uilabel(app.UnitPanel);
            app.C0011Label.Text = 'C:(0.01-1)';
            app.D0011Label = uilabel(app.UnitPanel);
            app.D0011Label.Text = 'D:(0.01-1)';
            app.N2Label_2 = uilabel(app.UnitPanel);
            app.N2Label_2.Text = 'N2';
            app.O2Label_2 = uilabel(app.UnitPanel);
            app.O2Label_2.Text = 'O2';
            app.E0055Label = uilabel(app.UnitPanel);
            app.E0055Label.Text = 'E:(0.05-5)';
            app.AirLabel = uilabel(app.UnitPanel);
            app.AirLabel.Text = 'Air';
            app.TotalFlowReadingsPanel = uipanel(app.NarrowChannel);
            app.TotalFlowReadingsPanel.Title = 'Total Flow Readings';
            app.ActualTotalFlowEditFieldLabel = uilabel(app.TotalFlowReadingsPanel);
            app.ActualTotalFlowEditFieldLabel.Text = 'Actual Total Flow';
            app.ActualTotalFlowEditField = uieditfield(app.TotalFlowReadingsPanel, 'numeric');
            app.ActualTotalFlowEditField.Editable = 'off';
            app.ActualTotalVelocityEditFieldLabel = uilabel(app.TotalFlowReadingsPanel);
            app.ActualTotalVelocityEditFieldLabel.Text = 'Actual Total Velocity';
            app.ActualTotalVelocityEditField = uieditfield(app.TotalFlowReadingsPanel, 'numeric');
            app.ActualTotalVelocityEditField.Editable = 'off';
            app.TotalFlowSetpointEditFieldLabel = uilabel(app.TotalFlowReadingsPanel);
            app.TotalFlowSetpointEditFieldLabel.Text = 'Total Flow Setpoint';
            app.TotalFlowSetpointEditField = uieditfield(app.TotalFlowReadingsPanel, 'numeric');
            app.TotalFlowSetpointEditField.Editable = 'off';
            app.TotalVelSetpointEditFieldLabel = uilabel(app.TotalFlowReadingsPanel);
            app.TotalVelSetpointEditFieldLabel.Text = 'Total Vel. Setpoint';
            app.TotalVelSetpointEditField = uieditfield(app.TotalFlowReadingsPanel, 'numeric');
            app.TotalVelSetpointEditField.Editable = 'off';
            app.RampInformationPanel = uipanel(app.NarrowChannel);
            app.RampInformationPanel.Title = 'Ramp Information';
            app.PhaseDurationsLabel = uilabel(app.RampInformationPanel);
            app.PhaseDurationsLabel.Text = 'Phase Durations';
            app.RampDown1secEditFieldLabel = uilabel(app.RampInformationPanel);
            app.RampDown1secEditFieldLabel.Text = 'Ramp Down 1 (sec)';
            app.RampDown1secEditField = uieditfield(app.RampInformationPanel, 'numeric');
            app.RampDown1secEditField.Editable = 'off';
            app.RampDown2secEditFieldLabel = uilabel(app.RampInformationPanel);
            app.RampDown2secEditFieldLabel.Text = 'Ramp Down 2 (sec)';
            app.RampDown2secEditField = uieditfield(app.RampInformationPanel, 'numeric');
            app.RampDown2secEditField.Editable = 'off';
            app.RampUp1secEditFieldLabel = uilabel(app.RampInformationPanel);
            app.RampUp1secEditFieldLabel.Text = 'Ramp Up 1 (sec)';
            app.RampUp1secEditField = uieditfield(app.RampInformationPanel, 'numeric');
            app.RampUp1secEditField.Editable = 'off';
            app.OxygenRampTimesecLabel = uilabel(app.RampInformationPanel);
            app.OxygenRampTimesecLabel.Text = 'Oxygen Ramp (sec)';
            app.OxygenRampsecEditField = uieditfield(app.RampInformationPanel, 'numeric');
            app.OxygenRampsecEditField.Editable = 'off';
            app.O2PercentRampDownsecSpinnerLabel = uilabel(app.RampInformationPanel);
            app.O2PercentRampDownsecSpinnerLabel.Text = 'O2 Percent Ramp Down (%/sec.)';
            app.O2PercentRampDownsecSpinner = uispinner(app.RampInformationPanel);
            app.O2PercentRampDownsecSpinner.ValueChangedFcn = createCallbackFcn(app, @O2PercentRampDownsecSpinnerValueChanged, true);
            app.SLMsEditField_15Label = uilabel(app.RampInformationPanel);
            app.SLMsEditField_15Label.Text = 'SLM/s';
            app.SLMsEditField_15 = uieditfield(app.RampInformationPanel, 'numeric');
            app.SLMsEditField_15.Editable = 'off';
            app.O2percentplateauVolumeLabel = uilabel(app.RampInformationPanel);
            app.O2percentplateauVolumeLabel.Text = 'O2 Percent Plateau (Volume %)';
            app.O2PercentPlateauVolumeSpinner = uispinner(app.RampInformationPanel);
            app.O2PercentPlateauVolumeSpinner.ValueChangedFcn = createCallbackFcn(app, @O2PercentPlateauVolumeSpinnerValueChanged, true);
            app.SLMEditField_18Label = uilabel(app.RampInformationPanel);
            app.SLMEditField_18Label.Text = 'SLM';
            app.SLMEditField_18 = uieditfield(app.RampInformationPanel, 'numeric');
            app.SLMEditField_18.Editable = 'off';
            app.RampActiveLampLabel = uilabel(app.RampInformationPanel);
            app.RampActiveLampLabel.Text = 'Ramp Active';
            app.RampActiveLamp = uilamp(app.RampInformationPanel);
            app.RampActiveLamp.Enable = 'off';
            % Show the figure after all components are created
            app.NarrowChannel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NCA_GUI_OCT2025

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NarrowChannel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NarrowChannel)
        end
    end
end