classdef camera_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        CameraControlTab            matlab.ui.container.Tab
        MovieModeButton             matlab.ui.control.StateButton
        StartMovieOnButtonPressDropDown  matlab.ui.control.DropDown
        StartMovieOnButtonPressDropDownLabel  matlab.ui.control.Label
        DownloadMovieButton         matlab.ui.control.Button
        GetStateButton              matlab.ui.control.Button
        EndMovieButton              matlab.ui.control.Button
        StartMovieButton            matlab.ui.control.Button
        StopLiveViewButton          matlab.ui.control.Button
        StartLiveViewButton         matlab.ui.control.Button
        TakePhotoButton             matlab.ui.control.Button
        TerminateButton             matlab.ui.control.Button
        InitializeButton            matlab.ui.control.Button
        CameraImageSettingsTab      matlab.ui.container.Tab
        ShutterSpeedDropDown        matlab.ui.control.DropDown
        ShutterSpeedDropDownLabel   matlab.ui.control.Label
        LiveViewFramerateEditField  matlab.ui.control.NumericEditField
        LiveViewFramerateEditFieldLabel  matlab.ui.control.Label
        ISOSpeedDropDown            matlab.ui.control.DropDown
        ISOSpeedDropDownLabel       matlab.ui.control.Label
        ApertureDropDown            matlab.ui.control.DropDown
        ApertureDropDownLabel       matlab.ui.control.Label
        recordingTimeLabel          matlab.ui.control.Label
        recordingLamp               matlab.ui.control.Lamp
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        mainNcaApp matlab.apps.AppBase % the main NCA GUI app
        cameraState struct %struct to store camera state booleans. Only updates when called with getState
        liveViewTimer timer %timer to get frames for Live View mode at given framerate also store image as user data
        recordingStopwatch timer %timer to update elapsed time for recording

        TvDisplayDict = dictionary;
    end

    properties (Access = private, Constant)
        tvSeconds = [
            -1.0          % Bulb sentinel

            30.0
            25.0
            20.0
            15.0
            13.0
            10.0
            8.0
            6.0
            5.0
            4.0
            3.2
            3.0
            2.5
            2.0
            1.6
            1.5
            1.3
            1.0
            0.8
            0.7
            0.6
            0.5
            0.4
            1.0/3.0

            1.0/4.0
            1.0/5.0
            1.0/6.0
            1.0/8.0
            1.0/10.0
            1.0/13.0
            1.0/15.0
            1.0/20.0
            1.0/25.0
            1.0/30.0
            1.0/40.0
            1.0/45.0
            1.0/50.0
            1.0/60.0
            1.0/80.0
            1.0/90.0
            1.0/100.0
            1.0/125.0
            1.0/160.0
            1.0/180.0
            1.0/200.0
            1.0/250.0
            1.0/320.0
            1.0/350.0
            1.0/400.0
            1.0/500.0
            1.0/640.0
            1.0/750.0
            1.0/800.0
            1.0/1000.0
            1.0/1250.0
            1.0/1500.0
            1.0/1600.0
            1.0/2000.0
            1.0/2500.0
            1.0/3000.0
            1.0/3200.0
            1.0/4000.0
            1.0/5000.0
            1.0/6000.0
            1.0/6400.0
            1.0/8000.0
            1.0/10000.0
            1.0/12800.0
            1.0/16000.0
            1.0/20000.0
            1.0/25600.0
            1.0/32000.0

            -2.0          % Not valid sentinel
            ];

        tvStrings = [
            "Bulb"

            "30''"
            "25''"
            "20''"
            "15''"
            "13''"
            "10''"
            "8''"
            "6''"
            "5''"
            "4''"
            "3.2''"
            "3''"
            "2.5''"
            "2''"
            "1.6''"
            "1.5''"
            "1.3''"
            "1''"
            "0.8''"
            "0.7''"
            "0.6''"
            "0.5''"
            "0.4''"
            "0.3''"

            "1/4"
            "1/5"
            "1/6"
            "1/8"
            "1/10"
            "1/13"
            "1/15"
            "1/20"
            "1/25"
            "1/30"
            "1/40"
            "1/45"
            "1/50"
            "1/60"
            "1/80"
            "1/90"
            "1/100"
            "1/125"
            "1/160"
            "1/180"
            "1/200"
            "1/250"
            "1/320"
            "1/350"
            "1/400"
            "1/500"
            "1/640"
            "1/750"
            "1/800"
            "1/1000"
            "1/1250"
            "1/1500"
            "1/1600"
            "1/2000"
            "1/2500"
            "1/3000"
            "1/3200"
            "1/4000"
            "1/5000"
            "1/6000"
            "1/6400"
            "1/8000"
            "1/10000"
            "1/12800"
            "1/16000"
            "1/20000"
            "1/25600"
            "1/32000"

            "Not valid"
            ];
    end

    methods (Access = private)

        function waitForDownloadCallback(app,obj,event)
            try
                app.cameraState = edsdk_mex('getState'); %update state
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            if ~app.cameraState.downloadingActive
                stop(obj)
            end
        end

        function updateFrame(app,obj,event)
            %get new frame from camera
            % frame = edsdk_mex('getFrame');
            try
                frame = edsdk_mex('getFrame');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            %Set the image data to the new frame's data
            obj.UserData.picture.CData = frame;

            %Update the axes
            %drawnow limitrate nocallbacks %limitrate limits to 20 fps
            drawnow nocallbacks
            % drawnow
        end


        function updateRecordingTime(app,obj,event)
            %Get elapsed time
            try
                elapsedTime = edsdk_mex('getMovTime');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end
            % elapsedTime = toc(obj.UserData.tStart);

            %Set Label time
            app.recordingTimeLabel.Text = string(duration(0,0,elapsedTime));
        end

        function resetUItoInitState(app)
            % Disable button to prevent repeated presses
            app.TerminateButton.Enable = "off";

            % Disable all other buttons except get state, enable initialize
            app.InitializeButton.Enable = "on";
            app.TakePhotoButton.Enable = "off";
            app.StartLiveViewButton.Enable  = "off";
            app.StopLiveViewButton.Enable = "off";
            app.MovieModeButton.Enable = "off";

            app.StartMovieButton.Enable = "off";
            app.StopLiveViewButton.Enable = "off";
            app.StartMovieOnButtonPressDropDown.Enable = 'off';

            app.ISOSpeedDropDown.Enable = "off";
            app.ApertureDropDown.Enable = "off";
            app.ShutterSpeedDropDown.Enable = "off";

            app.DownloadMovieButton.Enable = "off";

            app.cameraState = edsdk_mex('getState');
        end
    end

    methods (Access = public)

        function pressStartMovie(app)
            % Publicly accessible function that can press the
            % StartMovieButton. Allows the main app to press it based on
            % the selection in StartMovieOnButtonPressDropDown.

            % Only does something if the StartMovieButton is enabled
            if app.StartMovieButton.Enable == "on"
                app.StartMovieButtonPushed([]);
            else
                return
            end
        end

        function elapsedRecordingTime = readRecordingStopwatch(app)
            %app.cameraState = edsdk_mex('getState'); %update state

            if app.recordingStopwatch.Running == "off"
                elapsedRecordingTime = 0;
            else
                %Get elapsed time
                % elapsedRecordingTime = toc(app.recordingStopwatch.UserData.tStart);
                elapsedRecordingTime = edsdk_mex('getMovTime');
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainNcaApp)
            % Store main app instance in camera app
            app.mainNcaApp = mainNcaApp;

            % Populate camera state booleans
            % app.cameraState = edsdk_mex('getState');
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            % Create timer for live view
            framerate = app.LiveViewFramerateEditField.Value;
            % framerate = 30; %Video framerate in fps

            %Round period to nearest milisecond (timer cannot hold sub
            %milisecond precision)
            period = round(1/framerate,3);

            app.liveViewTimer = timer("BusyMode","drop","ExecutionMode",...
                'fixedSpacing','Period',period,...
                TimerFcn=@app.updateFrame,Name="liveViewTimer",StartDelay=1);

            % create image object for camera image. Store w/ the timer
            picture = image(zeros(640,960,3),'Parent',app.UIAxes);
            app.UIAxes.XLim = [1 960];
            app.UIAxes.YLim = [1 640];
            app.UIAxes.YDir = 'reverse';   % image-style coordinates
            axis(app.UIAxes, 'image');
            app.liveViewTimer.UserData.picture = picture;
            app.liveViewTimer.UserData.picture = picture;


            %Create timer for keeping track of elapsed recording time
            app.recordingStopwatch = timer("BusyMode","drop","ExecutionMode",...
                "fixedRate","Period",1,"Name","recordingStopwatch",...
                TimerFcn=@app.updateRecordingTime);

            %Populate the dictionary to map shutter speed display items to
            %actual values used in the back end.
            app.TvDisplayDict = dictionary(app.tvSeconds, app.tvStrings);
        end

        % Close request function: UIFigure
        function cameraAppCloseRequest(app, event)
            if isvalid(app.mainNcaApp)
                app.mainNcaApp.cameraButton.Enable = "on";
            end

            % Update state of camera
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end


            %Check for active recording
            if app.cameraState.recordingActive
                % If camera is recording on close, stop the movie and save
                % it
                %Stop timer (stop before because stopMovie also does download)
                stop(app.recordingStopwatch);

                % Stop movie recording
                try
                    edsdk_mex('stopMovie');
                catch ME
                    if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                        stop(app.liveViewTimer);
                        app.resetUItoInitState();
                        uialert(app.UIFigure,ME.identifier,ME.message);
                        return;
                        % Stop timers
                        % reset to initial state
                    else
                        rethrow(ME)
                    end
                end
                % edsdk_mex('stopMovie');

                % Update lamp
                app.recordingLamp.Enable = "off";
                app.recordingTimeLabel.Text = "00:00:00";

                %Update buttons
                app.StartMovieOnButtonPressDropDown.Enable = "on";
                app.StartMovieButton.Enable = "on";
                app.EndMovieButton.Enable = "off";

                %Delete timer
                delete(app.recordingStopwatch);
            end

            % Update camera state
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            %Check for active file download
            if app.cameraState.downloadingActive
                % issue message that file is still downloading
                msgbox("Files still downloading. Application will close when complete.");

                %if app is downloading, create a timer to refresh state
                %until download is finished
                downloadTimer = timer("ExecutionMode","fixedRate","Period",1,"TimerFcn",...
                    @app.waitForDownloadCallback,Name="downloadTimer");

                %Start the timer
                start(downloadTimer);

                %Wait until the timer is stopped
                wait(downloadTimer);

                % Once it finishes, delete the timer object
                delete(downloadTimer);
            end

            % Update camera state
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            %Check if Live View is active
            if app.cameraState.liveViewActive
                %Stop Live view if active
                % Disable button on press
                app.StopLiveViewButton.Enable = "off";

                % Stop getting frames
                stop(app.liveViewTimer);

                % Stop Live View mode
                % edsdk_mex('stopLiveView');
                try
                    edsdk_mex('stopLiveView');
                catch ME
                    if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                        stop(app.liveViewTimer);
                        app.resetUItoInitState();
                        uialert(app.UIFigure,ME.identifier,ME.message);
                        return;
                        % Stop timers
                        % reset to initial state
                    else
                        rethrow(ME)
                    end
                end

                % Enable/Disable other buttons
                app.StartLiveViewButton.Enable = "on";
                app.StartMovieButton.Enable = "off";

                %Delete the live view timer
                delete(app.liveViewTimer);
            end

            % Update camera state
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            %Check for initialized SDK
            if app.cameraState.isSDKInitialized
                %Terminate Session if initialized
                edsdk_mex('terminate');
                try
                    app.cameraState = edsdk_mex('getState');
                catch ME
                    if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                        stop(app.liveViewTimer);
                        app.resetUItoInitState();
                        uialert(app.UIFigure,ME.identifier,ME.message);
                        return;
                        % Stop timers
                        % reset to initial state
                    else
                        rethrow(ME)
                    end
                end
                app.TerminateButton.Enable = 'off';
                app.InitializeButton.Enable = 'on';
            end


            % Delete the camera app
            delete(app)

        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)

            % Disable button from repeated presses
            app.InitializeButton.Enable = "off";

            % initialize EDSDK
            try
                edsdk_mex('init');
            catch ME
                if contains(ME.message, 'Error code: 97')
                    msgbox(["EDS_ERR_INVALID_HANDLE"; "Check camera power."],"Error","error");
                    app.InitializeButton.Enable = "on";
                    return;
                else
                    rethrow(ME)
                end
            end

            % Enable the other buttons
            app.TerminateButton.Enable = "on";
            app.TakePhotoButton.Enable = "on";
            app.StartLiveViewButton.Enable = "on";
            app.MovieModeButton.Enable = "on";

            app.ISOSpeedDropDown.Enable = "on";
            app.ApertureDropDown.Enable = "on";
            app.ShutterSpeedDropDown.Enable = "on";

            % Get the camera state
            app.cameraState = edsdk_mex('getState');

            % Set ISO
            app.ISOSpeedDropDown.ItemsData = app.cameraState.ISOSpeeds;
            app.ISOSpeedDropDown.Items = string(app.cameraState.ISOSpeeds);
            app.ISOSpeedDropDown.Items(app.cameraState.ISOSpeeds == -1) = {'AUTO'};
            app.ISOSpeedDropDown.Value = app.cameraState.currentISO;

            % Set Aperture
            app.ApertureDropDown.ItemsData = app.cameraState.apertureFNumbers;
            app.ApertureDropDown.Items = "F"+app.cameraState.apertureFNumbers;
            app.ApertureDropDown.Value = app.cameraState.currentAperture;

            %Set shutter speed
            app.ShutterSpeedDropDown.ItemsData = app.cameraState.shutterSpeeds;
            app.ShutterSpeedDropDown.Items = app.TvDisplayDict(app.cameraState.shutterSpeeds);
            app.ShutterSpeedDropDown.Value = app.cameraState.currentShutterSpeed;

            % Set proper state of moviemode button
            movieModeOn = app.cameraState.movieModeActive;

            if movieModeOn
                %edsdk_mex('setMovieMode',true);
                % Run movie mode command b/c if in movie mode using
                % physical camera switch, the saveTo property may not be
                % set
                try
                    edsdk_mex('setMovieMode',true);
                catch ME
                    if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                        stop(app.liveViewTimer);
                        app.resetUItoInitState();
                        uialert(app.UIFigure,ME.identifier,ME.message);
                        return;
                        % Stop timers
                        % reset to initial state
                    else
                        rethrow(ME)
                    end
                end
                app.MovieModeButton.BackgroundColor = [0.85,0.99,0.66];              
                
                % If live view is also on, allow for movies to be taken.
                if app.cameraState.liveViewActive
                    app.StartMovieButton.Enable = "on";
                    app.StartMovieOnButtonPressDropDown.Enable = "on";
                end
            else
                %edsdk_mex('setMovieMode',false);
                try
                    edsdk_mex('setMovieMode',false);
                catch ME
                    if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                        stop(app.liveViewTimer);
                        app.resetUItoInitState();
                        uialert(app.UIFigure,ME.identifier,ME.message);
                        return;
                        % Stop timers
                        % reset to initial state
                    else
                        rethrow(ME)
                    end
                end                
                app.MovieModeButton.BackgroundColor = [1.00,0.61,0.61];
                app.StartMovieButton.Enable = "off";
                app.StartMovieOnButtonPressDropDown.Enable = "off";
                app.EndMovieButton.Enable = "off";
            end

        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)
            stop(app.liveViewTimer);

            try
                edsdk_mex('terminate');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end
            
            % Disable button to prevent repeated presses
            app.TerminateButton.Enable = "off";

            % Disable all other buttons except get state, enable initialize
            app.InitializeButton.Enable = "on";
            app.TakePhotoButton.Enable = "off";
            app.StartLiveViewButton.Enable  = "off";
            app.StopLiveViewButton.Enable = "off";
            app.MovieModeButton.Enable = "off";

            app.StartMovieButton.Enable = "off";
            app.StopLiveViewButton.Enable = "off";
            app.StartMovieOnButtonPressDropDown.Enable = 'off';

            app.ISOSpeedDropDown.Enable = "off";
            app.ApertureDropDown.Enable = "off";
            app.ShutterSpeedDropDown.Enable = "off";

            app.DownloadMovieButton.Enable = "off";

        end

        % Button pushed function: TakePhotoButton
        function TakePhotoButtonPushed(app, event)
            % edsdk_mex('takePhoto');
            try
                edsdk_mex('takePhoto');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end
        end

        % Button pushed function: StartLiveViewButton
        function StartLiveViewButtonPushed(app, event)
            % Disable button on press
            app.StartLiveViewButton.Enable = "off";

            %Start Live View mode
            % edsdk_mex('startLiveView');
            try
                edsdk_mex('startLiveView');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            % Enable the other buttons
            app.StopLiveViewButton.Enable = "on";

            app.cameraState = edsdk_mex('getState');

            % if movie mode is active enable movie shooting
            if app.cameraState.movieModeActive
                app.StartMovieButton.Enable = "on";
                app.StartMovieOnButtonPressDropDown.Enable = "on";
            end

            %Start getting frames
            start(app.liveViewTimer)

        end

        % Button pushed function: StopLiveViewButton
        function StopLiveViewButtonPushed(app, event)

            app.cameraState = edsdk_mex('getState');

            if app.cameraState.recordingActive
                uialert(app.UIFigure, ...
                    "Stop movie recording before stopping live view.", ...
                    "Recording active");
                return;
            end
            % Disable button on press
            app.StopLiveViewButton.Enable = "off";

            % Stop getting frames
            stop(app.liveViewTimer);

            % Stop Live View mode
            % edsdk_mex('stopLiveView');
            try
                edsdk_mex('stopLiveView');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            % Enable/Disable other buttons
            app.StartLiveViewButton.Enable = "on";
            app.StartMovieButton.Enable = "off";
            app.StartMovieOnButtonPressDropDown.Enable = "off";

        end

        % Button pushed function: StartMovieButton
        function StartMovieButtonPushed(app, event)
            % tic
            %Start timer to keep track of recording time
            % app.recordingStopwatch.UserData.tStart = tic;
            % edsdk_mex('startMovie'); % comment for deugging video
            try
                edsdk_mex('startMovie'); % comment for deugging video
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    reutrn;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end
            % toc

            app.recordingLamp.Enable = "on";

            % Start timer that updates elapsed recording time
            start(app.recordingStopwatch)
            % toc

            app.StartMovieOnButtonPressDropDown.Enable = "off";
            app.StartMovieButton.Enable = "off";
            app.EndMovieButton.Enable = "on";
        end

        % Button pushed function: EndMovieButton
        function EndMovieButtonPushed(app, event)
            %Stop timer (stop before because stopMovie also does download)
            stop(app.recordingStopwatch);

            % Stop movie recording
            % edsdk_mex('stopMovie'); %comment for debugging video
            try
                edsdk_mex('stopMovie'); %comment for debugging video
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            % Update lamp
            app.recordingLamp.Enable = "off";
            app.recordingTimeLabel.Text = "00:00:00";

            %Update buttons
            app.StartMovieOnButtonPressDropDown.Enable = "on";
            app.StartMovieButton.Enable = "on";
            app.EndMovieButton.Enable = "off";

            % Enable download movie button
            app.DownloadMovieButton.Enable = "on";
        end

        % Callback function
        function StartMovieOnButtonPressDropDownValueChanged(app, event)
            value = app.StartMovieOnButtonPressDropDown.Value;

            if (value ~= "Start Movie")
                app.StartMovieButton.Enable = "off";
            elseif (value == "Start Movie")
                app.StartMovieButton.Enable = "on";
            end

        end

        % Value changed function: ISOSpeedDropDown
        function ISOSpeedDropDownValueChanged(app, event)
            value = app.ISOSpeedDropDown.Value;

            % edsdk_mex('setISO',value);
            try
                edsdk_mex('setISO',value);
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end
        end

        % Value changed function: ApertureDropDown
        function ApertureDropDownValueChanged(app, event)
            value = app.ApertureDropDown.Value;

            % edsdk_mex('setAv',value);
            try
                edsdk_mex('setAv',value);
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end
        end

        % Button pushed function: DownloadMovieButton
        function DownloadMovieButtonPushed(app, event)

            % edsdk_mex('downloadMovie');
            try
                edsdk_mex('downloadMovie');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            app.DownloadMovieButton.Enable = "off";
        end

        % Button pushed function: GetStateButton
        function GetStateButtonPushed(app, event)

            % app.cameraState = edsdk_mex('getState');
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            if app.cameraState.pendingMovieDownload
                app.DownloadMovieButton.Enable = "on";
            end
        end

        % Value changed function: LiveViewFramerateEditField
        function LiveViewFramerateEditFieldValueChanged(app, event)
            framerate = app.LiveViewFramerateEditField.Value;

            % If timer is runnning, live view is currently active. Stop the
            % timers execution, change the period, and restart it
            if (app.liveViewTimer.Running == "on")
                stop(app.liveViewTimer)
                %Round period to nearest milisecond (timer cannot hold sub
                %milisecond precision)
                period = round(1/framerate,3);

                app.liveViewTimer.Period = period;

                start(app.liveViewTimer); %restart time with new period
            else
                %Round period to nearest milisecond (timer cannot hold sub
                %milisecond precision)
                period = round(1/framerate,3);

                app.liveViewTimer.Period = period;
            end
        end

        % Drop down opening function: ISOSpeedDropDown
        function ISOSpeedDropDownOpening(app, event)
            % Get the camera state

            % app.cameraState = edsdk_mex('getState');
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            % Set ISO
            app.ISOSpeedDropDown.ItemsData = app.cameraState.ISOSpeeds;
            app.ISOSpeedDropDown.Items = string(app.cameraState.ISOSpeeds);
            app.ISOSpeedDropDown.Items(app.cameraState.ISOSpeeds == -1) = {'AUTO'};
            app.ISOSpeedDropDown.Value = app.cameraState.currentISO;
        end

        % Drop down opening function: ApertureDropDown
        function ApertureDropDownOpening(app, event)
            % Get the camera state
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            % Set Aperture
            app.ApertureDropDown.ItemsData = app.cameraState.apertureFNumbers;
            app.ApertureDropDown.Items = "F"+app.cameraState.apertureFNumbers;
            app.ApertureDropDown.Value = app.cameraState.currentAperture;
        end

        % Value changed function: ShutterSpeedDropDown
        function ShutterSpeedDropDownValueChanged(app, event)
            value = app.ShutterSpeedDropDown.Value;

            % edsdk_mex('setTv',value);
            try
                edsdk_mex('setTv',value);
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end
        end

        % Drop down opening function: ShutterSpeedDropDown
        function ShutterSpeedDropDownOpening(app, event)
            % Get the camera state
            try
                app.cameraState = edsdk_mex('getState');
            catch ME
                if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                    stop(app.liveViewTimer);
                    app.resetUItoInitState();
                    uialert(app.UIFigure,ME.identifier,ME.message);
                    return;
                    % Stop timers
                    % reset to initial state
                else
                    rethrow(ME)
                end
            end

            %Set shutter speed
            app.ShutterSpeedDropDown.ItemsData = app.cameraState.shutterSpeeds;
            app.ShutterSpeedDropDown.Items = app.TvDisplayDict(app.cameraState.shutterSpeeds);
            app.ShutterSpeedDropDown.Value = app.cameraState.currentShutterSpeed;
        end

        % Value changed function: MovieModeButton
        function MovieModeButtonValueChanged(app, event)
            value = app.MovieModeButton.Value;

            if value               
                try
                    edsdk_mex('setMovieMode',true);
                catch ME
                    if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                        stop(app.liveViewTimer);
                        app.resetUItoInitState();
                        uialert(app.UIFigure,ME.identifier,ME.message);
                        return;
                        % Stop timers
                        % reset to initial state
                    else
                        rethrow(ME)
                    end
                end
                app.MovieModeButton.BackgroundColor = [0.85,0.99,0.66];
                
                % Get the camera state
                try
                    app.cameraState = edsdk_mex('getState');
                catch ME
                    if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                        stop(app.liveViewTimer);
                        app.resetUItoInitState();
                        uialert(app.UIFigure,ME.identifier,ME.message);
                        return;
                        % Stop timers
                        % reset to initial state
                    else
                        rethrow(ME)
                    end
                end
                
                % If live view is on, allow for movies to be taken.
                if app.cameraState.liveViewActive
                    app.StartMovieButton.Enable = "on";
                    app.StartMovieOnButtonPressDropDown.Enable = "on";
                end
            else
                try
                    edsdk_mex('setMovieMode',false);
                catch ME
                    if strcmp(ME.identifier, "edsdk_mex_c:CameraShutdown")
                        stop(app.liveViewTimer);
                        app.resetUItoInitState();
                        uialert(app.UIFigure,ME.identifier,ME.message);
                        return;
                        % Stop timers
                        % reset to initial state
                    else
                        rethrow(ME)
                    end
                end
                app.MovieModeButton.BackgroundColor = [1.00,0.61,0.61];
                app.StartMovieButton.Enable = "off";
                app.StartMovieOnButtonPressDropDown.Enable = "off";
                app.EndMovieButton.Enable = "off";
            end

            % Check for settings changes
            % Get the camera state
            app.cameraState = edsdk_mex('getState');

            % Set ISO
            app.ISOSpeedDropDown.ItemsData = app.cameraState.ISOSpeeds;
            app.ISOSpeedDropDown.Items = string(app.cameraState.ISOSpeeds);
            app.ISOSpeedDropDown.Items(app.cameraState.ISOSpeeds == -1) = {'AUTO'};
            app.ISOSpeedDropDown.Value = app.cameraState.currentISO;

            % Set Aperture
            app.ApertureDropDown.ItemsData = app.cameraState.apertureFNumbers;
            app.ApertureDropDown.Items = "F"+app.cameraState.apertureFNumbers;
            app.ApertureDropDown.Value = app.cameraState.currentAperture;

            %Set shutter speed
            app.ShutterSpeedDropDown.ItemsData = app.cameraState.shutterSpeeds;
            app.ShutterSpeedDropDown.Items = app.TvDisplayDict(app.cameraState.shutterSpeeds);
            app.ShutterSpeedDropDown.Value = app.cameraState.currentShutterSpeed;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @cameraAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.recordingLamp = uilamp(app.UIFigure);
            app.recordingLamp.Enable = 'off';
            app.recordingTimeLabel = uilabel(app.UIFigure);
            app.recordingTimeLabel.Text = '00:00:00';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CameraControlTab = uitab(app.TabGroup);
            app.CameraControlTab.Title = 'Camera Control';
            app.InitializeButton = uibutton(app.CameraControlTab, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Tooltip = {'Initializes the Canon API and connects to the first available camera.'};
            app.InitializeButton.Text = 'Initialize';
            app.TerminateButton = uibutton(app.CameraControlTab, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Enable = 'off';
            app.TerminateButton.Tooltip = {'Terminates the Canon API and releases resources.'};
            app.TerminateButton.Text = 'Terminate';
            app.TakePhotoButton = uibutton(app.CameraControlTab, 'push');
            app.TakePhotoButton.ButtonPushedFcn = createCallbackFcn(app, @TakePhotoButtonPushed, true);
            app.TakePhotoButton.Enable = 'off';
            app.TakePhotoButton.Tooltip = {'Commands the camera to take a photo and waits for the event. Event is handled and image is downloaded to the working directory.'};
            app.TakePhotoButton.Text = 'Take Photo';
            app.StartLiveViewButton = uibutton(app.CameraControlTab, 'push');
            app.StartLiveViewButton.ButtonPushedFcn = createCallbackFcn(app, @StartLiveViewButtonPushed, true);
            app.StartLiveViewButton.Enable = 'off';
            app.StartLiveViewButton.Tooltip = {'Starts the live view mode on the camera and prepares to receive live view images.'};
            app.StartLiveViewButton.Text = 'Start Live View';
            app.StopLiveViewButton = uibutton(app.CameraControlTab, 'push');
            app.StopLiveViewButton.ButtonPushedFcn = createCallbackFcn(app, @StopLiveViewButtonPushed, true);
            app.StopLiveViewButton.Enable = 'off';
            app.StopLiveViewButton.Tooltip = {'Stops the live view mode on the camera.'};
            app.StopLiveViewButton.Text = 'Stop Live View';
            app.StartMovieButton = uibutton(app.CameraControlTab, 'push');
            app.StartMovieButton.ButtonPushedFcn = createCallbackFcn(app, @StartMovieButtonPushed, true);
            app.StartMovieButton.Enable = 'off';
            app.StartMovieButton.Tooltip = {'Starts movie recording on the camera. Switches the save location'; '*              to the camera (required for movie recording), enables movie mode'; '*              if necessary, and issues the record-start property.'};
            app.StartMovieButton.Text = 'Start Movie';
            app.EndMovieButton = uibutton(app.CameraControlTab, 'push');
            app.EndMovieButton.ButtonPushedFcn = createCallbackFcn(app, @EndMovieButtonPushed, true);
            app.EndMovieButton.Enable = 'off';
            app.EndMovieButton.Tooltip = {'Stops movie recording and turns off movie mode on the camera. Triggers event and stores movie directory information.'};
            app.EndMovieButton.Text = 'End Movie';
            app.GetStateButton = uibutton(app.CameraControlTab, 'push');
            app.GetStateButton.ButtonPushedFcn = createCallbackFcn(app, @GetStateButtonPushed, true);
            app.GetStateButton.Text = 'Get State';
            app.DownloadMovieButton = uibutton(app.CameraControlTab, 'push');
            app.DownloadMovieButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadMovieButtonPushed, true);
            app.DownloadMovieButton.Enable = 'off';
            app.DownloadMovieButton.Tooltip = {'Downloads most recently recorded movie .MOV file is downloaded to host PC.'};
            app.DownloadMovieButton.Text = 'Download Movie';
            app.StartMovieOnButtonPressDropDownLabel = uilabel(app.CameraControlTab);
            app.StartMovieOnButtonPressDropDownLabel.Enable = 'off';
            app.StartMovieOnButtonPressDropDownLabel.Text = 'Start Movie On Button Press';
            app.StartMovieOnButtonPressDropDown = uidropdown(app.CameraControlTab);
            app.StartMovieOnButtonPressDropDown.Items = {'Start Movie', 'Start (Main app)', 'Rampdown 1 (Main app)', 'Rampdown 2 (Main app)', 'Rampdown O2 (Main app)'};
            app.StartMovieOnButtonPressDropDown.Enable = 'off';
            app.StartMovieOnButtonPressDropDown.Tooltip = {'The selected button will also start the movie recording.'};
            app.StartMovieOnButtonPressDropDown.Value = 'Start Movie';
            app.MovieModeButton = uibutton(app.CameraControlTab, 'state');
            app.MovieModeButton.ValueChangedFcn = createCallbackFcn(app, @MovieModeButtonValueChanged, true);
            app.MovieModeButton.Enable = 'off';
            app.MovieModeButton.Tooltip = {'Switch camera to and from movie mode. Red is in still mode. Green is movie mode.'};
            app.MovieModeButton.Text = 'Movie Mode';
            app.CameraImageSettingsTab = uitab(app.TabGroup);
            app.CameraImageSettingsTab.Title = 'Camera Image Settings';
            app.ApertureDropDownLabel = uilabel(app.CameraImageSettingsTab);
            app.ApertureDropDownLabel.Enable = 'off';
            app.ApertureDropDownLabel.Text = 'Aperture';
            app.ApertureDropDown = uidropdown(app.CameraImageSettingsTab);
            app.ApertureDropDown.Items = {'2.8', '3.2', '3.5', '4.0', '4.5', '5.0', '5.6', '6.3', '7.1', '8.0', '9.0', '10.0', '11.0', '13.0', '14.0', '16.0', '18.0', '20.0', '22.0', '25.0', '29.0', '32.0'};
            app.ApertureDropDown.ItemsData = [2.8 3.2 3.5 4 4.5 5 5.6 6.3 7.1 8 9 10 11 13 14 16 18 20 22 25 29 32];
            app.ApertureDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ApertureDropDownOpening, true);
            app.ApertureDropDown.ValueChangedFcn = createCallbackFcn(app, @ApertureDropDownValueChanged, true);
            app.ApertureDropDown.Enable = 'off';
            app.ApertureDropDown.Value = 2.8;
            app.ISOSpeedDropDownLabel = uilabel(app.CameraImageSettingsTab);
            app.ISOSpeedDropDownLabel.Enable = 'off';
            app.ISOSpeedDropDownLabel.Text = 'ISO Speed';
            app.ISOSpeedDropDown = uidropdown(app.CameraImageSettingsTab);
            app.ISOSpeedDropDown.Items = {'AUTO', '100', '125', '160', '200', '320', '400', '500', '640', '800', '1000', '1250', '1600', '2000', '2500', '3200', '4000', '5000', '6400', '8000', '10000', '12800', '16000', '20000', '25600'};
            app.ISOSpeedDropDown.ItemsData = [-1 100 125 160 200 320 400 500 640 800 1000 1250 1600 2000 2500 3200 4000 5000 6400 8000 10000 12800 16000 20000 25600];
            app.ISOSpeedDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ISOSpeedDropDownOpening, true);
            app.ISOSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @ISOSpeedDropDownValueChanged, true);
            app.ISOSpeedDropDown.Enable = 'off';
            app.ISOSpeedDropDown.Value = -1;
            app.LiveViewFramerateEditFieldLabel = uilabel(app.CameraImageSettingsTab);
            app.LiveViewFramerateEditFieldLabel.Text = 'Live View Framerate';
            app.LiveViewFramerateEditField = uieditfield(app.CameraImageSettingsTab, 'numeric');
            app.LiveViewFramerateEditField.Limits = [1 30];
            app.LiveViewFramerateEditField.RoundFractionalValues = 'on';
            app.LiveViewFramerateEditField.ValueChangedFcn = createCallbackFcn(app, @LiveViewFramerateEditFieldValueChanged, true);
            app.LiveViewFramerateEditField.Tooltip = {'Sets the frame rate of the live view window. Does not effect the frame rate of video recording.'};
            app.LiveViewFramerateEditField.Value = 20;
            app.ShutterSpeedDropDownLabel = uilabel(app.CameraImageSettingsTab);
            app.ShutterSpeedDropDownLabel.Enable = 'off';
            app.ShutterSpeedDropDownLabel.Text = 'Shutter Speed';
            app.ShutterSpeedDropDown = uidropdown(app.CameraImageSettingsTab);
            app.ShutterSpeedDropDown.Items = {'1/30'};
            app.ShutterSpeedDropDown.ItemsData = 0.0333333333333333;
            app.ShutterSpeedDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ShutterSpeedDropDownOpening, true);
            app.ShutterSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @ShutterSpeedDropDownValueChanged, true);
            app.ShutterSpeedDropDown.Enable = 'off';
            app.ShutterSpeedDropDown.Value = 0.0333333333333333;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camera_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end