classdef NarrowChannelApp_David_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NarrowChannel                   matlab.ui.Figure
        Lamp                            matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        ExportDataButton                matlab.ui.control.Button
        RecordDataButton                matlab.ui.control.Button
        DryingVelocitycmsSpinner        matlab.ui.control.Spinner
        DryingVelocitycmsSpinnerLabel   matlab.ui.control.Label
        ActualTotalVelocityEditField    matlab.ui.control.NumericEditField
        ActualTotalVelocityEditFieldLabel  matlab.ui.control.Label
        TotalVelSetpointEditField       matlab.ui.control.NumericEditField
        TotalVelSetpointEditFieldLabel  matlab.ui.control.Label
        ActualTotalFlowEditField        matlab.ui.control.NumericEditField
        ActualTotalFlowEditFieldLabel   matlab.ui.control.Label
        TotalFlowSetpointEditField      matlab.ui.control.NumericEditField
        TotalFlowSetpointEditFieldLabel  matlab.ui.control.Label
        TasksPanel                      matlab.ui.container.Panel
        SLMsEditField_15                matlab.ui.control.NumericEditField
        SLMsEditField_15Label           matlab.ui.control.Label
        SLMEditField_18                 matlab.ui.control.NumericEditField
        SLMEditField_18Label            matlab.ui.control.Label
        O2percentrampdownratepersecondSpinner  matlab.ui.control.Spinner
        O2percentrampdownratepersecondSpinnerLabel  matlab.ui.control.Label
        O2percentplateauVolumeSpinner   matlab.ui.control.Spinner
        O2percentplateauVolumeSpinnerLabel  matlab.ui.control.Label
        OxygenRampTimesecEditField      matlab.ui.control.NumericEditField
        OxygenRampTimesecEditFieldLabel  matlab.ui.control.Label
        RampUp1secEditField             matlab.ui.control.NumericEditField
        RampUp1secEditFieldLabel        matlab.ui.control.Label
        RampDown2secEditField           matlab.ui.control.NumericEditField
        RampDown2secEditFieldLabel      matlab.ui.control.Label
        RampDown1secEditField           matlab.ui.control.NumericEditField
        RampDown1secEditFieldLabel      matlab.ui.control.Label
        PhaseDurationsLabel             matlab.ui.control.Label
        RampDownO2Button                matlab.ui.control.Button
        RampUpButton                    matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        RampDown2Button                 matlab.ui.control.Button
        RampDown1Button                 matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        DrySampleButton                 matlab.ui.control.Button
        ReadMFCsButton                  matlab.ui.control.Button
        UnitPanel                       matlab.ui.container.Panel
        AirLabel                        matlab.ui.control.Label
        E0055Label                      matlab.ui.control.Label
        O2Label_2                       matlab.ui.control.Label
        N2Label_2                       matlab.ui.control.Label
        D0011Label                      matlab.ui.control.Label
        C0011Label                      matlab.ui.control.Label
        O2Label                         matlab.ui.control.Label
        B150Label                       matlab.ui.control.Label
        N2Label                         matlab.ui.control.Label
        A150Label                       matlab.ui.control.Label
        ActualsPanel                    matlab.ui.container.Panel
        EditField_20                    matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        VelocityLabel                   matlab.ui.control.Label
        SLMLabel_2                      matlab.ui.control.Label
        SettingsPanel                   matlab.ui.container.Panel
        FullScaleLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        SLMLabel                        matlab.ui.control.Label
        ReferencesPanel                 matlab.ui.container.Panel
        CrossSectionalAreacm2EditField  matlab.ui.control.NumericEditField
        CrossSectionalAreacm2EditFieldLabel  matlab.ui.control.Label
        KEditField_2                    matlab.ui.control.NumericEditField
        KEditField_2Label               matlab.ui.control.Label
        psiaEditField_2                 matlab.ui.control.NumericEditField
        psiaEditField_2Label            matlab.ui.control.Label
        FEditField_2                    matlab.ui.control.NumericEditField
        FEditField_2Label               matlab.ui.control.Label
        atmEditField_2                  matlab.ui.control.NumericEditField
        atmEditField_2Label             matlab.ui.control.Label
        DensityCorrectionFactorEditField  matlab.ui.control.NumericEditField
        DensityCorrectionFactorEditFieldLabel  matlab.ui.control.Label
        ChannelWidthcmSpinner           matlab.ui.control.Spinner
        ChannelWidthcmSpinnerLabel      matlab.ui.control.Label
        ReferenceTemperatureCSpinner    matlab.ui.control.Spinner
        ReferenceTemperatureCSpinnerLabel  matlab.ui.control.Label
        ReferencePressurekPaSpinner     matlab.ui.control.Spinner
        ReferencePressurekPaSpinnerLabel  matlab.ui.control.Label
        SetupPanel                      matlab.ui.container.Panel
        SensorPressurepsiaEditField     matlab.ui.control.NumericEditField
        SensorPressurepsiaEditFieldLabel  matlab.ui.control.Label
        psiatokpaconverterLabel         matlab.ui.control.Label
        kpaEditField                    matlab.ui.control.NumericEditField
        kpaEditFieldLabel               matlab.ui.control.Label
        psiaSpinner                     matlab.ui.control.Spinner
        psiaSpinnerLabel                matlab.ui.control.Label
        cmEditField                     matlab.ui.control.NumericEditField
        cmEditFieldLabel                matlab.ui.control.Label
        ChannelTemperatureCSpinner      matlab.ui.control.Spinner
        ChannelTemperatureCSpinnerLabel  matlab.ui.control.Label
        DesiredOxygenCompositionOxygeninMixtureSpinner  matlab.ui.control.Spinner
        DesiredOxygenCompositionOxygeninMixtureSpinnerLabel  matlab.ui.control.Label
        psiaEditField                   matlab.ui.control.NumericEditField
        psiaEditFieldLabel              matlab.ui.control.Label
        Panel_15                        matlab.ui.container.Panel
        FinalVelocity1TotalcmsSpinner   matlab.ui.control.Spinner
        FinalVelocity1TotalcmsSpinnerLabel  matlab.ui.control.Label
        RampUpRate1TotalcmssSpinner     matlab.ui.control.Spinner
        RampUpRate1TotalcmssSpinnerLabel  matlab.ui.control.Label
        SLMEditField_17                 matlab.ui.control.NumericEditField
        SLMEditField_17Label            matlab.ui.control.Label
        SLMsEditField_14                matlab.ui.control.NumericEditField
        SLMsEditField_14Label           matlab.ui.control.Label
        Panel_14                        matlab.ui.container.Panel
        PlateauVelocity2TotalcmsSpinner  matlab.ui.control.Spinner
        PlateauVelocity2TotalcmsSpinnerLabel  matlab.ui.control.Label
        RampDownRate2TotalcmssSpinner   matlab.ui.control.Spinner
        RampDownRate2TotalcmssSpinnerLabel  matlab.ui.control.Label
        SLMEditField_16                 matlab.ui.control.NumericEditField
        SLMEditField_16Label            matlab.ui.control.Label
        SLMsEditField_13                matlab.ui.control.NumericEditField
        SLMsEditField_13Label           matlab.ui.control.Label
        Panel_13                        matlab.ui.container.Panel
        SLMEditField_15                 matlab.ui.control.NumericEditField
        SLMEditField_15Label            matlab.ui.control.Label
        SLMsEditField_12                matlab.ui.control.NumericEditField
        SLMsEditField_12Label           matlab.ui.control.Label
        RampUpRateBcmssEditField        matlab.ui.control.NumericEditField
        RampUpRateBcmssEditFieldLabel   matlab.ui.control.Label
        FinalVelocityBcmsEditField      matlab.ui.control.NumericEditField
        FinalVelocityBcmsEditFieldLabel  matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        SLMEditField_14                 matlab.ui.control.NumericEditField
        SLMEditField_14Label            matlab.ui.control.Label
        SLMsEditField_11                matlab.ui.control.NumericEditField
        SLMsEditField_11Label           matlab.ui.control.Label
        RampDownRate2BcmssEditField     matlab.ui.control.NumericEditField
        RampDownRate2BcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocity2BcmsEditField   matlab.ui.control.NumericEditField
        PlateauVelocity2BcmsEditFieldLabel  matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        SLMEditField_13                 matlab.ui.control.NumericEditField
        SLMEditField_13Label            matlab.ui.control.Label
        SLMsEditField_10                matlab.ui.control.NumericEditField
        SLMsEditField_10Label           matlab.ui.control.Label
        RampDownRate1BcmssEditField     matlab.ui.control.NumericEditField
        RampDownRate1BcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocity1BcmsEditField   matlab.ui.control.NumericEditField
        PlateauVelocity1BcmsEditFieldLabel  matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        SLMEditField_12                 matlab.ui.control.NumericEditField
        SLMEditField_12Label            matlab.ui.control.Label
        InitialVelocityBcmsEditField    matlab.ui.control.NumericEditField
        InitialVelocityBcmsEditFieldLabel  matlab.ui.control.Label
        SourceDGasDropDown              matlab.ui.control.DropDown
        SourceDGasDropDownLabel         matlab.ui.control.Label
        SourceBGasDropDown              matlab.ui.control.DropDown
        SourceBGasDropDownLabel         matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        PlateauVelocity1TotalcmsSpinner  matlab.ui.control.Spinner
        PlateauVelocity1TotalcmsSpinnerLabel  matlab.ui.control.Label
        RampDownRate1TotalcmssSpinner   matlab.ui.control.Spinner
        RampDownRate1TotalcmssSpinnerLabel  matlab.ui.control.Label
        SLMEditField_9                  matlab.ui.control.NumericEditField
        SLMEditField_9Label             matlab.ui.control.Label
        SLMsEditField_7                 matlab.ui.control.NumericEditField
        SLMsEditField_7Label            matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        InitialVelocityTotalcmsSpinner  matlab.ui.control.Spinner
        InitialVelocityTotalcmsSpinnerLabel  matlab.ui.control.Label
        TotalFlowSettingsLabel          matlab.ui.control.Label
        SLMEditField_8                  matlab.ui.control.NumericEditField
        SLMEditField_8Label             matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        SLMEditField_7                  matlab.ui.control.NumericEditField
        SLMEditField_7Label             matlab.ui.control.Label
        SLMsEditField_6                 matlab.ui.control.NumericEditField
        SLMsEditField_6Label            matlab.ui.control.Label
        RampDownRateAcmssEditField      matlab.ui.control.NumericEditField
        RampDownRateAcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocityAcmsEditField    matlab.ui.control.NumericEditField
        PlateauVelocityAcmsEditFieldLabel  matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        SLMEditField_6                  matlab.ui.control.NumericEditField
        SLMEditField_6Label             matlab.ui.control.Label
        SLMsEditField_5                 matlab.ui.control.NumericEditField
        SLMsEditField_5Label            matlab.ui.control.Label
        RampDownRate2AcmssEditField     matlab.ui.control.NumericEditField
        RampDownRate2AcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocity2AcmsEditField   matlab.ui.control.NumericEditField
        PlateauVelocity2AcmsEditFieldLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        SLMEditField_5                  matlab.ui.control.NumericEditField
        SLMEditField_5Label             matlab.ui.control.Label
        SLMsEditField_4                 matlab.ui.control.NumericEditField
        SLMsEditField_4Label            matlab.ui.control.Label
        RampDownRate1AcmssEditField     matlab.ui.control.NumericEditField
        RampDownRate1AcmssEditFieldLabel  matlab.ui.control.Label
        PlateauVelocity1AcmsEditField   matlab.ui.control.NumericEditField
        PlateauVelocity1AcmsEditFieldLabel  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SLMEditField                    matlab.ui.control.NumericEditField
        SLMEditFieldLabel               matlab.ui.control.Label
        InitialVelocityAcmsEditField    matlab.ui.control.NumericEditField
        InitialVelocityAcmsEditFieldLabel  matlab.ui.control.Label
        SourceCGasDropDown              matlab.ui.control.DropDown
        SourceCGasDropDownLabel         matlab.ui.control.Label
        SourceAGasDropDown              matlab.ui.control.DropDown
        SourceAGasDropDownLabel         matlab.ui.control.Label
        GapHeightmmSpinner              matlab.ui.control.Spinner
        GapHeightmmSpinnerLabel         matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        FEditField                      matlab.ui.control.NumericEditField
        FEditFieldLabel                 matlab.ui.control.Label
        atmEditField                    matlab.ui.control.NumericEditField
        atmEditFieldLabel               matlab.ui.control.Label
        ChannelPressurekPaSpinner       matlab.ui.control.Spinner
        ChannelPressurekPaSpinnerLabel  matlab.ui.control.Label
        NarrowChannelTestRigPanel       matlab.ui.container.Panel
    end


    properties (Access = private, SetObservable)
        % Initializing variables within the gui
        gChPress; gRefPress; ChTempK; gRefTemp;
        gOxyComp; gIVT; gChannelWidth; gGapHeight;
        gPVT1; gRDRT1;
        gPVT2; gRDRT2; gRURT; gFVT;
        gO2_percent_plateau; gO2_percent_RDR;
        DC; A; Dryvel; QDry;
        gChPressATM;
        gChPressPSIA;
        gChTemp; ChTempF;
        gConverterPsia;
        gRefPressATM; gRefPressPSIA;
        RefTempF; RefTempK
        QT1; QB1; QA1; IVA; IVB; ISA50; ISA1; ISB50; ISB1;
        QRT1; QPT1; PVB1; QPB1; PVA1; QPA1; RDRA1; QRA1; RDRB1; QRB1;
        RD1T;
        O2_vel_plateau; O2_flow_plateau; N2_vel_plateau; N2_flow_plateau; O2_Q_ramp_rate; N2_Q_ramp_rate; duration
        QRT2; QPT2; PVB2; QPB2; PVA2; QPA2; RDRA2; RDRB2; QRA2; QRB2;
        RD2T;
        QRT3; QFT; PFB; QFB; PFA; QFA; RURA; RURB; QRA3; QRB3;
        RUT;
        
        ISA2MFC; ISB2MFC; N2Unit; NonN2Unit; O2Unit; NonO2Unit;
        
        AFlowAct; BFlowAct; CFlowAct; DFlowAct; EFlowAct;
        AFlow; BFlow; CFlow; DFlow; EFlow;
        
        RSA1; RSB1;

        sampleSize; sampleRate;
        pollA; pollB; pollC; pollD;
        
        sensorPressure; pressureDevice; sensorConversion; pressureVector;
        toggleData;
        %aliComm;
    
    end

    methods (Access = public)
        
        function handleIncoming(app, src, ~)
            sensorOut = readline(src);

            if isempty(sensorOut)
                return
            end
            app.sensorPressure = str2double(sensorOut);
        end
        
        function sensorChange(app, ~, ~)
            app.SensorPressurepsiaEditField.Value = app.sensorPressure*0.2897 + 0.5;
            if app.toggleData == true
                app.pressureVector = [app.pressureVector app.SensorPressurepsiaEditField.Value];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %These functions set up the default values when the gui loads up

            app.Lamp.Color = '#DA1884';
            app.pressureVector = [];
            app.toggleData = false;
            app.pressureDevice = serialport('COM4', 9600);
            readline(app.pressureDevice);
            configureTerminator(app.pressureDevice, 'CR/LF');
            configureCallback(app.pressureDevice, "terminator", @app.handleIncoming);
            addlistener(app, 'sensorPressure', 'PostSet', @app.sensorChange);

            %function ChannelPressurekPaSpinnerValueChanged(app, event)
            app.gChPress = app.ChannelPressurekPaSpinner.Value;
            app.gChPressATM = (app.gChPress)/101.32;
            app.atmEditField.Value = app.gChPressATM;
            
            %Pounds per square inch pressure
            app.gChPressPSIA = (app.gChPress)*14.696/101.32;
            app.psiaEditField.Value = app.gChPressPSIA;
            %end
            
            %function ChannelTemperatureCSpinnerValueChanged(app, event)
            app.gChTemp = app.ChannelTemperatureCSpinner.Value;
            app.ChTempK = (app.gChTemp) + 273.15;
            app.KEditField.Value = app.ChTempK;
            app.ChTempF = ((app.ChTempK)-273.15)*(9/5)+32;
            app.FEditField.Value = app.ChTempF;
            %end
            
            %function GapHeightmmSpinnerValueChanged(app, event)
            app.gGapHeight = app.GapHeightmmSpinner.Value;
            app.cmEditField.Value = app.gGapHeight/10;
            %end

            %function psiaSpinnerValueChanged(app, event)
            app.gConverterPsia = app.psiaSpinner.Value;
            app.kpaEditField.Value = app.gConverterPsia*6.89476;
            %end
            
            %function ChannelWidthcmSpinnerValueChanged(app, event)
            app.gChannelWidth = app.ChannelWidthcmSpinner.Value;
            %end
            
            %function CrossSectionalAreacm2EditFieldValueChanged(app, event)
            app.A = app.CrossSectionalAreacm2EditField.Value;
            %end
            
            %function DensityCorrectionFactorEditFieldValueChanged(app, event)
            app.DC = app.DensityCorrectionFactorEditField.Value;
            %end
            
            %function ReferencePressurekPaSpinnerValueChanged(app, event)
            app.gRefPress = app.ReferencePressurekPaSpinner.Value;
            app.gRefPressATM = (app.gRefPress)/101.32;
            app.atmEditField_2.Value = app.gRefPressATM;
            app.gRefPressPSIA = (app.gRefPress)*14.696/101.32;
            app.psiaEditField_2.Value = app.gRefPressPSIA;
            %end
            
            %function ReferenceTemperatureCSpinnerValueChanged(app, event)
            app.gRefTemp = app.ReferenceTemperatureCSpinner.Value;
            app.RefTempK = (app.gRefTemp) + 273.15;
            app.KEditField_2.Value = app.RefTempK;
            app.RefTempF = ((app.RefTempK)-273.15)*(9/5)+32;
            app.FEditField_2.Value = app.RefTempF;
            %end
            
            %function DesiredOxygenCompositionOxygeninMixtureSpinnerValueChanged(app, event)
            app.gOxyComp = app.DesiredOxygenCompositionOxygeninMixtureSpinner.Value;
            %end
            
            %function DryingVelocitycmsSpinnerValueChanged(app, event)
            app.Dryvel = app.DryingVelocitycmsSpinner.Value;
            %end
            
            %function InitialVelocityTotalcmsSpinnerValueChanged(app, event)
            app.gIVT = app.InitialVelocityTotalcmsSpinner.Value;
            %end
            
            %function RampDownRate1TotalcmssSpinnerValueChanged(app, event)
            app.gRDRT1 = app.RampDownRate1TotalcmssSpinner.Value;
            %end
            
            %function PlateauVelocity1TotalcmsSpinnerValueChanged(app, event)
            app.gPVT1 = app.PlateauVelocity1TotalcmsSpinner.Value;
            %end
            app.PVA1 = app.PlateauVelocity1AcmsEditField.Value;
            app.PVB1 = app.PlateauVelocity1BcmsEditField.Value;
            
            %function RampDownRate2TotalcmssSpinnerValueChanged(app, event)
            app.gRDRT2 = app.RampDownRate2TotalcmssSpinner.Value;
            %end
            
            %function PlateauVelocity2TotalcmsSpinnerValueChanged(app, event)
            app.gPVT2 = app.PlateauVelocity2TotalcmsSpinner.Value;
            %end
            app.PVA2 = app.PlateauVelocity2AcmsEditField.Value;
            app.PVB2 = app.PlateauVelocity2BcmsEditField.Value;
            
            %function RampUpRate1TotalcmssSpinnerValueChanged(app, event)
            app.gRURT = app.RampUpRate1TotalcmssSpinner.Value;
            %end
            
            %function FinalVelocity1TotalcmsSpinnerValueChanged(app, event)
            app.gFVT = app.FinalVelocity1TotalcmsSpinner.Value;
            %end
            app.PFA = app.PlateauVelocityAcmsEditField.Value;
            app.PFB = app.FinalVelocityBcmsEditField.Value;
            
            %function O2percentplateauVolumeSpinnerValueChanged(app, event)
            app.gO2_percent_plateau = app.O2percentplateauVolumeSpinner.Value;
            %end
            
            %function O2percentrampdownratepersecondSpinnerValueChanged(app, event)
            app.gO2_percent_RDR = app.O2percentrampdownratepersecondSpinner.Value;
            %end
            
            %app.AFlow = 0;
            %app.BFlow = 0;
            %app.CFlow = 0;
            %app.DFlow = 0;
            %app.EFlow = 0;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %Calculates Density Correction Factor
            app.DC =(app.gChPress/app.gRefPress)*(app.ChTempK/app.RefTempK);
            app.DensityCorrectionFactorEditField.Value = app.DC;
            
            %Calculates Cross Sectional Area
            app.A = (app.gChannelWidth)*(app.gGapHeight / 10);
            app.CrossSectionalAreacm2EditField.Value = app.A;
              
            %QT1
            app.QT1 = app.gIVT * app.A* app.DC * .06;
            app.SLMEditField_8.Value = app.QT1;
            
            %QB1
            app.QB1 = (app.gOxyComp * app.QT1)/100;
            app.SLMEditField_12.Value = app.QB1;
            
            %QA1
            app.QA1 = (app.QT1 - app.QB1);
            app.SLMEditField.Value = app.QA1;
            
            %IVA
            app.IVA = app.QA1/(app.A*.06);
            app.InitialVelocityAcmsEditField.Value = app.IVA;
            
            %IVB
            app.IVB = app.QB1/(app.A*.06);
            app.InitialVelocityBcmsEditField.Value = app.IVB;
            
            %ISA50 
            app.ISA50 = app.QA1*64000/50;
            
            %ISA1
            app.ISA1 = app.QA1*64000/1;
            
            %ISB50
            app.ISB50 = app.QB1*64000/50;
            
            %ISB1
            app.ISB1 = app.QB1*64000/1;
            
            %The rest will be reacting on the ramp buttons------------------------------------------
            %Ramp Down 1 calculations were transferred to the Ramp Down 1 Button---
            
            %%Ramp Down 1 Button------------------------------------------------------------------
            
            if app.gPVT1 >= app.gIVT
                msgbox('Plateau Velocity 1 Total must be lower than Initial Velocity Total');
            else
                %QRT1
                app.QRT1 = app.gRDRT1*app.A*app.DC*6/100;
                app.SLMsEditField_7.Value = app.QRT1;
            
                %PVT1
                app.QPT1 = app.gPVT1*app.A*app.DC*6/100;
                app.SLMEditField_9.Value = app.QPT1;
            
                %PVB1
                app.PVB1 = app.gPVT1*app.gOxyComp/100;
                app.PlateauVelocity1BcmsEditField.Value = app.PVB1;
            
                %QPB1
                app.QPB1 = app.PVB1*app.A*app.DC*6/100;
                app.SLMEditField_13.Value = app.QPB1;
            
                %PVA1
                app.PVA1 = app.gPVT1-app.PVB1;
                app.PlateauVelocity1AcmsEditField.Value = app.PVA1;
            
                %QPA1
                app.QPA1 = app.PVA1*app.A*app.DC*6/100;
                app.SLMEditField_5.Value = app.QPA1;
            
                %RDRA1
                app.RDRA1 = app.gRDRT1*((app.PVA1-app.IVA)/(app.gPVT1-app.gIVT));
                if isnan(app.RDRA1)
                    msgbox('Ramp Down Rate 1-A resulted to NaN');
                else
                    app.RampDownRate1AcmssEditField.Value = app.RDRA1;
                end
            
                %QRA1
                app.QRA1 = app.RDRA1*app.A*app.DC*6/100;
                app.SLMsEditField_4.Value = app.QRA1;
            
                %RDRB1
                app.RDRB1 = app.gRDRT1*((app.PVB1-app.IVB)/(app.gPVT1-app.gIVT));
                if isnan(app.RDRB1)
                    msgbox('Ramp Down Rate 1-B resulted to NaN');
                else
                    app.RampDownRate1BcmssEditField.Value = app.RDRB1;
                end
            
                %QRB1
                app.QRB1 = app.RDRB1*app.A*app.DC*6/100;
                app.SLMsEditField_10.Value = app.QRB1;
            
                %RD1T
                app.RD1T = (app.gPVT1-app.gIVT)/(app.gRDRT1);
                if isnan(app.RD1T)
                    msgbox('Ramp Down 1 resulted to NaN');
                else
                    app.RampDown1secEditField.Value = app.RD1T;
                end
            end
            
            %Thao's code -----------------------------------------------------------------------
            app.O2_vel_plateau = app.gIVT*app.gO2_percent_plateau/100;
            app.O2_flow_plateau = app.QT1*app.gO2_percent_plateau/100;
            app.SLMEditField_18.Value = app.O2_flow_plateau; % SLM for O2
            
            app.N2_vel_plateau = app.gIVT-app.O2_vel_plateau;
            app.N2_flow_plateau = app.QT1-app.O2_flow_plateau;
            
            app.O2_Q_ramp_rate = app.QT1*app.gO2_percent_RDR/100;
            app.SLMsEditField_15.Value = app.O2_Q_ramp_rate; % SLM/s for O2
            app.N2_Q_ramp_rate = -1*app.O2_Q_ramp_rate;
            
            app.duration = (app.gO2_percent_plateau-app.gOxyComp)/app.gO2_percent_RDR;
            if isnan(app.duration)
                msgbox('Oxygen Ramp Time resulted to NaN');
            else
                app.OxygenRampTimesecEditField.Value = app.duration;
            end
            
            %Ramp Down 2 calculations were transferred to the Ramp Down 2 Button
            %Ramp Down 2 Button---------------------------------------------------------------------
            
            if app.gPVT2 > app.gPVT1
                msgbox('Plateau Velocity 2 Total must be lower than Plateau Velocity 1 Total');
            elseif app.gPVT2 == app.gPVT1
                app.RDRA2 = 0;
                app.RDRB2 = 0;
            else
                %QRT2
                app.QRT2 = app.gRDRT2*app.A*app.DC*6/100;
                app.SLMsEditField_13.Value = app.QRT2;
            
                %QPT2
                app.QPT2 = app.gPVT2*app.A*app.DC*6/100;
                app.SLMEditField_16.Value = app.QPT2;
            
                %PVB2
                app.PVB2 = app.gPVT2*app.gOxyComp/100;
                app.PlateauVelocity2BcmsEditField.Value = app.PVB2;
            
                %QPB2
                app.QPB2 = app.PVB2*app.A*app.DC*6/100;
                app.SLMEditField_14.Value = app.QPB2;
            
                %PVA2
                app.PVA2 = app.gPVT2-app.PVB2;
                app.PlateauVelocity2AcmsEditField.Value = app.PVA2;
            
                %QPA2
                app.QPA2 = app.PVA2*app.A*app.DC*6/100;
                app.SLMEditField_6.Value = app.QPA2;
            
                %RDRA2
                app.RDRA2 = app.gRDRT2*((app.PVA2-app.PVA1)/(app.gPVT2-app.gPVT1));
                
                if isnan(app.RDRA2)
                    msgbox('Ramp Down Rate 2-A resulted to NaN');
                else
                    app.RampDownRate2AcmssEditField.Value = app.RDRA2;
                end
            
                %if ~isnan(app.RDRA2)
                    %app.RampDownRate2AcmssEditField.Value = app.RDRA2;
                %end
            
                %QRA2
                app.QRA2 = app.RDRA2*app.A*app.DC*6/100;
                if isnan(app.QRA2)
                    msgbox('Ramp Down Flow Rate 2-A (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_5.Value = app.QRA2;
                end
            
                %RDRB2
                app.RDRB2 = app.gRDRT2*((app.PVB2-app.PVB1)/(app.gPVT2-app.gPVT1));
                if isnan(app.RDRB2)
                    msgbox('Ramp Down Rate 2-B resulted to NaN');
                else
                    app.RampDownRate2BcmssEditField.Value = app.RDRB2;
                end
            
                %QRB2
                app.QRB2 = app.RDRB2*app.A*app.DC*6/100;
                if isnan(app.QRB2)
                    msgbox('Ramp Down Flow Rate 2-B resulted to NaN');
                else
                    app.SLMsEditField_11.Value = app.QRB2;
                end
            
                %RD2T
                app.RD2T =(app.gPVT2-app.gPVT1)/(app.gRDRT2);
                if isnan(app.RD2T)
                    msgbox('Ramp Down 2 resulted to NaN');
                else
                app.RampDown2secEditField.Value = app.RD2T;
                end
            end
          
            %Ramp Up Button-------------------------------------------------------------------
            if app.gFVT < app.gPVT2
                msgbox('Final Plateau Velocity must be higher than Plateau Velocity 2 Total');
            elseif app.gFVT == app.gPVT2
                app.RURA = 0;
                app.RURB = 0;
            else
                %QRT3
                app.QRT3 = app.gRURT*app.A*app.DC*6/100;
                app.SLMsEditField_14.Value = app.QRT3;
            
                %QFT
                app.QFT = app.gFVT*app.A*app.DC*6/100;
                app.SLMEditField_17.Value = app.QFT;
                
                %PFB
                app.PFB = app.gFVT*app.gOxyComp/100;
                app.FinalVelocityBcmsEditField.Value = app.PFB;
                
                %QFB
                app.QFB = app.PFB*app.A*app.DC*6/100;
                app.SLMEditField_15.Value = app.QFB;
                
                %PFA
                app.PFA = app.gFVT-app.PFB;
                app.PlateauVelocityAcmsEditField.Value = app.PFA;
                
                %QFA
                app.QFA = app.PFA*app.A*app.DC*6/100;
                app.SLMEditField_7.Value = app.QFA;
                
                %RURA
                app.RURA = app.gRURT*((app.PFA-app.PVA2)/(app.gFVT-app.gPVT2));
                if isnan(app.RURA)
                    msgbox('Ramp Up Rate A resulted to NaN');
                else
                    app.RampDownRateAcmssEditField.Value = app.RURA;
                end
                
                %QRA3
                app.QRA3 = app.RURA*app.A*app.DC*6/100;
                if isnan(app.QRA3)
                    msgbox('Ramp Up Flow Rate A (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_6.Value = app.QRA3;
                end
                
                %RURB
                app.RURB = app.gRURT*((app.PFB-app.PVA2)/(app.gFVT-app.gPVT2));
                if isnan(app.RURB)
                    msgbox('Ramp Up Rate B resulted to NaN');
                else
                    app.RampUpRateBcmssEditField.Value = app.RURB;
                end
                
                %QRB3
                app.QRB3 = app.RURB*app.A*app.DC*6/100;
                if isnan(app.QRB3)
                    msgbox('Ramp Up Flow Rate B (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_12.Value = app.QRB3;
                end
                
                %RUT
                app.RUT = (app.gFVT-app.gPVT2)/(app.gRURT);
                if isnan(app.RUT)
                    msgbox('Ramp Up 1 resulted to NaN');
                else
                    app.RampUp1secEditField.Value = app.RUT;
                end
            end
        end

        % Value changed function: ChannelPressurekPaSpinner
        function ChannelPressurekPaSpinnerValueChanged(app, event)
            app.gChPress = app.ChannelPressurekPaSpinner.Value;
            
            %Atmospheric Pressure
            app.gChPressATM = (app.gChPress)/101.32;
            app.atmEditField.Value = app.gChPressATM;
            
            %Pounds per square inch pressure
            app.gChPressPSIA = (app.gChPress)*14.696/101.32;
            app.psiaEditField.Value = app.gChPressPSIA;
        end

        % Value changed function: ChannelTemperatureCSpinner
        function ChannelTemperatureCSpinnerValueChanged(app, event)
            app.gChTemp = app.ChannelTemperatureCSpinner.Value;
            
            %Kelvins
            app.ChTempK = (app.gChTemp) + 273.15;
            app.KEditField.Value = app.ChTempK;
            
            %Fahrenheit
            app.ChTempF = ((app.ChTempK)-273.15)*(9/5)+32;
            app.FEditField.Value = app.ChTempF;
        end

        % Value changed function: CrossSectionalAreacm2EditField
        function CrossSectionalAreacm2EditFieldValueChanged(app, event)
            %value = app.CrossSectionalAreacm2EditField.Value;
            
        end

        % Value changed function: DensityCorrectionFactorEditField
        function DensityCorrectionFactorEditFieldValueChanged(app, event)
            %app.DC = app.DensityCorrectionFactorEditField.Value;
            %app.DC =(app.gChPress/app.gRefPress)*(app.ChTempK/app.RefTempK);
            %app.DensityCorrectionFactorEditField.Value = app.DC;
        end

        % Value changed function: 
        % DesiredOxygenCompositionOxygeninMixtureSpinner
        function DesiredOxygenCompositionOxygeninMixtureSpinnerValueChanged(app, event)
            app.gOxyComp = app.DesiredOxygenCompositionOxygeninMixtureSpinner.Value;
            %OxyComp.Value = event.value;
        end

        % Button pushed function: DrySampleButton
        function DrySampleButtonPushed(app, event)
            format shortG
            app.QDry = app.Dryvel * app.A * app.DC * 0.06;
            app.Dryvel;
            app.QDry;
            %testing;
            
            if app.QDry < 5
                setFlow(app.QDry,'E');
                %testing;
                
                %For the display for SLPM scale
                app.EFlow = calcFlow(app.QDry,'E');
                
                %pause(app.Dryvel) %pause might not be suitable for ramp downs because multiple they would happen simultaneously
                %setFlow(0, 'E');
            elseif app.QDry > 5
                setFlow(5, 'E');
                
                %For the display for SLPM scale
                app.QDry = 5;
                app.EFlow = 64000;
                
                disp('Max Flow Rate has been reached at 5SLM')
            end
            
            %Display for SLPM scale
            app.EditField_9.Value = app.EFlow;
            %Display for SLM
            app.EditField_10.Value = app.QDry;
            %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
            app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
            %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
        end

        % Value changed function: DryingVelocitycmsSpinner
        function DryingVelocitycmsSpinnerValueChanged(app, event)
            app.Dryvel = app.DryingVelocitycmsSpinner.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %value = app.EditField.Value;
            
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            %value = app.EditField_10.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            %value = app.EditField_2.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            %value = app.EditField_3.Value;
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            %value = app.EditField_4.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            %value = app.EditField_5.Value;
            
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            %value = app.EditField_6.Value;
            
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            %value = app.EditField_7.Value;
            
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            %value = app.EditField_8.Value;
            
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            %value = app.EditField_9.Value;
            
        end

        % Value changed function: FEditField
        function FEditFieldValueChanged(app, event)
            %value = app.FEditField.Value;
            %app.ChTempF = ((app.ChTempK)-273.15)*(9/5)+32; %If this doesn't work, you would have to make a function for Temp F
            %app.FEditField.Value = app.ChTempF;
        end

        % Value changed function: FEditField_2
        function FEditField_2ValueChanged(app, event)
            %value = app.FEditField_2.Value;
            
        end

        % Value changed function: FinalVelocity1TotalcmsSpinner
        function FinalVelocity1TotalcmsSpinnerValueChanged(app, event)
            app.gFVT = app.FinalVelocity1TotalcmsSpinner.Value;
            
        end

        % Value changed function: FinalVelocityBcmsEditField
        function FinalVelocityBcmsEditFieldValueChanged(app, event)
            %value = app.FinalVelocityBcmsEditField.Value;
            
        end

        % Value changed function: GapHeightmmSpinner
        function GapHeightmmSpinnerValueChanged(app, event)
            app.gGapHeight = app.GapHeightmmSpinner.Value;

            % mm to cm
            app.cmEditField.Value = app.gGapHeight/10;
            
        end

        % Value changed function: ChannelWidthcmSpinner
        function ChannelWidthcmSpinnerValueChanged(app, event)
            app.gChannelWidth = app.ChannelWidthcmSpinner.Value;
            
        end

        % Value changed function: InitialVelocityAcmsEditField
        function InitialVelocityAcmsEditFieldValueChanged(app, event)
            %value = app.InitialVelocityAcmsEditField.Value;
            
        end

        % Value changed function: InitialVelocityBcmsEditField
        function InitialVelocityBcmsEditFieldValueChanged(app, event)
            %value = app.InitialVelocityBcmsEditField.Value;
            
        end

        % Value changed function: InitialVelocityTotalcmsSpinner
        function InitialVelocityTotalcmsSpinnerValueChanged(app, event)
            app.gIVT = app.InitialVelocityTotalcmsSpinner.Value;
          
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            %value = app.KEditField.Value;
            %app.ChTempK = ChannelTemperatureK(gBattVolt, gChTempVolt);
            %app.KEditField.Value = app.ChTempK
        end

        % Value changed function: KEditField_2
        function KEditField_2ValueChanged(app, event)
            %value = app.KEditField_2.Value;
            
        end

        % Value changed function: O2percentplateauVolumeSpinner
        function O2percentplateauVolumeSpinnerValueChanged(app, event)
            app.gO2_percent_plateau = app.O2percentplateauVolumeSpinner.Value;
            
        end

        % Value changed function: O2percentrampdownratepersecondSpinner
        function O2percentrampdownratepersecondSpinnerValueChanged(app, event)
            app.gO2_percent_RDR = app.O2percentrampdownratepersecondSpinner.Value;
            
        end

        % Value changed function: OxygenRampTimesecEditField
        function OxygenRampTimesecEditFieldValueChanged(app, event)
            %value = app.OxygenRampTimesecEditField.Value;
            
        end

        % Value changed function: PlateauVelocity1AcmsEditField
        function PlateauVelocity1AcmsEditFieldValueChanged(app, event)
            %value = app.PlateauVelocity1AcmsEditField.Value;
            
        end

        % Value changed function: PlateauVelocity1BcmsEditField
        function PlateauVelocity1BcmsEditFieldValueChanged(app, event)
            %value = app.PlateauVelocity1BcmsEditField.Value;
            
        end

        % Value changed function: PlateauVelocity1TotalcmsSpinner
        function PlateauVelocity1TotalcmsSpinnerValueChanged(app, event)
            app.gPVT1 = app.PlateauVelocity1TotalcmsSpinner.Value;
            
        end

        % Value changed function: PlateauVelocity2AcmsEditField
        function PlateauVelocity2AcmsEditFieldValueChanged(app, event)
            %value = app.PlateauVelocity2AcmsEditField.Value;
            
        end

        % Value changed function: PlateauVelocity2BcmsEditField
        function PlateauVelocity2BcmsEditFieldValueChanged(app, event)
            %value = app.PlateauVelocity2BcmsEditField.Value;
            
        end

        % Value changed function: PlateauVelocity2TotalcmsSpinner
        function PlateauVelocity2TotalcmsSpinnerValueChanged(app, event)
            app.gPVT2 = app.PlateauVelocity2TotalcmsSpinner.Value;
            
        end

        % Value changed function: PlateauVelocityAcmsEditField
        function PlateauVelocityAcmsEditFieldValueChanged(app, event)
            %value = app.PlateauVelocityAcmsEditField.Value;
            
        end

        % Button pushed function: RampDown1Button
        function RampDown1ButtonPushed(app, event)
            %Ramp Down 1 Button------------------------------------------------------------------
            
            if app.gPVT1 >= app.gIVT
                msgbox('Plateau Velocity 1 Total must be lower than Initial Velocity Total');
            else
                %QRT1
                app.QRT1 = app.gRDRT1*app.A*app.DC*6/100;
                app.SLMsEditField_7.Value = app.QRT1;
            
                %PVT1
                app.QPT1 = app.gPVT1*app.A*app.DC*6/100;
                app.SLMEditField_9.Value = app.QPT1;
            
                %PVB1
                app.PVB1 = app.gPVT1*app.gOxyComp/100;
                app.PlateauVelocity1BcmsEditField.Value = app.PVB1;
            
                %QPB1
                app.QPB1 = app.PVB1*app.A*app.DC*6/100;
                app.SLMEditField_13.Value = app.QPB1;
            
                %PVA1
                app.PVA1 = app.gPVT1-app.PVB1;
                app.PlateauVelocity1AcmsEditField.Value = app.PVA1;
            
                %QPA1
                app.QPA1 = app.PVA1*app.A*app.DC*6/100;
                app.SLMEditField_5.Value = app.QPA1;
            
                %RDRA1
                app.RDRA1 = app.gRDRT1*((app.PVA1-app.IVA)/(app.gPVT1-app.gIVT));
                if isnan(app.RDRA1)
                    msgbox('Ramp Down Rate 1-A resulted to NaN');
                else
                    app.RampDownRate1AcmssEditField.Value = app.RDRA1;
                end
            
                %QRA1
                app.QRA1 = app.RDRA1*app.A*app.DC*6/100;
                app.SLMsEditField_4.Value = app.QRA1;
            
                %RDRB1
                app.RDRB1 = app.gRDRT1*((app.PVB1-app.IVB)/(app.gPVT1-app.gIVT));
                if isnan(app.RDRB1)
                    msgbox('Ramp Down Rate 1-B resulted to NaN');
                else
                    app.RampDownRate1BcmssEditField.Value = app.RDRB1;
                end
            
                %QRB1
                app.QRB1 = app.RDRB1*app.A*app.DC*6/100;
                app.SLMsEditField_10.Value = app.QRB1;
            
                %RD1T
                app.RD1T = (app.gPVT1-app.gIVT)/(app.gRDRT1);
                if isnan(app.RD1T)
                    msgbox('Ramp Down 1 resulted to NaN');
                else
                    app.RampDown1secEditField.Value = app.RD1T;
                end
            
            
                %-----Setting the Flows for gas A and gas B------
                 
                %For Display Purposes
                if app.N2Unit == 'A'
                    %Display for SLM Capacity
                    app.AFlow = calcFlow(app.QPA1, app.N2Unit);
                    app.EditField.Value = app.AFlow;
                    
                    %Display for MFC flow
                    app.EditField_3.Value = app.QPA1; %SLM
                elseif app.N2Unit == 'C'
                    %Display for SLM Capacity
                    app.CFlow = calcFlow(app.QPA1, app.N2Unit);
                    app.EditField_4.Value = app.CFlow;
                    
                    %Display for MFC flow
                    app.EditField_6.Value = app.QPA1; %SLM
                end
                
                if app.O2Unit == 'B'
                    %Display for SLM Capacity
                    app.BFlow = calcFlow(app.QPB1, app.O2Unit);
                    app.EditField_2.Value = app.BFlow;
                    
                    %Display for MFC flow
                    app.EditField_5.Value = app.QPB1; %SLM
                elseif app.O2Unit == 'D'
                    %Display for SLM Capacity
                    app.DFlow = calcFlow(app.QPB1, app.O2Unit);
                    app.EditField_7.Value = app.DFlow;
                    
                    %Display for MFC flow
                    app.EditField_8.Value = app.QPB1; %SLM
                end
                %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
                app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
                %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
                app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
                
                %Starts the ramp down procedure by calling RampDown func
                RampDown(app.gIVT, app.gPVT1, app.gRDRT1, app.gOxyComp, app.A, app.DC, app.N2Unit, app.O2Unit)
            
                %%This snippet of code was an attempt to replicate the Igor Pro Code for Ramp Down -- didn't quite work
                %             app.RSA1 = app.QA1;
                %             app.RSB1 = app.QB1;
                %             N = 100;
                %             dt = app.RD1T/N;
                %             
                %             tic
                %             for i = 1:N
                %                 timerVal = tic;
                %                 elapsedTime = toc(timerVal);
                %                 while elapsedTime <= dt
                %                     app.RSA1 = app.RSA1 - ((app.RSA1-app.QPA1)/N); %curved slope decrease; we want a linear slope based on rate
                %                     app.RSB1 = app.RSB1 - ((app.RSB1-app.QPB1)/N); %curved slope decrease; we want a linear slope based on rate
                %                     %app.RSA1 = app.RSA1 - ((app.QA1-app.QPA1)/N);
                %                     %app.RSB1 = app.RSB1 - ((app.QB1-app.QPB1)/N);
                %                     elapsedTime = toc(timerVal);
                %                     setFlow(app.RSA1, app.N2Unit)
                %                     setFlow(app.RSB1, app.O2Unit)
                %                     %if app.RSA1 == app.QPA1 && app.RSB1 == app.QPB1
                %                         %break
                %                     %end
                %                 end
                %             toc
                %             end
            end
        end

        % Value changed function: RampDown1secEditField
        function RampDown1secEditFieldValueChanged(app, event)
            %value = app.RampDown1secEditField.Value;
            
        end

        % Button pushed function: RampDown2Button
        function RampDown2ButtonPushed(app, event)
            %Ramp Down 2 Button---------------------------------------------------------------------
            
            if app.gPVT2 >= app.gPVT1
                msgbox('Plateau Velocity 2 Total must be lower than Plateau Velocity 1 Total');
            else
                %QRT2
                app.QRT2 = app.gRDRT2*app.A*app.DC*6/100;
                app.SLMsEditField_13.Value = app.QRT2;
            
                %QPT2
                app.QPT2 = app.gPVT2*app.A*app.DC*6/100;
                app.SLMEditField_16.Value = app.QPT2;
            
                %PVB2
                app.PVB2 = app.gPVT2*app.gOxyComp/100;
                app.PlateauVelocity2BcmsEditField.Value = app.PVB2;
            
                %QPB2
                app.QPB2 = app.PVB2*app.A*app.DC*6/100;
                app.SLMEditField_14.Value = app.QPB2;
            
                %PVA2
                app.PVA2 = app.gPVT2-app.PVB2;
                app.PlateauVelocity2AcmsEditField.Value = app.PVA2;
            
                %QPA2
                app.QPA2 = app.PVA2*app.A*app.DC*6/100;
                app.SLMEditField_6.Value = app.QPA2;
            
                %RDRA2
                app.RDRA2 = app.gRDRT2*((app.PVA2-app.PVA1)/(app.gPVT2-app.gPVT1));
                if isnan(app.RDRA2)
                    msgbox('Ramp Down Rate 2-A resulted to NaN');
                else
                    app.RampDownRate2AcmssEditField.Value = app.RDRA2;
                end
            
                %if ~isnan(app.RDRA2)
                    %app.RampDownRate2AcmssEditField.Value = app.RDRA2;
                %end
            
                %QRA2
                app.QRA2 = app.RDRA2*app.A*app.DC*6/100;
                if isnan(app.QRA2)
                    msgbox('Ramp Down Flow Rate 2-A (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_5.Value = app.QRA2;
                end
            
                %RDRB2
                app.RDRB2 = app.gRDRT2*((app.PVB2-app.PVB1)/(app.gPVT2-app.gPVT1));
                if isnan(app.RDRB2)
                    msgbox('Ramp Down Rate 2-B resulted to NaN');
                else
                    app.RampDownRate2BcmssEditField.Value = app.RDRB2;
                end
            
                %QRB2
                app.QRB2 = app.RDRB2*app.A*app.DC*6/100;
                if isnan(app.QRB2)
                    msgbox('Ramp Down Flow Rate 2-B resulted to NaN');
                else
                    app.SLMsEditField_11.Value = app.QRB2;
                end
            
                %RD2T
                app.RD2T =(app.gPVT2-app.gPVT1)/(app.gRDRT2);
                if isnan(app.RD2T)
                    msgbox('Ramp Down 2 resulted to NaN');
                else
                app.RampDown2secEditField.Value = app.RD2T;
                end
                %-----Setting the Flows for gas A and gas B------
                
                %For Display Purposes
                if app.N2Unit == 'A'
                    %Display for SLM Capacity
                    app.AFlow = calcFlow(app.QPA2, app.N2Unit);
                    app.EditField.Value = app.AFlow;
                    
                    %Display for MFC flow
                    app.EditField_3.Value = app.QPA2; %SLM
                elseif app.N2Unit == 'C'
                    %Display for SLM Capacity
                    app.CFlow = calcFlow(app.QPA2, app.N2Unit);
                    app.EditField_4.Value = app.CFlow;
                    
                    %Display for MFC flow
                    app.EditField_6.Value = app.QPA2; %SLM
                end
                
                if app.O2Unit == 'B'
                    %Display for SLM Capacity
                    app.BFlow = calcFlow(app.QPB2, app.O2Unit);
                    app.EditField_2.Value = app.BFlow;
                    
                    %Display for MFC flow
                    app.EditField_5.Value = app.QPB2; %SLM
                elseif app.O2Unit == 'D'
                    %Display for SLM Capacity
                    app.DFlow = calcFlow(app.QPB2, app.O2Unit);
                    app.EditField_7.Value = app.DFlow;
                    
                    %Display for MFC flow
                    app.EditField_8.Value = app.QPB2; %SLM
                end
                %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
                app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
                %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
                app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
                
                %Starts the ramp down procedure by calling RampDown func
                RampDown(app.gPVT1, app.gPVT2, app.gRDRT2, app.gOxyComp, app.A, app.DC, app.N2Unit, app.O2Unit);
            end
            
        end

        % Value changed function: RampDown2secEditField
        function RampDown2secEditFieldValueChanged(app, event)
            %value = app.RampDown2secEditField.Value;
            
        end

        % Button pushed function: RampDownO2Button
        function RampDownO2ButtonPushed(app, event)
            %Thao's code -----------------------------------------------------------------------
            app.O2_vel_plateau = app.gIVT*app.gO2_percent_plateau/100;
            app.O2_flow_plateau = app.QT1*app.gO2_percent_plateau/100;
            app.SLMEditField_18.Value = app.O2_flow_plateau; % SLM for O2
            
            app.N2_vel_plateau = app.gIVT-app.O2_vel_plateau;
            app.N2_flow_plateau = app.QT1-app.O2_flow_plateau;
            
            app.O2_Q_ramp_rate = app.QT1*app.gO2_percent_RDR/100;
            app.SLMsEditField_15.Value = app.O2_Q_ramp_rate; % SLM/s for O2
            app.N2_Q_ramp_rate = -1*app.O2_Q_ramp_rate;
            
            app.duration = (app.gO2_percent_plateau-app.gOxyComp)/app.gO2_percent_RDR;
            if isnan(app.duration)
                msgbox('Oxygen Ramp Time resulted to NaN');
            else
                app.OxygenRampTimesecEditField.Value = app.duration;
            end
            
            %For Display Purposes
            if app.N2Unit == 'A'
                %Display for SLM Capacity
                app.AFlow = calcFlow(app.N2_flow_plateau, app.N2Unit);
                app.EditField.Value = app.AFlow;
                
                %Display for MFC flow
                app.EditField_3.Value = app.N2_flow_plateau; %SLM
            elseif app.N2Unit == 'C'
                %Display for SLM Capacity
                app.CFlow = calcFlow(app.N2_flow_plateau, app.N2Unit);
                app.EditField_4.Value = app.CFlow;
                
                %Display for MFC flow
                app.EditField_6.Value = app.N2_flow_plateau; %SLM
            end
            
            if app.O2Unit == 'B'
                %Display for SLM Capacity
                app.BFlow = calcFlow(app.O2_flow_plateau, app.O2Unit);
                app.EditField_2.Value = app.BFlow;
                
                %Display for MFC flow
                app.EditField_5.Value = app.O2_flow_plateau; %SLM
            elseif app.O2Unit == 'D'
                %Display for SLM Capacity
                app.DFlow = calcFlow(app.O2_flow_plateau, app.O2Unit);
                app.EditField_7.Value = app.DFlow;
                
                %Display for MFC flow
                app.EditField_8.Value = app.O2_flow_plateau; %SLM
            end
            %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E) -- Although the total shouldn't change since keeping the same total flow rate
            app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
            %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
            
            RampDownOxygen(app.QT1, app.gO2_percent_plateau, app.gO2_percent_RDR, app.gOxyComp, app.N2Unit, app.O2Unit )
        end

        % Value changed function: RampDownRate1AcmssEditField
        function RampDownRate1AcmssEditFieldValueChanged(app, event)
            %value = app.RampDownRate1AcmssEditField.Value;
            
        end

        % Value changed function: RampDownRate1BcmssEditField
        function RampDownRate1BcmssEditFieldValueChanged(app, event)
            %value = app.RampDownRate1BcmssEditField.Value;
            
        end

        % Value changed function: RampDownRate1TotalcmssSpinner
        function RampDownRate1TotalcmssSpinnerValueChanged(app, event)
            app.gRDRT1 = app.RampDownRate1TotalcmssSpinner.Value;
            
        end

        % Value changed function: RampDownRate2AcmssEditField
        function RampDownRate2AcmssEditFieldValueChanged(app, event)
            %value = app.RampDownRate2AcmssEditField.Value;
            
        end

        % Value changed function: RampDownRate2BcmssEditField
        function RampDownRate2BcmssEditFieldValueChanged(app, event)
            %value = app.RampDownRate2BcmssEditField.Value;
            
        end

        % Value changed function: RampDownRate2TotalcmssSpinner
        function RampDownRate2TotalcmssSpinnerValueChanged(app, event)
            app.gRDRT2 = app.RampDownRate2TotalcmssSpinner.Value;
            
        end

        % Value changed function: RampDownRateAcmssEditField
        function RampDownRateAcmssEditFieldValueChanged(app, event)
            %value = app.RampDownRateAcmssEditField.Value;
            
        end

        % Value changed function: RampUp1secEditField
        function RampUp1secEditFieldValueChanged(app, event)
            %value = app.RampUp1secEditField.Value;
            
        end

        % Button pushed function: RampUpButton
        function RampUpButtonPushed(app, event)
            if app.gFVT <= app.gPVT2
                msgbox('Final Plateau Velocity must be higher than Plateau Velocity 2 Total');
            else
                %Ramp Up Button-------------------------------------------------------------------
                %QRT3
                app.QRT3 = app.gRURT*app.A*app.DC*6/100;
                app.SLMsEditField_14.Value = app.QRT3;
                
                %QFT
                app.QFT = app.gFVT*app.A*app.DC*6/100;
                app.SLMEditField_17.Value = app.QFT;
                
                %PFB
                app.PFB = app.gFVT*app.gOxyComp/100;
                app.FinalVelocityBcmsEditField.Value = app.PFB;
                
                %QFB
                app.QFB = app.PFB*app.A*app.DC*6/100;
                app.SLMEditField_15.Value = app.QFB;
                
                %PFA
                app.PFA = app.gFVT-app.PFB;
                app.PlateauVelocityAcmsEditField.Value = app.PFA;
                
                %QFA
                app.QFA = app.PFA*app.A*app.DC*6/100;
                app.SLMEditField_7.Value = app.QFA;
                
                %RURA
                app.RURA = app.gRURT*((app.PFA-app.PVA2)/(app.gFVT-app.gPVT2));
                if isnan(app.RURA)
                    msgbox('Ramp Up Rate A resulted to NaN');
                else
                    app.RampDownRateAcmssEditField.Value = app.RURA;
                end
                
                %QRA3
                app.QRA3 = app.RURA*app.A*app.DC*6/100;
                if isnan(app.QRA3)
                    msgbox('Ramp Up Flow Rate A (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_6.Value = app.QRA3;
                end
                
                %RURB
                app.RURB = app.gRURT*((app.PFB-app.PVA2)/(app.gFVT-app.gPVT2));
                if isnan(app.RURB)
                    msgbox('Ramp Up Rate B resulted to NaN');
                else
                    app.RampUpRateBcmssEditField.Value = app.RURB;
                end
                
                %QRB3
                app.QRB3 = app.RURB*app.A*app.DC*6/100;
                if isnan(app.QRB3)
                    msgbox('Ramp Up Flow Rate B (SLM/s) resulted to NaN');
                else
                    app.SLMsEditField_12.Value = app.QRB3;
                end
                
                %RUT
                app.RUT = (app.gFVT-app.gPVT2)/(app.gRURT);
                if isnan(app.RUT)
                    msgbox('Ramp Up 1 resulted to NaN');
                else
                    app.RampUp1secEditField.Value = app.RUT;
                end
                
                %-----Setting the Flows for gas A and gas B------
                
                %For Display Purposes
                if app.N2Unit == 'A'
                    %Display for SLM Capacity
                    app.AFlow = calcFlow(app.QFA, app.N2Unit);
                    app.EditField.Value = app.AFlow;
                    
                    %Display for MFC flow
                    app.EditField_3.Value = app.QFA; %SLM
                elseif app.N2Unit == 'C'
                    %Display for SLM Capacity
                    app.CFlow = calcFlow(app.QFA, app.N2Unit);
                    app.EditField_4.Value = app.CFlow;
                    
                    %Display for MFC flow
                    app.EditField_6.Value = app.QFA; %SLM
                end
                
                if app.O2Unit == 'B'
                    %Display for SLM Capacity
                    app.BFlow = calcFlow(app.QFB, app.O2Unit);
                    app.EditField_2.Value = app.BFlow;
                    
                    %Display for MFC flow
                    app.EditField_5.Value = app.QFB; %SLM
                elseif app.O2Unit == 'D'
                    %Display for SLM Capacity
                    app.DFlow = calcFlow(app.QFB, app.O2Unit);
                    app.EditField_7.Value = app.DFlow;
                    
                    %Display for MFC flow
                    app.EditField_8.Value = app.QFB; %SLM
                end
                %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
                app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
                %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
                app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
                
                %Starts the ramp down procedure by calling RampDown func
                RampUp(app.gPVT2, app.gFVT, app.gRURT, app.gOxyComp, app.A, app.DC, app.N2Unit, app.O2Unit)
            end
        end

        % Value changed function: RampUpRate1TotalcmssSpinner
        function RampUpRate1TotalcmssSpinnerValueChanged(app, event)
            app.gRURT = app.RampUpRate1TotalcmssSpinner.Value;
            
        end

        % Value changed function: RampUpRateBcmssEditField
        function RampUpRateBcmssEditFieldValueChanged(app, event)
            %value = app.RampUpRateBcmssEditField.Value;
            
        end

        % Button pushed function: ReadMFCsButton
        function ReadMFCsButtonPushed(app, event)
            flushAlicatBuffer;

            app.sampleRate = 1; app.sampleSize = 1;

            app.pollA = readMFC('A');
            app.pollB = readMFC('B');
            app.pollC = readMFC('C');
            app.pollD = readMFC('D');

            while app.pollA.setPoint || app.pollB.setPoint || app.pollC.setPoint || app.pollD.setPoint ~= 0

                pollArrayA = pollMFC('A', app.sampleRate, app.sampleSize);
                app.AFlowAct = cell2mat(pollArrayA).massFlow;
                app.EditField_11.Value = app.AFlowAct;
                app.EditField_12.Value = app.EditField_11.Value / (app.A * app.DC * 0.06);

                pollArrayB = pollMFC('B', app.sampleRate, app.sampleSize);
                app.BFlowAct = cell2mat(pollArrayB).massFlow;
                app.EditField_13.Value = app.BFlowAct;
                app.EditField_14.Value = app.EditField_13.Value / (app.A * app.DC * 0.06);

                pollArrayC = pollMFC('C', app.sampleRate, app.sampleSize);
                app.CFlowAct = cell2mat(pollArrayC).massFlow;
                app.EditField_16.Value = app.CFlowAct / 1000;
                app.EditField_15.Value = app.EditField_16.Value / (app.A * app.DC * 0.06);

                pollArrayD = pollMFC('D', app.sampleRate, app.sampleSize);
                app.DFlowAct = cell2mat(pollArrayD).massFlow;
                app.EditField_17.Value = app.DFlowAct / 1000;
                app.EditField_18.Value = app.EditField_17.Value / (app.A * app.DC * 0.06);

                app.ActualTotalFlowEditField.Value = app.EditField_11.Value + app.EditField_13.Value + app.EditField_16.Value + app.EditField_17.Value;

                app.ActualTotalVelocityEditField.Value = app.ActualTotalFlowEditField.Value / (app.A * app.DC * 0.06);

            end
            
            
        end

        % Value changed function: ReferencePressurekPaSpinner
        function ReferencePressurekPaSpinnerValueChanged(app, event)
            app.gRefPress = app.ReferencePressurekPaSpinner.Value;
            
            %Atmospheric Pressure
            app.gRefPressATM = (app.gRefPress)/101.32;
            app.atmEditField_2.Value = app.gRefPressATM;
            
            %Pounds per square inch pressure
            app.gRefPressPSIA = (app.gRefPress)*14.696/101.32;
            app.psiaEditField_2.Value = app.gRefPressPSIA;
        end

        % Value changed function: ReferenceTemperatureCSpinner
        function ReferenceTemperatureCSpinnerValueChanged(app, event)
            app.gRefTemp = app.ReferenceTemperatureCSpinner.Value;
            
            %Kelvin
            app.RefTempK = (app.gRefTemp) + 273.15;
            app.KEditField_2.Value = app.RefTempK;
            
            %Fahrenheit
            app.RefTempF = ((app.RefTempK)-273.15)*(9/5)+32;
            app.FEditField_2.Value = app.RefTempF;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            startupFcn(app)  %Currently doesnt work; The premise is to go back to the default settings dictated by the startupFcn
        end

        % Value changed function: SLMEditField
        function SLMEditFieldValueChanged(app, event)
            %value = app.SLMEditField.Value;
            
        end

        % Value changed function: SLMEditField_12
        function SLMEditField_12ValueChanged(app, event)
            %app.QB1 = app.SLMEditField_12.Value;
            
        end

        % Value changed function: SLMEditField_13
        function SLMEditField_13ValueChanged(app, event)
            %value = app.SLMEditField_13.Value;
            
        end

        % Value changed function: SLMEditField_14
        function SLMEditField_14ValueChanged(app, event)
            %value = app.SLMEditField_14.Value;
            
        end

        % Value changed function: SLMEditField_15
        function SLMEditField_15ValueChanged(app, event)
            %value = app.SLMEditField_15.Value;
            
        end

        % Value changed function: SLMEditField_16
        function SLMEditField_16ValueChanged(app, event)
            %value = app.SLMEditField_16.Value;
            
        end

        % Value changed function: SLMEditField_17
        function SLMEditField_17ValueChanged(app, event)
            %value = app.SLMEditField_17.Value;
            
        end

        % Value changed function: SLMEditField_18
        function SLMEditField_18ValueChanged(app, event)
            %value = app.SLMEditField_18.Value;
            
        end

        % Value changed function: SLMEditField_5
        function SLMEditField_5ValueChanged(app, event)
            %value = app.SLMEditField_5.Value;
            
        end

        % Value changed function: SLMEditField_6
        function SLMEditField_6ValueChanged(app, event)
            %value = app.SLMEditField_6.Value;
            
        end

        % Value changed function: SLMEditField_7
        function SLMEditField_7ValueChanged(app, event)
            %value = app.SLMEditField_7.Value;
            
        end

        % Value changed function: SLMEditField_8
        function SLMEditField_8ValueChanged(app, event)
            %app.QT1 = app.SLMEditField_8.Value;
            
        end

        % Value changed function: SLMEditField_9
        function SLMEditField_9ValueChanged(app, event)
            %value = app.SLMEditField_9.Value;
            
        end

        % Value changed function: SLMsEditField_10
        function SLMsEditField_10ValueChanged(app, event)
            %value = app.SLMsEditField_10.Value;
            
        end

        % Value changed function: SLMsEditField_11
        function SLMsEditField_11ValueChanged(app, event)
            %value = app.SLMsEditField_11.Value;
            
        end

        % Value changed function: SLMsEditField_12
        function SLMsEditField_12ValueChanged(app, event)
            %value = app.SLMsEditField_12.Value;
            
        end

        % Value changed function: SLMsEditField_13
        function SLMsEditField_13ValueChanged(app, event)
            %value = app.SLMsEditField_13.Value;
            
        end

        % Value changed function: SLMsEditField_14
        function SLMsEditField_14ValueChanged(app, event)
            %value = app.SLMsEditField_14.Value;
            
        end

        % Value changed function: SLMsEditField_15
        function SLMsEditField_15ValueChanged(app, event)
            %value = app.SLMsEditField_15.Value;
            
        end

        % Value changed function: SLMsEditField_4
        function SLMsEditField_4ValueChanged(app, event)
            %value = app.SLMsEditField_4.Value;
            
        end

        % Value changed function: SLMsEditField_5
        function SLMsEditField_5ValueChanged(app, event)
            %value = app.SLMsEditField_5.Value;
            
        end

        % Value changed function: SLMsEditField_6
        function SLMsEditField_6ValueChanged(app, event)
            %value = app.SLMsEditField_6.Value;
            
        end

        % Value changed function: SLMsEditField_7
        function SLMsEditField_7ValueChanged(app, event)
            %value = app.SLMsEditField_7.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %------------------Before pressing Start, make sure that all lines to the MFCs are pressurized!!!---------------
            
            %-----------Calculations-----------------------------------(Independent from the 'Calculate' Button)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %             app.EditField_3.Value = 0;
            %             app.EditField_5.Value = 0;
            %             app.EditField_6.Value = 0;
            %             app.EditField_8.Value = 0;
            %             app.EditField_10.Value = 0;
            %            
            %             setFlow(0,'A');
            %             setFlow(0,'B');
            %             setFlow(0,'C');
            %             setFlow(0,'D');
            %             setFlow(0,'E');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Calculates Density Correction Factor
            app.DC =(app.gChPress/app.gRefPress)*(app.ChTempK/app.RefTempK);
            app.DensityCorrectionFactorEditField.Value = app.DC;
            
            %Calculates Cross Sectional Area
            app.A = (app.gChannelWidth)*(app.gGapHeight / 10);
            app.CrossSectionalAreacm2EditField.Value = app.A;
            
            %QT1
            app.QT1 = app.gIVT * app.A* app.DC * .06;
            app.SLMEditField_8.Value = app.QT1;
            
            %QB1
            app.QB1 = (app.gOxyComp * app.QT1)/100;
            app.SLMEditField_12.Value = app.QB1;
            
            %QA1
            app.QA1 = (app.QT1 - app.QB1);
            app.SLMEditField.Value = app.QA1;
            
            %IVA
            app.IVA = app.QA1/(app.A*.06);
            app.InitialVelocityAcmsEditField.Value = app.IVA;
            
            %IVB
            app.IVB = app.QB1/(app.A*.06);
            app.InitialVelocityBcmsEditField.Value = app.IVB;
            
            %ISA50
            app.ISA50 = app.QA1*64000/50;
            
            %ISA1
            app.ISA1 = app.QA1*64000/1;
            
            %ISB50
            app.ISB50 = app.QB1*64000/50;
            
            %ISB1
            app.ISB1 = app.QB1*64000/1;
            
            %N2 Units (Will pick one depending on flow rate capacity)
            if app.QA1 < 1 %if QA1 is less than one (64000/50 = 1280)
                app.ISA2MFC = num2str(app.ISA1); %use MFC Unit C for N2
                app.N2Unit = 'C';
                app.NonN2Unit = 'A';
                setFlow(app.QA1, app.N2Unit);
                             
                %Display for SLM Capacity
                app.CFlow = calcFlow(app.QA1, app.N2Unit);
                app.EditField_4.Value = app.CFlow;
                             
                %Display for MFC flow
                app.EditField_6.Value = app.QA1; %SLM
                setFlow(app.QA1, app.N2Unit);
            else
                app.ISA2MFC = num2str(app.ISA50); %use MFC Unit A for N2
                app.N2Unit = 'A';
                app.NonN2Unit = 'C';
                
                %Display for SLM Capacity
                app.AFlow = calcFlow(app.QA1, app.N2Unit); 
                app.EditField.Value = app.AFlow;
                
                %Display for MFC flow
                app.EditField_3.Value = app.QA1; %SLM
                setFlow(app.QA1, app.N2Unit);
            end
            
            %O2 Units (Will pick one depending on flow rate capacity)    
            if app.QB1 < 1 %if QB1 is less than one (64000/50 = 1280)
                app.ISB2MFC = num2str(app.ISB1); %use MFC Unit D for O2
                app.O2Unit = 'D';
                app.NonO2Unit = 'B';
                setFlow(app.QB1, app.O2Unit);
                             
                %Display for SLM Capacity
                app.DFlow = calcFlow(app.QB1, app.O2Unit);
                app.EditField_7.Value = app.DFlow;
                             
                %Display for MFC flow
                app.EditField_8.Value = app.QB1; %SLM
                setFlow(app.QB1, app.O2Unit);
            else
                app.ISB2MFC = num2str(app.ISB50); %use MFC Unit B for O2
                app.O2Unit = 'B';
                app.NonO2Unit = 'D';
                
                %Display for SLM Capacity
                app.BFlow = calcFlow(app.QB1, app.O2Unit);
                app.EditField_2.Value = app.BFlow;
                
                %Display for MFC flow
                app.EditField_5.Value = app.QB1; %SLM
                setFlow(app.QB1, app.O2Unit);
            end
            
            %Display for Total Flow (Sum of the SLMs of MFCs A, B, C, D, and E)
            app.TotalFlowSetpointEditField.Value = app.EditField_3.Value + app.EditField_5.Value + app.EditField_6.Value + app.EditField_8.Value + app.EditField_10.Value;
            %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);
                
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            format shortG
            setFlow(0,'A');
            setFlow(0,'B');
            setFlow(0,'C');
            setFlow(0,'D');
            setFlow(0,'E');

            app.pollA = readMFC('A');
            app.pollB = readMFC('B');
            app.pollC = readMFC('C');
            app.pollD = readMFC('D');
            
            %Display for SLPM scale
            %MFC Unit A
            app.AFlow = 0;
            app.EditField.Value = 0; %Flow Unit
            app.EditField_3.Value = 0; %SLM
            app.AFlowAct = 0;
            app.EditField_11.Value = 0;
            app.EditField_12.Value = 0;

            %MFC Unit B
            app.BFlow = 0;
            app.EditField_2.Value = 0; %Flow Unit
            app.EditField_5.Value = 0; %SLM
            app.BFlowAct = 0;
            app.EditField_13.Value = 0;
            app.EditField_14.Value = 0;

            %MFC Unit C
            app.CFlow = 0;
            app.EditField_4.Value = 0; %Flow Unit
            app.EditField_6.Value = 0; %SLM
            app.CFlowAct = 0;
            app.EditField_16.Value = 0;
            app.EditField_15.Value = 0;

            %MFC Unit D
            app.DFlow = 0;
            app.EditField_7.Value = 0; %Flow Unit
            app.EditField_8.Value = 0; %SLM
            app.DFlowAct = 0;
            app.EditField_17.Value = 0;
            app.EditField_18.Value = 0;

            %MFC Unit E
            app.EFlow = 0;
            app.EditField_9.Value = 0; %Flow Unit
            app.EditField_10.Value = 0; %SLM

            %TotalFlow
            app.TotalFlowSetpointEditField.Value = 0;

            %Display for Total Velocity (Sum of the cm/s of MFCs A, B, C, D, and E)
            app.TotalVelSetpointEditField.Value = app.TotalFlowSetpointEditField.Value / (app.A * app.DC * 0.06);

            %Actual Total Flow
            app.ActualTotalFlowEditField.Value = 0;

            %Actual Total Velocity
            app.ActualTotalVelocityEditField.Value = app.ActualTotalFlowEditField.Value / (app.A * app.DC * 0.06);
        end

        % Value changed function: TotalFlowSetpointEditField
        function TotalFlowSetpointEditFieldValueChanged(app, event)
            %value = app.TotalFlowSetpointEditField.Value;
            
        end

        % Value changed function: TotalVelSetpointEditField
        function TotalVelSetpointEditFieldValueChanged(app, event)
            %value = app.TotalVelSetpointEditField.Value;
            
        end

        % Value changed function: atmEditField
        function atmEditFieldValueChanged(app, event)
            %app.atmPress = AtmosphericPressure(app.gChPress);
            %atmPress = (ChPress)/101.32;
        end

        % Value changed function: atmEditField_2
        function atmEditField_2ValueChanged(app, event)
            %value = app.atmEditField_2.Value;
            
        end

        % Value changed function: psiaEditField
        function psiaEditFieldValueChanged(app, event)
            %value = app.psiaEditField.Value;
            
        end

        % Value changed function: psiaEditField_2
        function psiaEditField_2ValueChanged(app, event)
            %value = app.psiaEditField_2.Value;
            
        end

        % Value changed function: psiaSpinner
        function psiaSpinnerValueChanged(app, event)
            app.gConverterPsia = app.psiaSpinner.Value;

            % mm to cm
            app.kpaEditField.Value = app.gConverterPsia*6.89476;
            
        end

        % Button pushed function: RecordDataButton
        function RecordDataButtonPushed(app, event)
            if app.toggleData == false
                app.toggleData = true;
                app.Lamp.Color = '#009000';
            else
                app.toggleData = false;
                app.Lamp.Color = '#DA1884';
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            writematrix(app.pressureVector','C:\Users\NCA\Desktop\NCA B Testing\Flame Tracking Excel Data\pressure sensor data\tempSensorData.csv');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NarrowChannel = uifigure('Visible', 'off');
            app.NarrowChannel.Name = 'UI Figure';
            app.NarrowChannelTestRigPanel = uipanel(app.NarrowChannel);
            app.NarrowChannelTestRigPanel.Title = 'Narrow Channel Test Rig';
            app.SetupPanel = uipanel(app.NarrowChannel);
            app.SetupPanel.Title = 'Setup';
            app.ChannelPressurekPaSpinnerLabel = uilabel(app.SetupPanel);
            app.ChannelPressurekPaSpinnerLabel.Text = 'Channel Pressure (kPa)';
            app.ChannelPressurekPaSpinner = uispinner(app.SetupPanel);
            app.ChannelPressurekPaSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelPressurekPaSpinnerValueChanged, true);
            app.ChannelPressurekPaSpinner.Value = 101.32;
            app.atmEditFieldLabel = uilabel(app.SetupPanel);
            app.atmEditFieldLabel.Text = 'atm';
            app.atmEditField = uieditfield(app.SetupPanel, 'numeric');
            app.atmEditField.ValueChangedFcn = createCallbackFcn(app, @atmEditFieldValueChanged, true);
            app.atmEditField.Editable = 'off';
            app.atmEditField.Value = 1;
            app.FEditFieldLabel = uilabel(app.SetupPanel);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.SetupPanel, 'numeric');
            app.FEditField.ValueChangedFcn = createCallbackFcn(app, @FEditFieldValueChanged, true);
            app.FEditField.Editable = 'off';
            app.FEditField.Value = 77;
            app.KEditFieldLabel = uilabel(app.SetupPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.SetupPanel, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Editable = 'off';
            app.KEditField.Value = 298.1;
            app.GapHeightmmSpinnerLabel = uilabel(app.SetupPanel);
            app.GapHeightmmSpinnerLabel.Text = 'Gap Height (mm)';
            app.GapHeightmmSpinner = uispinner(app.SetupPanel);
            app.GapHeightmmSpinner.ValueChangedFcn = createCallbackFcn(app, @GapHeightmmSpinnerValueChanged, true);
            app.GapHeightmmSpinner.Value = 5;
            app.Panel_2 = uipanel(app.SetupPanel);
            app.SourceAGasDropDownLabel = uilabel(app.Panel_2);
            app.SourceAGasDropDownLabel.Text = 'Source A Gas';
            app.SourceAGasDropDown = uidropdown(app.Panel_2);
            app.SourceAGasDropDown.Items = {'O2', 'N2', 'Air', ''};
            app.SourceAGasDropDown.Value = 'N2';
            app.SourceCGasDropDownLabel = uilabel(app.Panel_2);
            app.SourceCGasDropDownLabel.Text = 'Source C Gas';
            app.SourceCGasDropDown = uidropdown(app.Panel_2);
            app.SourceCGasDropDown.Items = {'O2', 'N2', 'Air', ''};
            app.SourceCGasDropDown.Value = 'N2';
            app.InitialVelocityAcmsEditFieldLabel = uilabel(app.Panel_2);
            app.InitialVelocityAcmsEditFieldLabel.Text = 'Initial Velocity- A (cm/s)';
            app.InitialVelocityAcmsEditField = uieditfield(app.Panel_2, 'numeric');
            app.InitialVelocityAcmsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialVelocityAcmsEditFieldValueChanged, true);
            app.InitialVelocityAcmsEditField.Editable = 'off';
            app.SLMEditFieldLabel = uilabel(app.Panel_2);
            app.SLMEditFieldLabel.Text = 'SLM';
            app.SLMEditField = uieditfield(app.Panel_2, 'numeric');
            app.SLMEditField.ValueChangedFcn = createCallbackFcn(app, @SLMEditFieldValueChanged, true);
            app.SLMEditField.Editable = 'off';
            app.Panel_3 = uipanel(app.SetupPanel);
            app.PlateauVelocity1AcmsEditFieldLabel = uilabel(app.Panel_3);
            app.PlateauVelocity1AcmsEditFieldLabel.Text = 'Plateau Velocity 1-A (cm/s)';
            app.PlateauVelocity1AcmsEditField = uieditfield(app.Panel_3, 'numeric');
            app.PlateauVelocity1AcmsEditField.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocity1AcmsEditFieldValueChanged, true);
            app.PlateauVelocity1AcmsEditField.Editable = 'off';
            app.RampDownRate1AcmssEditFieldLabel = uilabel(app.Panel_3);
            app.RampDownRate1AcmssEditFieldLabel.Text = 'Ramp Down Rate 1-A (cm/s/s)';
            app.RampDownRate1AcmssEditField = uieditfield(app.Panel_3, 'numeric');
            app.RampDownRate1AcmssEditField.ValueChangedFcn = createCallbackFcn(app, @RampDownRate1AcmssEditFieldValueChanged, true);
            app.RampDownRate1AcmssEditField.Editable = 'off';
            app.SLMsEditField_4Label = uilabel(app.Panel_3);
            app.SLMsEditField_4Label.Text = 'SLM/s';
            app.SLMsEditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.SLMsEditField_4.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_4ValueChanged, true);
            app.SLMsEditField_4.Editable = 'off';
            app.SLMEditField_5Label = uilabel(app.Panel_3);
            app.SLMEditField_5Label.Text = 'SLM';
            app.SLMEditField_5 = uieditfield(app.Panel_3, 'numeric');
            app.SLMEditField_5.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_5ValueChanged, true);
            app.SLMEditField_5.Editable = 'off';
            app.Panel_4 = uipanel(app.SetupPanel);
            app.PlateauVelocity2AcmsEditFieldLabel = uilabel(app.Panel_4);
            app.PlateauVelocity2AcmsEditFieldLabel.Text = 'Plateau Velocity 2-A (cm/s)';
            app.PlateauVelocity2AcmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.PlateauVelocity2AcmsEditField.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocity2AcmsEditFieldValueChanged, true);
            app.PlateauVelocity2AcmsEditField.Editable = 'off';
            app.RampDownRate2AcmssEditFieldLabel = uilabel(app.Panel_4);
            app.RampDownRate2AcmssEditFieldLabel.Text = 'Ramp Down Rate 2-A (cm/s/s)';
            app.RampDownRate2AcmssEditField = uieditfield(app.Panel_4, 'numeric');
            app.RampDownRate2AcmssEditField.ValueChangedFcn = createCallbackFcn(app, @RampDownRate2AcmssEditFieldValueChanged, true);
            app.RampDownRate2AcmssEditField.Editable = 'off';
            app.SLMsEditField_5Label = uilabel(app.Panel_4);
            app.SLMsEditField_5Label.Text = 'SLM/s';
            app.SLMsEditField_5 = uieditfield(app.Panel_4, 'numeric');
            app.SLMsEditField_5.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_5ValueChanged, true);
            app.SLMsEditField_5.Editable = 'off';
            app.SLMEditField_6Label = uilabel(app.Panel_4);
            app.SLMEditField_6Label.Text = 'SLM';
            app.SLMEditField_6 = uieditfield(app.Panel_4, 'numeric');
            app.SLMEditField_6.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_6ValueChanged, true);
            app.SLMEditField_6.Editable = 'off';
            app.Panel_5 = uipanel(app.SetupPanel);
            app.PlateauVelocityAcmsEditFieldLabel = uilabel(app.Panel_5);
            app.PlateauVelocityAcmsEditFieldLabel.Text = 'Plateau Velocity-A (cm/s)';
            app.PlateauVelocityAcmsEditField = uieditfield(app.Panel_5, 'numeric');
            app.PlateauVelocityAcmsEditField.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocityAcmsEditFieldValueChanged, true);
            app.PlateauVelocityAcmsEditField.Editable = 'off';
            app.RampDownRateAcmssEditFieldLabel = uilabel(app.Panel_5);
            app.RampDownRateAcmssEditFieldLabel.Text = 'Ramp Down Rate-A (cm/s/s)';
            app.RampDownRateAcmssEditField = uieditfield(app.Panel_5, 'numeric');
            app.RampDownRateAcmssEditField.ValueChangedFcn = createCallbackFcn(app, @RampDownRateAcmssEditFieldValueChanged, true);
            app.RampDownRateAcmssEditField.Editable = 'off';
            app.SLMsEditField_6Label = uilabel(app.Panel_5);
            app.SLMsEditField_6Label.Text = 'SLM/s';
            app.SLMsEditField_6 = uieditfield(app.Panel_5, 'numeric');
            app.SLMsEditField_6.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_6ValueChanged, true);
            app.SLMsEditField_6.Editable = 'off';
            app.SLMEditField_7Label = uilabel(app.Panel_5);
            app.SLMEditField_7Label.Text = 'SLM';
            app.SLMEditField_7 = uieditfield(app.Panel_5, 'numeric');
            app.SLMEditField_7.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_7ValueChanged, true);
            app.SLMEditField_7.Editable = 'off';
            app.Panel_6 = uipanel(app.SetupPanel);
            app.SLMEditField_8Label = uilabel(app.Panel_6);
            app.SLMEditField_8Label.Text = 'SLM';
            app.SLMEditField_8 = uieditfield(app.Panel_6, 'numeric');
            app.SLMEditField_8.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_8ValueChanged, true);
            app.SLMEditField_8.Editable = 'off';
            app.TotalFlowSettingsLabel = uilabel(app.Panel_6);
            app.TotalFlowSettingsLabel.Text = 'Total Flow Settings';
            app.InitialVelocityTotalcmsSpinnerLabel = uilabel(app.Panel_6);
            app.InitialVelocityTotalcmsSpinnerLabel.Text = 'Initial Velocity-Total (cm/s)';
            app.InitialVelocityTotalcmsSpinner = uispinner(app.Panel_6);
            app.InitialVelocityTotalcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @InitialVelocityTotalcmsSpinnerValueChanged, true);
            app.InitialVelocityTotalcmsSpinner.Value = 15;
            app.Panel_7 = uipanel(app.SetupPanel);
            app.SLMsEditField_7Label = uilabel(app.Panel_7);
            app.SLMsEditField_7Label.Text = 'SLM/s';
            app.SLMsEditField_7 = uieditfield(app.Panel_7, 'numeric');
            app.SLMsEditField_7.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_7ValueChanged, true);
            app.SLMsEditField_7.Editable = 'off';
            app.SLMEditField_9Label = uilabel(app.Panel_7);
            app.SLMEditField_9Label.Text = 'SLM';
            app.SLMEditField_9 = uieditfield(app.Panel_7, 'numeric');
            app.SLMEditField_9.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_9ValueChanged, true);
            app.SLMEditField_9.Editable = 'off';
            app.RampDownRate1TotalcmssSpinnerLabel = uilabel(app.Panel_7);
            app.RampDownRate1TotalcmssSpinnerLabel.Text = 'Ramp Down Rate 1-Total (cm/s/s)';
            app.RampDownRate1TotalcmssSpinner = uispinner(app.Panel_7);
            app.RampDownRate1TotalcmssSpinner.ValueChangedFcn = createCallbackFcn(app, @RampDownRate1TotalcmssSpinnerValueChanged, true);
            app.PlateauVelocity1TotalcmsSpinnerLabel = uilabel(app.Panel_7);
            app.PlateauVelocity1TotalcmsSpinnerLabel.Text = 'Plateau Velocity 1-Total (cm/s)';
            app.PlateauVelocity1TotalcmsSpinner = uispinner(app.Panel_7);
            app.PlateauVelocity1TotalcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocity1TotalcmsSpinnerValueChanged, true);
            app.Panel_10 = uipanel(app.SetupPanel);
            app.SourceBGasDropDownLabel = uilabel(app.Panel_10);
            app.SourceBGasDropDownLabel.Text = 'Source B Gas';
            app.SourceBGasDropDown = uidropdown(app.Panel_10);
            app.SourceBGasDropDown.Items = {'O2', 'N2', 'Air', ''};
            app.SourceBGasDropDown.Value = 'O2';
            app.SourceDGasDropDownLabel = uilabel(app.Panel_10);
            app.SourceDGasDropDownLabel.Text = 'Source D Gas';
            app.SourceDGasDropDown = uidropdown(app.Panel_10);
            app.SourceDGasDropDown.Items = {'O2', 'N2', 'Air', ''};
            app.SourceDGasDropDown.Value = 'O2';
            app.InitialVelocityBcmsEditFieldLabel = uilabel(app.Panel_10);
            app.InitialVelocityBcmsEditFieldLabel.Text = 'Initial Velocity- B (cm/s)';
            app.InitialVelocityBcmsEditField = uieditfield(app.Panel_10, 'numeric');
            app.InitialVelocityBcmsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialVelocityBcmsEditFieldValueChanged, true);
            app.InitialVelocityBcmsEditField.Editable = 'off';
            app.SLMEditField_12Label = uilabel(app.Panel_10);
            app.SLMEditField_12Label.Text = 'SLM';
            app.SLMEditField_12 = uieditfield(app.Panel_10, 'numeric');
            app.SLMEditField_12.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_12ValueChanged, true);
            app.SLMEditField_12.Editable = 'off';
            app.Panel_11 = uipanel(app.SetupPanel);
            app.PlateauVelocity1BcmsEditFieldLabel = uilabel(app.Panel_11);
            app.PlateauVelocity1BcmsEditFieldLabel.Text = 'Plateau Velocity 1-B (cm/s)';
            app.PlateauVelocity1BcmsEditField = uieditfield(app.Panel_11, 'numeric');
            app.PlateauVelocity1BcmsEditField.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocity1BcmsEditFieldValueChanged, true);
            app.PlateauVelocity1BcmsEditField.Editable = 'off';
            app.RampDownRate1BcmssEditFieldLabel = uilabel(app.Panel_11);
            app.RampDownRate1BcmssEditFieldLabel.Text = 'Ramp Down Rate 1-B (cm/s/s)';
            app.RampDownRate1BcmssEditField = uieditfield(app.Panel_11, 'numeric');
            app.RampDownRate1BcmssEditField.ValueChangedFcn = createCallbackFcn(app, @RampDownRate1BcmssEditFieldValueChanged, true);
            app.RampDownRate1BcmssEditField.Editable = 'off';
            app.SLMsEditField_10Label = uilabel(app.Panel_11);
            app.SLMsEditField_10Label.Text = 'SLM/s';
            app.SLMsEditField_10 = uieditfield(app.Panel_11, 'numeric');
            app.SLMsEditField_10.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_10ValueChanged, true);
            app.SLMsEditField_10.Editable = 'off';
            app.SLMEditField_13Label = uilabel(app.Panel_11);
            app.SLMEditField_13Label.Text = 'SLM';
            app.SLMEditField_13 = uieditfield(app.Panel_11, 'numeric');
            app.SLMEditField_13.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_13ValueChanged, true);
            app.SLMEditField_13.Editable = 'off';
            app.Panel_12 = uipanel(app.SetupPanel);
            app.PlateauVelocity2BcmsEditFieldLabel = uilabel(app.Panel_12);
            app.PlateauVelocity2BcmsEditFieldLabel.Text = 'Plateau Velocity 2-B (cm/s)';
            app.PlateauVelocity2BcmsEditField = uieditfield(app.Panel_12, 'numeric');
            app.PlateauVelocity2BcmsEditField.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocity2BcmsEditFieldValueChanged, true);
            app.PlateauVelocity2BcmsEditField.Editable = 'off';
            app.RampDownRate2BcmssEditFieldLabel = uilabel(app.Panel_12);
            app.RampDownRate2BcmssEditFieldLabel.Text = 'Ramp Down Rate 2-B (cm/s/s)';
            app.RampDownRate2BcmssEditField = uieditfield(app.Panel_12, 'numeric');
            app.RampDownRate2BcmssEditField.ValueChangedFcn = createCallbackFcn(app, @RampDownRate2BcmssEditFieldValueChanged, true);
            app.RampDownRate2BcmssEditField.Editable = 'off';
            app.SLMsEditField_11Label = uilabel(app.Panel_12);
            app.SLMsEditField_11Label.Text = 'SLM/s';
            app.SLMsEditField_11 = uieditfield(app.Panel_12, 'numeric');
            app.SLMsEditField_11.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_11ValueChanged, true);
            app.SLMsEditField_11.Editable = 'off';
            app.SLMEditField_14Label = uilabel(app.Panel_12);
            app.SLMEditField_14Label.Text = 'SLM';
            app.SLMEditField_14 = uieditfield(app.Panel_12, 'numeric');
            app.SLMEditField_14.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_14ValueChanged, true);
            app.SLMEditField_14.Editable = 'off';
            app.Panel_13 = uipanel(app.SetupPanel);
            app.FinalVelocityBcmsEditFieldLabel = uilabel(app.Panel_13);
            app.FinalVelocityBcmsEditFieldLabel.Text = 'Final Velocity-B (cm/s)';
            app.FinalVelocityBcmsEditField = uieditfield(app.Panel_13, 'numeric');
            app.FinalVelocityBcmsEditField.ValueChangedFcn = createCallbackFcn(app, @FinalVelocityBcmsEditFieldValueChanged, true);
            app.FinalVelocityBcmsEditField.Editable = 'off';
            app.RampUpRateBcmssEditFieldLabel = uilabel(app.Panel_13);
            app.RampUpRateBcmssEditFieldLabel.Text = 'Ramp Up Rate-B (cm/s/s)';
            app.RampUpRateBcmssEditField = uieditfield(app.Panel_13, 'numeric');
            app.RampUpRateBcmssEditField.ValueChangedFcn = createCallbackFcn(app, @RampUpRateBcmssEditFieldValueChanged, true);
            app.RampUpRateBcmssEditField.Editable = 'off';
            app.SLMsEditField_12Label = uilabel(app.Panel_13);
            app.SLMsEditField_12Label.Text = 'SLM/s';
            app.SLMsEditField_12 = uieditfield(app.Panel_13, 'numeric');
            app.SLMsEditField_12.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_12ValueChanged, true);
            app.SLMsEditField_12.Editable = 'off';
            app.SLMEditField_15Label = uilabel(app.Panel_13);
            app.SLMEditField_15Label.Text = 'SLM';
            app.SLMEditField_15 = uieditfield(app.Panel_13, 'numeric');
            app.SLMEditField_15.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_15ValueChanged, true);
            app.SLMEditField_15.Editable = 'off';
            app.Panel_14 = uipanel(app.SetupPanel);
            app.SLMsEditField_13Label = uilabel(app.Panel_14);
            app.SLMsEditField_13Label.Text = 'SLM/s';
            app.SLMsEditField_13 = uieditfield(app.Panel_14, 'numeric');
            app.SLMsEditField_13.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_13ValueChanged, true);
            app.SLMsEditField_13.Editable = 'off';
            app.SLMEditField_16Label = uilabel(app.Panel_14);
            app.SLMEditField_16Label.Text = 'SLM';
            app.SLMEditField_16 = uieditfield(app.Panel_14, 'numeric');
            app.SLMEditField_16.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_16ValueChanged, true);
            app.SLMEditField_16.Editable = 'off';
            app.RampDownRate2TotalcmssSpinnerLabel = uilabel(app.Panel_14);
            app.RampDownRate2TotalcmssSpinnerLabel.Text = 'Ramp Down Rate 2-Total (cm/s/s)';
            app.RampDownRate2TotalcmssSpinner = uispinner(app.Panel_14);
            app.RampDownRate2TotalcmssSpinner.ValueChangedFcn = createCallbackFcn(app, @RampDownRate2TotalcmssSpinnerValueChanged, true);
            app.PlateauVelocity2TotalcmsSpinnerLabel = uilabel(app.Panel_14);
            app.PlateauVelocity2TotalcmsSpinnerLabel.Text = 'Plateau Velocity 2-Total (cm/s)';
            app.PlateauVelocity2TotalcmsSpinner = uispinner(app.Panel_14);
            app.PlateauVelocity2TotalcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @PlateauVelocity2TotalcmsSpinnerValueChanged, true);
            app.Panel_15 = uipanel(app.SetupPanel);
            app.SLMsEditField_14Label = uilabel(app.Panel_15);
            app.SLMsEditField_14Label.Text = 'SLM/s';
            app.SLMsEditField_14 = uieditfield(app.Panel_15, 'numeric');
            app.SLMsEditField_14.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_14ValueChanged, true);
            app.SLMsEditField_14.Editable = 'off';
            app.SLMEditField_17Label = uilabel(app.Panel_15);
            app.SLMEditField_17Label.Text = 'SLM';
            app.SLMEditField_17 = uieditfield(app.Panel_15, 'numeric');
            app.SLMEditField_17.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_17ValueChanged, true);
            app.SLMEditField_17.Editable = 'off';
            app.RampUpRate1TotalcmssSpinnerLabel = uilabel(app.Panel_15);
            app.RampUpRate1TotalcmssSpinnerLabel.Text = 'Ramp Up Rate 1-Total (cm/s/s)';
            app.RampUpRate1TotalcmssSpinner = uispinner(app.Panel_15);
            app.RampUpRate1TotalcmssSpinner.ValueChangedFcn = createCallbackFcn(app, @RampUpRate1TotalcmssSpinnerValueChanged, true);
            app.FinalVelocity1TotalcmsSpinnerLabel = uilabel(app.Panel_15);
            app.FinalVelocity1TotalcmsSpinnerLabel.Text = 'Final Velocity 1-Total (cm/s)';
            app.FinalVelocity1TotalcmsSpinner = uispinner(app.Panel_15);
            app.FinalVelocity1TotalcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @FinalVelocity1TotalcmsSpinnerValueChanged, true);
            app.psiaEditFieldLabel = uilabel(app.SetupPanel);
            app.psiaEditFieldLabel.Text = 'psia';
            app.psiaEditField = uieditfield(app.SetupPanel, 'numeric');
            app.psiaEditField.ValueChangedFcn = createCallbackFcn(app, @psiaEditFieldValueChanged, true);
            app.psiaEditField.Editable = 'off';
            app.psiaEditField.Value = 14.7;
            app.DesiredOxygenCompositionOxygeninMixtureSpinnerLabel = uilabel(app.SetupPanel);
            app.DesiredOxygenCompositionOxygeninMixtureSpinnerLabel.Text = 'Desired Oxygen Composition (% Oxygen in Mixture)';
            app.DesiredOxygenCompositionOxygeninMixtureSpinner = uispinner(app.SetupPanel);
            app.DesiredOxygenCompositionOxygeninMixtureSpinner.Limits = [0 100];
            app.DesiredOxygenCompositionOxygeninMixtureSpinner.ValueChangedFcn = createCallbackFcn(app, @DesiredOxygenCompositionOxygeninMixtureSpinnerValueChanged, true);
            app.DesiredOxygenCompositionOxygeninMixtureSpinner.Value = 100;
            app.ChannelTemperatureCSpinnerLabel = uilabel(app.SetupPanel);
            app.ChannelTemperatureCSpinnerLabel.Text = 'Channel Temperature (C)';
            app.ChannelTemperatureCSpinner = uispinner(app.SetupPanel);
            app.ChannelTemperatureCSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelTemperatureCSpinnerValueChanged, true);
            app.ChannelTemperatureCSpinner.Value = 25;
            app.cmEditFieldLabel = uilabel(app.SetupPanel);
            app.cmEditFieldLabel.Text = 'cm';
            app.cmEditField = uieditfield(app.SetupPanel, 'numeric');
            app.cmEditField.Editable = 'off';
            app.psiaSpinnerLabel = uilabel(app.SetupPanel);
            app.psiaSpinnerLabel.Text = 'psia';
            app.psiaSpinner = uispinner(app.SetupPanel);
            app.psiaSpinner.ValueChangedFcn = createCallbackFcn(app, @psiaSpinnerValueChanged, true);
            app.psiaSpinner.Value = 14.696;
            app.kpaEditFieldLabel = uilabel(app.SetupPanel);
            app.kpaEditFieldLabel.Text = 'kpa';
            app.kpaEditField = uieditfield(app.SetupPanel, 'numeric');
            app.kpaEditField.ValueDisplayFormat = '%.2f';
            app.kpaEditField.Editable = 'off';
            app.psiatokpaconverterLabel = uilabel(app.SetupPanel);
            app.psiatokpaconverterLabel.Text = 'psia to kpa converter';
            app.SensorPressurepsiaEditFieldLabel = uilabel(app.SetupPanel);
            app.SensorPressurepsiaEditFieldLabel.Text = 'Sensor Pressure (psia)';
            app.SensorPressurepsiaEditField = uieditfield(app.SetupPanel, 'numeric');
            app.SensorPressurepsiaEditField.Editable = 'off';
            app.ReferencesPanel = uipanel(app.NarrowChannel);
            app.ReferencesPanel.Title = 'References';
            app.ReferencePressurekPaSpinnerLabel = uilabel(app.ReferencesPanel);
            app.ReferencePressurekPaSpinnerLabel.Text = 'Reference Pressure (kPa)';
            app.ReferencePressurekPaSpinner = uispinner(app.ReferencesPanel);
            app.ReferencePressurekPaSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferencePressurekPaSpinnerValueChanged, true);
            app.ReferencePressurekPaSpinner.Value = 101.32;
            app.ReferenceTemperatureCSpinnerLabel = uilabel(app.ReferencesPanel);
            app.ReferenceTemperatureCSpinnerLabel.Text = 'Reference Temperature (C)';
            app.ReferenceTemperatureCSpinner = uispinner(app.ReferencesPanel);
            app.ReferenceTemperatureCSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceTemperatureCSpinnerValueChanged, true);
            app.ReferenceTemperatureCSpinner.Value = 25;
            app.ChannelWidthcmSpinnerLabel = uilabel(app.ReferencesPanel);
            app.ChannelWidthcmSpinnerLabel.Text = 'Channel Width (cm)';
            app.ChannelWidthcmSpinner = uispinner(app.ReferencesPanel);
            app.ChannelWidthcmSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelWidthcmSpinnerValueChanged, true);
            app.ChannelWidthcmSpinner.Value = 8.0645;
            app.DensityCorrectionFactorEditFieldLabel = uilabel(app.ReferencesPanel);
            app.DensityCorrectionFactorEditFieldLabel.Text = 'Density Correction Factor';
            app.DensityCorrectionFactorEditField = uieditfield(app.ReferencesPanel, 'numeric');
            app.DensityCorrectionFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DensityCorrectionFactorEditFieldValueChanged, true);
            app.DensityCorrectionFactorEditField.Editable = 'off';
            app.DensityCorrectionFactorEditField.Value = 1;
            app.atmEditField_2Label = uilabel(app.ReferencesPanel);
            app.atmEditField_2Label.Text = 'atm';
            app.atmEditField_2 = uieditfield(app.ReferencesPanel, 'numeric');
            app.atmEditField_2.ValueChangedFcn = createCallbackFcn(app, @atmEditField_2ValueChanged, true);
            app.atmEditField_2.Editable = 'off';
            app.atmEditField_2.Value = 1;
            app.FEditField_2Label = uilabel(app.ReferencesPanel);
            app.FEditField_2Label.Text = 'F';
            app.FEditField_2 = uieditfield(app.ReferencesPanel, 'numeric');
            app.FEditField_2.ValueChangedFcn = createCallbackFcn(app, @FEditField_2ValueChanged, true);
            app.FEditField_2.Editable = 'off';
            app.FEditField_2.Value = 77;
            app.psiaEditField_2Label = uilabel(app.ReferencesPanel);
            app.psiaEditField_2Label.Text = 'psia';
            app.psiaEditField_2 = uieditfield(app.ReferencesPanel, 'numeric');
            app.psiaEditField_2.ValueChangedFcn = createCallbackFcn(app, @psiaEditField_2ValueChanged, true);
            app.psiaEditField_2.Editable = 'off';
            app.psiaEditField_2.Value = 14.7;
            app.KEditField_2Label = uilabel(app.ReferencesPanel);
            app.KEditField_2Label.Text = 'K';
            app.KEditField_2 = uieditfield(app.ReferencesPanel, 'numeric');
            app.KEditField_2.ValueChangedFcn = createCallbackFcn(app, @KEditField_2ValueChanged, true);
            app.KEditField_2.Editable = 'off';
            app.KEditField_2.Value = 298.1;
            app.CrossSectionalAreacm2EditFieldLabel = uilabel(app.ReferencesPanel);
            app.CrossSectionalAreacm2EditFieldLabel.Text = 'Cross-Sectional Area (cm^2)';
            app.CrossSectionalAreacm2EditField = uieditfield(app.ReferencesPanel, 'numeric');
            app.CrossSectionalAreacm2EditField.ValueChangedFcn = createCallbackFcn(app, @CrossSectionalAreacm2EditFieldValueChanged, true);
            app.CrossSectionalAreacm2EditField.Editable = 'off';
            app.CrossSectionalAreacm2EditField.Value = 4.0325;
            app.SettingsPanel = uipanel(app.NarrowChannel);
            app.SettingsPanel.Title = 'Settings';
            app.SLMLabel = uilabel(app.SettingsPanel);
            app.SLMLabel.Text = 'SLM';
            app.EditField = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%.0f';
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_9.ValueDisplayFormat = '%.0f';
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.SettingsPanel, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_10.Editable = 'off';
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = '64000=100%';
            app.FullScaleLabel = uilabel(app.SettingsPanel);
            app.FullScaleLabel.Text = '%Full Scale';
            app.ActualsPanel = uipanel(app.NarrowChannel);
            app.ActualsPanel.Title = 'Actuals';
            app.SLMLabel_2 = uilabel(app.ActualsPanel);
            app.SLMLabel_2.Text = 'SLM';
            app.VelocityLabel = uilabel(app.ActualsPanel);
            app.VelocityLabel.Text = 'Velocity';
            app.EditField_11 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_11.Editable = 'off';
            app.EditField_12 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_12.Editable = 'off';
            app.EditField_13 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_13.Editable = 'off';
            app.EditField_14 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_14.Editable = 'off';
            app.EditField_15 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_15.Editable = 'off';
            app.EditField_16 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_16.Editable = 'off';
            app.EditField_17 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_17.Editable = 'off';
            app.EditField_18 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_18.Editable = 'off';
            app.EditField_19 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_19.Editable = 'off';
            app.EditField_20 = uieditfield(app.ActualsPanel, 'numeric');
            app.EditField_20.Editable = 'off';
            app.UnitPanel = uipanel(app.NarrowChannel);
            app.UnitPanel.Title = 'Unit';
            app.A150Label = uilabel(app.UnitPanel);
            app.A150Label.Text = 'A:(1-50)';
            app.N2Label = uilabel(app.UnitPanel);
            app.N2Label.Text = 'N2';
            app.B150Label = uilabel(app.UnitPanel);
            app.B150Label.Text = 'B:(1-50)';
            app.O2Label = uilabel(app.UnitPanel);
            app.O2Label.Text = 'O2';
            app.C0011Label = uilabel(app.UnitPanel);
            app.C0011Label.Text = 'C:(0.01-1)';
            app.D0011Label = uilabel(app.UnitPanel);
            app.D0011Label.Text = 'D:(0.01-1)';
            app.N2Label_2 = uilabel(app.UnitPanel);
            app.N2Label_2.Text = 'N2';
            app.O2Label_2 = uilabel(app.UnitPanel);
            app.O2Label_2.Text = 'O2';
            app.E0055Label = uilabel(app.UnitPanel);
            app.E0055Label.Text = 'E:(0.05-5)';
            app.AirLabel = uilabel(app.UnitPanel);
            app.AirLabel.Text = 'Air';
            app.ReadMFCsButton = uibutton(app.NarrowChannel, 'push');
            app.ReadMFCsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadMFCsButtonPushed, true);
            app.ReadMFCsButton.Text = 'Read MFCs';
            app.DrySampleButton = uibutton(app.NarrowChannel, 'push');
            app.DrySampleButton.ButtonPushedFcn = createCallbackFcn(app, @DrySampleButtonPushed, true);
            app.DrySampleButton.Text = 'Dry Sample';
            app.CalculateButton = uibutton(app.NarrowChannel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.NarrowChannel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StartButton = uibutton(app.NarrowChannel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RampDown1Button = uibutton(app.NarrowChannel, 'push');
            app.RampDown1Button.ButtonPushedFcn = createCallbackFcn(app, @RampDown1ButtonPushed, true);
            app.RampDown1Button.Text = 'Ramp Down 1';
            app.RampDown2Button = uibutton(app.NarrowChannel, 'push');
            app.RampDown2Button.ButtonPushedFcn = createCallbackFcn(app, @RampDown2ButtonPushed, true);
            app.RampDown2Button.Text = 'Ramp Down 2';
            app.StopButton = uibutton(app.NarrowChannel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RampUpButton = uibutton(app.NarrowChannel, 'push');
            app.RampUpButton.ButtonPushedFcn = createCallbackFcn(app, @RampUpButtonPushed, true);
            app.RampUpButton.Text = 'Ramp Up';
            app.RampDownO2Button = uibutton(app.NarrowChannel, 'push');
            app.RampDownO2Button.ButtonPushedFcn = createCallbackFcn(app, @RampDownO2ButtonPushed, true);
            app.RampDownO2Button.Text = 'Ramp Down O2';
            app.PhaseDurationsLabel = uilabel(app.NarrowChannel);
            app.PhaseDurationsLabel.Text = 'Phase Durations';
            app.RampDown1secEditFieldLabel = uilabel(app.NarrowChannel);
            app.RampDown1secEditFieldLabel.Text = 'Ramp Down 1 (sec)';
            app.RampDown1secEditField = uieditfield(app.NarrowChannel, 'numeric');
            app.RampDown1secEditField.ValueChangedFcn = createCallbackFcn(app, @RampDown1secEditFieldValueChanged, true);
            app.RampDown1secEditField.Editable = 'off';
            app.RampDown2secEditFieldLabel = uilabel(app.NarrowChannel);
            app.RampDown2secEditFieldLabel.Text = 'Ramp Down 2 (sec)';
            app.RampDown2secEditField = uieditfield(app.NarrowChannel, 'numeric');
            app.RampDown2secEditField.ValueChangedFcn = createCallbackFcn(app, @RampDown2secEditFieldValueChanged, true);
            app.RampDown2secEditField.Editable = 'off';
            app.RampUp1secEditFieldLabel = uilabel(app.NarrowChannel);
            app.RampUp1secEditFieldLabel.Text = 'Ramp Up 1 (sec)';
            app.RampUp1secEditField = uieditfield(app.NarrowChannel, 'numeric');
            app.RampUp1secEditField.ValueChangedFcn = createCallbackFcn(app, @RampUp1secEditFieldValueChanged, true);
            app.RampUp1secEditField.Editable = 'off';
            app.OxygenRampTimesecEditFieldLabel = uilabel(app.NarrowChannel);
            app.OxygenRampTimesecEditFieldLabel.Text = 'Oxygen Ramp Time(sec)';
            app.OxygenRampTimesecEditField = uieditfield(app.NarrowChannel, 'numeric');
            app.OxygenRampTimesecEditField.ValueChangedFcn = createCallbackFcn(app, @OxygenRampTimesecEditFieldValueChanged, true);
            app.OxygenRampTimesecEditField.Editable = 'off';
            app.O2percentplateauVolumeSpinnerLabel = uilabel(app.NarrowChannel);
            app.O2percentplateauVolumeSpinnerLabel.Text = 'O2 percent plateau (Volume%)';
            app.O2percentplateauVolumeSpinner = uispinner(app.NarrowChannel);
            app.O2percentplateauVolumeSpinner.ValueChangedFcn = createCallbackFcn(app, @O2percentplateauVolumeSpinnerValueChanged, true);
            app.O2percentrampdownratepersecondSpinnerLabel = uilabel(app.NarrowChannel);
            app.O2percentrampdownratepersecondSpinnerLabel.Text = 'O2 percent ramp down rate (% per second)';
            app.O2percentrampdownratepersecondSpinner = uispinner(app.NarrowChannel);
            app.O2percentrampdownratepersecondSpinner.ValueChangedFcn = createCallbackFcn(app, @O2percentrampdownratepersecondSpinnerValueChanged, true);
            app.SLMEditField_18Label = uilabel(app.NarrowChannel);
            app.SLMEditField_18Label.Text = 'SLM';
            app.SLMEditField_18 = uieditfield(app.NarrowChannel, 'numeric');
            app.SLMEditField_18.ValueChangedFcn = createCallbackFcn(app, @SLMEditField_18ValueChanged, true);
            app.SLMEditField_18.Editable = 'off';
            app.SLMsEditField_15Label = uilabel(app.NarrowChannel);
            app.SLMsEditField_15Label.Text = 'SLM/s';
            app.SLMsEditField_15 = uieditfield(app.NarrowChannel, 'numeric');
            app.SLMsEditField_15.ValueChangedFcn = createCallbackFcn(app, @SLMsEditField_15ValueChanged, true);
            app.SLMsEditField_15.Editable = 'off';
            app.TasksPanel = uipanel(app.NarrowChannel);
            app.TasksPanel.Title = 'Tasks';
            app.TotalFlowSetpointEditFieldLabel = uilabel(app.NarrowChannel);
            app.TotalFlowSetpointEditFieldLabel.Text = 'Total Flow Setpoint';
            app.TotalFlowSetpointEditField = uieditfield(app.NarrowChannel, 'numeric');
            app.TotalFlowSetpointEditField.ValueChangedFcn = createCallbackFcn(app, @TotalFlowSetpointEditFieldValueChanged, true);
            app.TotalFlowSetpointEditField.Editable = 'off';
            app.ActualTotalFlowEditFieldLabel = uilabel(app.NarrowChannel);
            app.ActualTotalFlowEditFieldLabel.Text = 'Actual Total Flow';
            app.ActualTotalFlowEditField = uieditfield(app.NarrowChannel, 'numeric');
            app.ActualTotalFlowEditField.Editable = 'off';
            app.TotalVelSetpointEditFieldLabel = uilabel(app.NarrowChannel);
            app.TotalVelSetpointEditFieldLabel.Text = 'Total Vel. Setpoint';
            app.TotalVelSetpointEditField = uieditfield(app.NarrowChannel, 'numeric');
            app.TotalVelSetpointEditField.ValueChangedFcn = createCallbackFcn(app, @TotalVelSetpointEditFieldValueChanged, true);
            app.TotalVelSetpointEditField.Editable = 'off';
            app.ActualTotalVelocityEditFieldLabel = uilabel(app.NarrowChannel);
            app.ActualTotalVelocityEditFieldLabel.Text = 'Actual Total Velocity';
            app.ActualTotalVelocityEditField = uieditfield(app.NarrowChannel, 'numeric');
            app.ActualTotalVelocityEditField.Editable = 'off';
            app.DryingVelocitycmsSpinnerLabel = uilabel(app.NarrowChannel);
            app.DryingVelocitycmsSpinnerLabel.Text = 'Drying Velocity (cm/s)';
            app.DryingVelocitycmsSpinner = uispinner(app.NarrowChannel);
            app.DryingVelocitycmsSpinner.Limits = [0 25];
            app.DryingVelocitycmsSpinner.ValueChangedFcn = createCallbackFcn(app, @DryingVelocitycmsSpinnerValueChanged, true);
            app.DryingVelocitycmsSpinner.Value = 5;
            app.RecordDataButton = uibutton(app.NarrowChannel, 'push');
            app.RecordDataButton.ButtonPushedFcn = createCallbackFcn(app, @RecordDataButtonPushed, true);
            app.RecordDataButton.Text = 'Record Data';
            app.ExportDataButton = uibutton(app.NarrowChannel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Label_2 = uilabel(app.NarrowChannel);
            app.Label_2.Text = '';
            app.Lamp = uilamp(app.NarrowChannel);
            % Show the figure after all components are created
            app.NarrowChannel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NarrowChannelApp_David_V5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NarrowChannel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NarrowChannel)
        end
    end
end